classdef PARTICLE_FLOW_VISUALIZATION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        VIDEOTOFRAMESTab                matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        Panel_7                         matlab.ui.container.Panel
        SelectInputFolderButton         matlab.ui.control.StateButton
        DirectoryFolder                 matlab.ui.control.TextArea
        VideoListBox                    matlab.ui.control.ListBox
        SelectOutputFolderButton_2      matlab.ui.control.Button
        OutputFolder_2                  matlab.ui.control.TextArea
        OutputBaseFileNameTextAreaLabel_2  matlab.ui.control.Label
        OutputBaseFileNameTextArea_2    matlab.ui.control.TextArea
        GetandSaveFramesButton          matlab.ui.control.Button
        CheckProcessCheckBox            matlab.ui.control.CheckBox
        GrayImageCheckBox               matlab.ui.control.CheckBox
        FrameVisualizationSpinnerLabel  matlab.ui.control.Label
        FrameVisualizationSpinner       matlab.ui.control.Spinner
        FramesOfVideoList               matlab.ui.control.ListBox
        GridLayout17                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.UIAxes
        GridLayout18                    matlab.ui.container.GridLayout
        Panel_8                         matlab.ui.container.Panel
        VisualizationLamp_2Label        matlab.ui.control.Label
        VisualizationLamp_2             matlab.ui.control.Lamp
        FinishNowCheckBox_2             matlab.ui.control.CheckBox
        EXPORTButton                    matlab.ui.control.Button
        CommandWindowTextAreaLabel      matlab.ui.control.Label
        Result                          matlab.ui.control.TextArea
        FRAMESTOVIDEOTab                matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        SelectInputFolderButton_2       matlab.ui.control.StateButton
        DirectoryFolderFrames           matlab.ui.control.TextArea
        FrameList                       matlab.ui.control.ListBox
        SelectionOptionButton           matlab.ui.control.Button
        SortAlphabeticallyCheckBox      matlab.ui.control.CheckBox
        SortbyfiledatetimeCheckBox      matlab.ui.control.CheckBox
        Panel_10                        matlab.ui.container.Panel
        PauseAftereachimageCheckBox     matlab.ui.control.CheckBox
        ChekPlotHistogramsImagesCheckBox  matlab.ui.control.CheckBox
        FrameRateSpinnerLabel           matlab.ui.control.Label
        FrameRateSpinner                matlab.ui.control.Spinner
        FrameRateLabel                  matlab.ui.control.Label
        NumberoftimestorepeatfirstframeLabel  matlab.ui.control.Label
        NumberoftimestorepeatfirstframeEditField  matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel           matlab.ui.control.Label
        ColumnsEditField                matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        ResizeImagesCheckBox            matlab.ui.control.CheckBox
        SelectOutputFolderButton        matlab.ui.control.Button
        OutputFolder                    matlab.ui.control.TextArea
        OutputBaseFileNameTextAreaLabel  matlab.ui.control.Label
        OutputBaseFileNameTextArea      matlab.ui.control.TextArea
        EXPORTTOIMAGEFILTERINGButton    matlab.ui.control.Button
        SaveDirectoryButton             matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        FinishNowCheckBox               matlab.ui.control.CheckBox
        VisualizationLampLabel          matlab.ui.control.Label
        VisualizationLamp               matlab.ui.control.Lamp
        GridLayout20                    matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.UIAxes
        GridLayout21                    matlab.ui.container.GridLayout
        CommandWindowTextAreaLabel_2    matlab.ui.control.Label
        Result_2                        matlab.ui.control.TextArea
        Plot                            matlab.ui.control.UIAxes
        FILTERINGTab                    matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        Panel_11                        matlab.ui.container.Panel
        DirectoryFolderFramesToFilter   matlab.ui.control.TextArea
        DeleteFrameButton               matlab.ui.control.Button
        SelectAllSamplesButton          matlab.ui.control.Button
        FrameListToFilter               matlab.ui.control.ListBox
        ClearButton                     matlab.ui.control.Button
        FrameAfterFilter                matlab.ui.control.ListBox
        VisualizationLamp_3Label        matlab.ui.control.Label
        VisualizationLamp_3             matlab.ui.control.Lamp
        FinishNowCheckBox_3             matlab.ui.control.CheckBox
        Panel_12                        matlab.ui.container.Panel
        ImageAdjustButton               matlab.ui.control.Button
        HistogramEqualizationButton     matlab.ui.control.Button
        AdaptiveHistogramEquButton      matlab.ui.control.Button
        FlatFieldcorrectionButton       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Sigma                           matlab.ui.control.Spinner
        MedianfilteringButton           matlab.ui.control.Button
        AdaptiveFilteringButton         matlab.ui.control.Button
        DenoisedButton                  matlab.ui.control.Button
        CheckProcessCheckBox_2          matlab.ui.control.CheckBox
        DeleteFilterButton              matlab.ui.control.Button
        ListFilterUsed                  matlab.ui.control.ListBox
        ApplyFiltersButton              matlab.ui.control.Button
        ListFiltersApplied              matlab.ui.control.ListBox
        SelectOutputFolderButton_3      matlab.ui.control.Button
        OutputFolder_3                  matlab.ui.control.TextArea
        OutputBaseFileNameTextArea_3Label  matlab.ui.control.Label
        OutputBaseFileNameTextArea_3    matlab.ui.control.TextArea
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        Panel_13                        matlab.ui.container.Panel
        Result_3                        matlab.ui.control.TextArea
        SaveImageFilteredButton         matlab.ui.control.Button
        EXPORTTOBKGSUBSTRACTIONButton_2  matlab.ui.control.Button
        Plot_2                          matlab.ui.control.UIAxes
        Image_3                         matlab.ui.control.UIAxes
        BACKGROUNDSUBTRACTIONMODELTab   matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        Panel_14                        matlab.ui.container.Panel
        DirectoryFolderFramesToBackground  matlab.ui.control.TextArea
        RefreshButton                   matlab.ui.control.Button
        DeleteFrameButton_2             matlab.ui.control.Button
        SelectAllSamplesButton_2        matlab.ui.control.Button
        FrameListBackground             matlab.ui.control.ListBox
        GetCoresInfoButton              matlab.ui.control.Button
        FinishNowCheckBox_4             matlab.ui.control.CheckBox
        CoresNumberDropDownLabel        matlab.ui.control.Label
        CoresNumberDropDown             matlab.ui.control.DropDown
        Create3DARRAYButton             matlab.ui.control.Button
        Create3DARRAYButton_2           matlab.ui.control.Button
        BackgroundModellist             matlab.ui.control.ListBox
        DeleteFrameButton_3             matlab.ui.control.Button
        SelectAllSamplesButton_3        matlab.ui.control.Button
        VisualizationLamp_4Label        matlab.ui.control.Label
        VisualizationLamp_4             matlab.ui.control.Lamp
        Panel_15                        matlab.ui.container.Panel
        SelectInputFolderButton_3       matlab.ui.control.StateButton
        DirectoryFolderFrames_2         matlab.ui.control.TextArea
        BackgroundList                  matlab.ui.control.ListBox
        BackGroundMedianOperationButton  matlab.ui.control.Button
        BackGroundMeanOperationButton   matlab.ui.control.Button
        BackGroundModeOperationButton   matlab.ui.control.Button
        CheckProcessCheckBox_3          matlab.ui.control.CheckBox
        SelectOutputFolderButton_4      matlab.ui.control.Button
        OutputFolder_4                  matlab.ui.control.TextArea
        OutputBaseFileNameTextArea_4Label  matlab.ui.control.Label
        OutputBaseFileNameTextArea_4    matlab.ui.control.TextArea
        GridLayout26                    matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        Panel_16                        matlab.ui.container.Panel
        SaveBackgroundImageButton       matlab.ui.control.Button
        EXPORTTOANALIZEButton           matlab.ui.control.Button
        CommandWindowTextAreaLabel_3    matlab.ui.control.Label
        Result_4                        matlab.ui.control.TextArea
        Image_4                         matlab.ui.control.UIAxes
        ANALYZETab                      matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        SelectInputImagesFolderButton   matlab.ui.control.StateButton
        DirectoryFolderFrames_3         matlab.ui.control.TextArea
        ImagesToAnalizeList             matlab.ui.control.ListBox
        DeleteFrameButton_4             matlab.ui.control.Button
        SelectAllSamplesButton_4        matlab.ui.control.Button
        SelectInputBackgroundFolderButton  matlab.ui.control.StateButton
        DirectoryFolderBackground       matlab.ui.control.TextArea
        BackgroundList_2                matlab.ui.control.ListBox
        FinishNowCheckBox_5             matlab.ui.control.CheckBox
        CheckProcessCheckBox_4          matlab.ui.control.CheckBox
        VisualizationLamp_5Label        matlab.ui.control.Label
        VisualizationLamp_5             matlab.ui.control.Lamp
        BkGStaticProcessFrameButton     matlab.ui.control.StateButton
        DilatePixelSizeLabel            matlab.ui.control.Label
        DilatePixelSizeEditField        matlab.ui.control.NumericEditField
        SmoothPixelSizeLabel            matlab.ui.control.Label
        SmoothPixelSizeEditField        matlab.ui.control.NumericEditField
        BkGStaticProcessEntireListButton  matlab.ui.control.StateButton
        ThresholdThsEditFieldLabel      matlab.ui.control.Label
        ThresholdThsEditField           matlab.ui.control.NumericEditField
        HighGraylimitLabel              matlab.ui.control.Label
        HighGraylimitEditField          matlab.ui.control.NumericEditField
        GPUCheckBox_3                   matlab.ui.control.CheckBox
        AverageMethodButton             matlab.ui.control.StateButton
        FuzzyApplicationButton          matlab.ui.control.StateButton
        ThresholdThuEditFieldLabel      matlab.ui.control.Label
        ThresholdThuEditField           matlab.ui.control.NumericEditField
        ThresholdThFsEditFieldLabel     matlab.ui.control.Label
        ThresholdThFsEditField          matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        CoresNumberLabel                matlab.ui.control.Label
        CoresNumberDropDown_2           matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Image_5                         matlab.ui.control.UIAxes
        Result_5                        matlab.ui.control.TextArea
        Tab2_3                          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Image_6                         matlab.ui.control.UIAxes
        Image_7                         matlab.ui.control.UIAxes
        Image_8                         matlab.ui.control.UIAxes
        Image_9                         matlab.ui.control.UIAxes
        Image_10                        matlab.ui.control.UIAxes
        Image_11                        matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        Image_12                        matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        SegmentationOrPerimeterCheckBox  matlab.ui.control.CheckBox
        CheckButton                     matlab.ui.control.StateButton
        PlotADropDownLabel              matlab.ui.control.Label
        PlotADropDown                   matlab.ui.control.DropDown
        PlotBDropDownLabel              matlab.ui.control.Label
        PlotBDropDown                   matlab.ui.control.DropDown
        Image_13                        matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        Image_14                        matlab.ui.control.UIAxes
        Image_15                        matlab.ui.control.UIAxes
        Image_16                        matlab.ui.control.UIAxes
        Image_17                        matlab.ui.control.UIAxes
        Tab5                            matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        Image_18                        matlab.ui.control.UIAxes
        Image_19                        matlab.ui.control.UIAxes
        Panel_5                         matlab.ui.container.Panel
        ThresholdPlotEditFieldLabel     matlab.ui.control.Label
        ThresholdPlotEditField          matlab.ui.control.NumericEditField
        PercentEditFieldLabel           matlab.ui.control.Label
        PercentEditField                matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        Image_20                        matlab.ui.control.UIAxes
        Tab6                            matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        Image_22                        matlab.ui.control.UIAxes
        Panel_6                         matlab.ui.container.Panel
        VisualizationLamp_6Label        matlab.ui.control.Label
        VisualizationLamp_6             matlab.ui.control.Lamp
        Result_6                        matlab.ui.control.TextArea
        Image_21                        matlab.ui.control.UIAxes
        Tab7                            matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        Image_23                        matlab.ui.control.UIAxes
        Tab8                            matlab.ui.container.Tab
        Image_24                        matlab.ui.control.UIAxes
        Tab9                            matlab.ui.container.Tab
        GridLayout37                    matlab.ui.container.GridLayout
        GridLayout38                    matlab.ui.container.GridLayout
        Result_7                        matlab.ui.control.TextArea
        Image_25                        matlab.ui.control.UIAxes
        Panel_21                        matlab.ui.container.Panel
        SelectOutputFolderButton_6      matlab.ui.control.Button
        OutputFolder_6                  matlab.ui.control.TextArea
        OutputBaseFileNameTextArea_4Label_3  matlab.ui.control.Label
        OutputBaseFileNameTextArea_6    matlab.ui.control.TextArea
        FramesToPIVList                 matlab.ui.control.ListBox
        DeleteFrameButton_5             matlab.ui.control.Button
        SelectAllSamplesButton_5        matlab.ui.control.Button
        SaveImagestoanalizeusingPIVButton  matlab.ui.control.Button
        MediaFil_3                      matlab.ui.control.CheckBox
        AdjustContrastCheckBox_3        matlab.ui.control.CheckBox
        windowEditFieldLabel            matlab.ui.control.Label
        windowEditField                 matlab.ui.control.NumericEditField
        LowPassFilteringCheckBox        matlab.ui.control.CheckBox
        VIDEOTab                        matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        Panel_17                        matlab.ui.container.Panel
        CheckCameraButton               matlab.ui.control.Button
        SelectOutputFolderButton_5      matlab.ui.control.Button
        OutputFolder_5                  matlab.ui.control.TextArea
        OutputBaseFileNameTextArea_4Label_2  matlab.ui.control.Label
        OutputBaseFileNameTextArea_5    matlab.ui.control.TextArea
        NameAdaptorEditFieldLabel       matlab.ui.control.Label
        NameAdaptorEditField            matlab.ui.control.EditField
        DeviceIDEditFieldLabel          matlab.ui.control.Label
        DeviceIDEditField               matlab.ui.control.NumericEditField
        SupportedFormats                matlab.ui.control.ListBox
        HeightLabel                     matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        WidthLabel                      matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        ExposureTimeLabel               matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
        FramesPerTriggerLabel           matlab.ui.control.Label
        FramesPerTriggerEditField       matlab.ui.control.NumericEditField
        PreviewButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        FinishCheckBox                  matlab.ui.control.CheckBox
        Panel_19                        matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        Tab_3                           matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        FrameVideo                      matlab.ui.control.UIAxes
        StatusCamera                    matlab.ui.control.TextArea
        Tab2_4                          matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        Panel_18                        matlab.ui.container.Panel
        TESTFRAMESButton                matlab.ui.control.Button
        MediaFil                        matlab.ui.control.CheckBox
        FrameGrabInttervalEditFieldLabel  matlab.ui.control.Label
        FrameGrabInttervalEditField     matlab.ui.control.NumericEditField
        AdjustContrastCheckBox          matlab.ui.control.CheckBox
        GPUCheckBox                     matlab.ui.control.CheckBox
        FrameHamamatsuList              matlab.ui.control.ListBox
        TextArea                        matlab.ui.control.TextArea
        CaptureButton                   matlab.ui.control.Button
        HistogramCheckBox               matlab.ui.control.CheckBox
        FramesEditFieldLabel            matlab.ui.control.Label
        FramesEditField                 matlab.ui.control.NumericEditField
        SaveButton                      matlab.ui.control.Button
        GridLayout32                    matlab.ui.container.GridLayout
        HistogramVideoFrame             matlab.ui.control.UIAxes
        FrameRVideo                     matlab.ui.control.UIAxes
        Tab3_2                          matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        Panel_20                        matlab.ui.container.Panel
        SelectInputBackgroundFolderButton_2  matlab.ui.control.StateButton
        DirectoryFolderBackground_2     matlab.ui.control.TextArea
        BackgroundList_3                matlab.ui.control.ListBox
        FrameGrabInttervalEditField_2Label  matlab.ui.control.Label
        FrameGrabInttervalEditField_2   matlab.ui.control.NumericEditField
        TESTFRAMESButton_2              matlab.ui.control.Button
        CaptureButton_2                 matlab.ui.control.Button
        FramesEditField_2Label          matlab.ui.control.Label
        FramesEditField_2               matlab.ui.control.NumericEditField
        MediaFil_2                      matlab.ui.control.CheckBox
        AdjustContrastCheckBox_2        matlab.ui.control.CheckBox
        GPUCheckBox_2                   matlab.ui.control.CheckBox
        ListBox_2                       matlab.ui.control.ListBox
        TextArea_2                      matlab.ui.control.TextArea
        ThresholdThsEditField_2Label    matlab.ui.control.Label
        ThresholdThsEditField_2         matlab.ui.control.NumericEditField
        HighGraylimitEditField_2Label   matlab.ui.control.Label
        HighGraylimitEditField_2        matlab.ui.control.NumericEditField
        ThresholdThuEditField_2Label    matlab.ui.control.Label
        ThresholdThuEditField_2         matlab.ui.control.NumericEditField
        ThresholdThFsEditField_2Label   matlab.ui.control.Label
        ThresholdThFsEditField_2        matlab.ui.control.NumericEditField
        AlphaEditField_2Label           matlab.ui.control.Label
        AlphaEditField_2                matlab.ui.control.NumericEditField
        AverageMethodButton_2           matlab.ui.control.StateButton
        FuzzyApplicationButton_2        matlab.ui.control.StateButton
        CoresNumberDropDown_3Label      matlab.ui.control.Label
        CoresNumberDropDown_3           matlab.ui.control.DropDown
        GridLayout34                    matlab.ui.container.GridLayout
        BackgroundImage                 matlab.ui.control.UIAxes
        LiveVideo                       matlab.ui.control.UIAxes
        ForegroundH                     matlab.ui.control.UIAxes
        Perimeter                       matlab.ui.control.UIAxes
        Tab4_2                          matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        GrayScaleBackground             matlab.ui.control.UIAxes
        Tab5_2                          matlab.ui.container.Tab
        GridLayout36                    matlab.ui.container.GridLayout
        GridLayout39                    matlab.ui.container.GridLayout
        Panel_22                        matlab.ui.container.Panel
        FramesToPIVList_2               matlab.ui.control.ListBox
        DeleteFrameButton_6             matlab.ui.control.Button
        SelectAllSamplesButton_6        matlab.ui.control.Button
        SaveImagestoanalizeusingPIVButton_2  matlab.ui.control.Button
        MediaFil_4                      matlab.ui.control.CheckBox
        AdjustContrastCheckBox_4        matlab.ui.control.CheckBox
        windowEditField_2Label          matlab.ui.control.Label
        windowEditField_2               matlab.ui.control.NumericEditField
        LowPassFilteringCheckBox_2      matlab.ui.control.CheckBox
        BkGStaticProcessEntireListButton_2  matlab.ui.control.Button
        MediaFil_5                      matlab.ui.control.CheckBox
        AdjustContrastCheckBox_5        matlab.ui.control.CheckBox
        GPUCheckBox_4                   matlab.ui.control.CheckBox
        AverageMethodButton_3           matlab.ui.control.StateButton
        FuzzyApplicationButton_3        matlab.ui.control.StateButton
        CoresNumberDropDown_3Label_2    matlab.ui.control.Label
        CoresNumberDropDown_4           matlab.ui.control.DropDown
        FramesEditField_3Label          matlab.ui.control.Label
        FramesEditField_3               matlab.ui.control.NumericEditField
        ThresholdThsEditField_3Label    matlab.ui.control.Label
        ThresholdThsEditField_3         matlab.ui.control.NumericEditField
        HighGraylimitEditField_3Label   matlab.ui.control.Label
        HighGraylimitEditField_3        matlab.ui.control.NumericEditField
        ThresholdThuEditField_3Label    matlab.ui.control.Label
        ThresholdThuEditField_3         matlab.ui.control.NumericEditField
        ThresholdThFsEditField_3Label   matlab.ui.control.Label
        ThresholdThFsEditField_3        matlab.ui.control.NumericEditField
        AlphaEditField_3Label           matlab.ui.control.Label
        AlphaEditField_3                matlab.ui.control.NumericEditField
        CheckProcessCheckBox_5          matlab.ui.control.CheckBox
        Image_26                        matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        VideoFolder; % Description
        ImageFolder; % Description
        ProgramFolder; % Description
        OutputVideoFolder; % Description
        OutputFramesFolder; % Description
        Video; % Description
        OutputFinalFramesFolder; % Description
        FilteredImage{}; % Description
        OutputFramesFilteredFolder;  
        BackgroundFolder; % Description
        OutputBackgroundFolder; % Description
        FinalFilteredFolder; % Description
        ArrayOfImages(:,:,:); % Description
        BackgroundModel{}; % Description
        FinalBackgroundFolder;
        FramesFolderUsed;
        VideoH; % Description
        FramesHamamatsuFolder; % Description
        FramesHamamatsu{}; % Description
        FramesToPIV{};
        OutputFramesToPIVFolder; % Description
    end
    
    methods (Access = private)
        
        function LoadVideoList(app)
            try
                ListOfVideosNames = {};
                InputDirectory = app.VideoFolder;
                if ~isempty(app.VideoFolder)
                    if exist(InputDirectory, 'dir') == false
                        warningMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an video folder.', app.VideoFolder);
                        f = app.UIFigure;
                        uialert(f,warningMessage,'Warning',...
                        'Icon','warning');
			% Make it something, so that we can continue.
			app.VideoFolder = pwd;
                    end
                else
                     f = app.UIFigure;
                     uialert(f,'No folder specified as input for function LoadImageList.','Warning',...
                     'Icon','warning');
                     app.VideoFolder = pwd;
                end
                % If it gets to here, the folder is good.
                VideoFiles = dir([app.VideoFolder '/*.*']);
                %Read all video Files
                for Index = 1:length(VideoFiles)
                       	baseFileName = VideoFiles(Index).name;
                	[folder,name, extension] = fileparts(baseFileName);
                	extension = upper(extension);
                	switch lower(extension)
                		case {'.avi', '.mj2', '.ogg', '.ogv'}
                			% Allow only PNG, TIF, JPG, or BMP images
                			ListOfVideosNames = [ListOfVideosNames baseFileName];
                		otherwise
                	end
                end
                app.VideoListBox.Items = ListOfVideosNames; 
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
        function SaveUserSettings(app)
            try   
                	matFullFileName = fullfile(app.ProgramFolder, [mfilename, '.mat']);
                	% Save the current folder they're looking at.
                        lastUsedVideoFolder = app.VideoFolder;
                	lastUsedImageFolder = app.ImageFolder;
                        lastUsedOutputVideoFolder = app.OutputVideoFolder;
                        lastUsedOutputFramesFolder = app.OutputFramesFolder;
                        lastOutputFinalFramesFolder = app.OutputFinalFramesFolder;
                        lastBackgroundFolder = app.BackgroundFolder;
                        lastOutputFramesFilteredFolder = app.OutputFramesFilteredFolder;
                        lastOutputBackgroundFolder = app.OutputBackgroundFolder;
                        lastFinalFilteredFolder = app.FinalFilteredFolder;
                        lastFinalBackgroundFolder = app.FinalBackgroundFolder;
                        lastFramesFolderUsed = app.FramesFolderUsed;
                        lastOutputFramesToPIVFolder = app.OutputFramesToPIVFolder;


                	% Get current value of GUI controls, like checkboxes, etc.
                	appSettings.chkPauseAfterImage = app.PauseAftereachimageCheckBox.Value;
                	appSettings.chkPlotHistograms = app.ChekPlotHistogramsImagesCheckBox.Value;
                	appSettings.sldFrameRate = app.FrameRateSpinner.Value;
                	appSettings.radSortAlpha = app.SortAlphabeticallyCheckBox.Value;
                	appSettings.radSortTime = app.SortbyfiledatetimeCheckBox.Value;
                	% Save all the settings to a .mat file.
                	%%save(matFullFileName, 'lastUsedImageFolder', 'guiSettings');
                        save(matFullFileName, 'lastUsedImageFolder', 'lastUsedVideoFolder', 'lastBackgroundFolder','lastUsedOutputVideoFolder', 'lastUsedOutputFramesFolder','lastOutputFinalFramesFolder','lastOutputFramesFilteredFolder','lastOutputBackgroundFolder','lastFinalFilteredFolder','lastFinalBackgroundFolder','lastFramesFolderUsed','lastOutputFramesToPIVFolder','appSettings');
                catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                        mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end           
        end
        
        function LoadUserSettings(app)
            try
                % Load up the initial values from the mat file.
                matFullFileName = fullfile(app.ProgramFolder, [mfilename, '.mat']);
                if exist(matFullFileName, 'file')
                	% Pull out values and stuff them in structure initialValues.
                	initialValues = load(matFullFileName);
                	% Assign the image folder from the lastUsedImageFolder field of the structure.
                	app.ImageFolder = initialValues.lastUsedImageFolder;
                        app.VideoFolder = initialValues.lastUsedVideoFolder;
                        app.BackgroundFolder = initialValues.lastBackgroundFolder;
                	app.OutputFramesFolder = initialValues.lastUsedOutputFramesFolder;
                        app.OutputVideoFolder = initialValues.lastUsedOutputVideoFolder;
                        app.OutputFinalFramesFolder = initialValues.lastOutputFinalFramesFolder;
                        app.OutputFramesFilteredFolder = initialValues.lastOutputFramesFilteredFolder;
                        app.OutputBackgroundFolder = initialValues.lastOutputBackgroundFolder;
                        app.FinalFilteredFolder = initialValues.lastFinalFilteredFolder;
                        app.FinalBackgroundFolder = initialValues.lastFinalBackgroundFolder;
                        app.FramesFolderUsed = initialValues.lastFramesFolderUsed;
                        app.OutputFramesToPIVFolder = initialValues.lastOutputFramesToPIVFolder;
                        
                	app.OutputFolder.Value = char(app.OutputVideoFolder);	
                        app.OutputFolder_2.Value = char(app.OutputFramesFolder);
                        app.DirectoryFolderFramesToFilter.Value = char(app.OutputFinalFramesFolder);
                       	app.OutputFolder_3.Value = char(app.OutputFramesFilteredFolder);
                        app.DirectoryFolderFrames_2.Value = char(app.BackgroundFolder);
                        app.OutputFolder_4.Value = char(app.OutputBackgroundFolder);
                        app.DirectoryFolderFramesToBackground.Value = char(app.FinalFilteredFolder);
                        app.DirectoryFolderFrames_3.Value = char(app.FramesFolderUsed);
                        app.DirectoryFolderBackground.Value = char(app.FinalBackgroundFolder);
                        
                        app.OutputFolder_6.Value = char(app.OutputFramesToPIVFolder);
                        
                	% Get the last state of the controls.
                	chkPauseAfterImage = initialValues.appSettings.chkPauseAfterImage;
                	chkPlotHistograms = initialValues.appSettings.chkPlotHistograms;
                	sldFrameRate = initialValues.appSettings.sldFrameRate;
                	radSortAlpha = initialValues.appSettings.radSortAlpha;
                	radSortTime = initialValues.appSettings.radSortTime;
                	% Send those recalled values to their respective controls on the GUI.
                	app.PauseAftereachimageCheckBox.Value = chkPauseAfterImage;
                        app.ChekPlotHistogramsImagesCheckBox.Value = chkPlotHistograms;
                	app.FrameRateSpinner.Value = sldFrameRate;
                        app.SortAlphabeticallyCheckBox.Value = radSortAlpha;
                	app.SortbyfiledatetimeCheckBox.Value = radSortTime;
                		
                	else
                		% If the mat file file does not exist yet, save the settings out to a new settings .mat file.
                		SaveUserSettings(app);
                	end
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure; 
                uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
            
        end
        
        function SpinnerValueChanged(app)
            try
                % Set up the Horizontal Scrollbar caption.
                SpinnerValue = app.FrameRateSpinner.Value;
                FramesPerSecond = SpinnerValue;
                caption = sprintf('Frame Rate = %.2f frames per second', FramesPerSecond);
                app.FrameRateLabel.Text = caption;
                % Find out how many frames are selected.
                selectedListboxItem = app.FrameList.Value;
            	if isempty(selectedListboxItem)
            		% None are selected.  Find out how many are in the listbox.
            		ListOfImageNames = app.FrameList.Items;
            		numFrames = length(ListOfImageNames);
            	else
            		numFrames = length(selectedListboxItem);
            	end
                txtInfo = sprintf('Number of frames = %.2f frames.\nFrame Rate = %.2f frames per second.\nTotal movie length = %.1f seconds.',numFrames, FramesPerSecond, numFrames / FramesPerSecond);
            	app.Result_2.Value = txtInfo;          
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure; 
                uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
            
        end
        
         function UpdateAnalyzeButtonCaption(app)
                    value = app.FrameList.Value;
                   % if length(Selected) > 1
                   % 	buttonCaption = sprintf('Step 6:  Go! Process %d images', length(Selected));
                   % 	set(handles.btnAnalyze, 'string', buttonCaption);
                   % 	set(handles.btnAnalyze, 'Tooltipstring', 'Display and process the selected image(s)');
                   % elseif length(Selected) == 1
                   % 	set(handles.btnAnalyze, 'string', 'Step 6:  Go! Process 1 image');
                   % 	set(handles.btnAnalyze, 'Tooltipstring', 'Display and process the selected image');
                   % else
                   % 	set(handles.btnAnalyze, 'string', 'Step 6:  Stop! Process no images');
                   % 	set(handles.btnAnalyze, 'Tooltipstring', 'Please select image(s) first');
                   % end
        end
        
        function imageArray = DisplayImage(app, fullImageFileName)
                % Read in image.
                imageArray = []; % Initialize
                try
                        [imageArray, colorMap] = imread(fullImageFileName);
                	% colorMap will have something for an indexed image (gray scale image with a stored colormap).
                	% colorMap will be empty for a true color RGB image or a monochrome gray scale image.
                	% The orientation should be 1.  If the orientation is 3, the image is upside down and needs to be rotated.
                	info = imfinfo(fullImageFileName);
                	hasField = isfield(info, 'Orientation');
                	if hasField
                		if info.Orientation == 3
                			imageArray = imrotate(imageArray, 180);
                		end
                	end
                catch ME
                        % Will get here if imread() fails, like if the file is not an image file but a text file or Excel workbook or something.
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                	return;	% Skip the rest of this function
                end
                try
                        % Display image array in a window on the user interface.
                         %hold off;	% IMPORTANT NOTE: hold needs to be off in order for the "fit" feature to work correctly.
                         % Here we actually display the image in the "axesImage" axes.
                         
                        [rows, columns, map] = size(imageArray);
                        RI = imref2d(size(imageArray));
                        imshow(imageArray, RI, 'InitialMagnification', 'fit', 'parent', app.Image_2);
                        %axis('image', 'on');
                        % Display a title above the image.
                	[~, basefilename, extension] = fileparts(fullImageFileName);
                	extension = lower(extension);
                	% Display the title.
                        caption = [basefilename, extension];
                        %app.Image.Title.String = caption;
                	title(app.Image_2, caption, 'Interpreter', 'none', 'FontSize', 14);
                        app.Image_2.XLabel.String = 'PIXELS X AXIS '; 
                        app.Image_2.XLim =[0,columns]; 
                        app.Image_2.YLim =[0,rows]; 
                        app.Image_2.YLabel.String = 'PIXELS Y AXIS ';
                        %grid on;
                        [rows, columns, numberOfColorChannels] = size(imageArray);
                	% Get the file date
                	fileInfo = dir(fullImageFileName);
                	txtInfo = sprintf('%s\n\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                		[basefilename extension], rows, columns, numberOfColorChannels);
                	% Tell user the type of image it is.
                	if numberOfColorChannels == 3
                		colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a true color, RGB image.', txtInfo);
                	elseif numberOfColorChannels == 1 && isempty(colorMap)
                		colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a gray scale image, with no stored color map.', txtInfo);
                	elseif numberOfColorChannels == 1 && ~isempty(colorMap)
                		txtInfo = sprintf('%s\nThis is an indexed image.  It has one "value" channel with a stored color map that is used to pseudocolor it.', txtInfo);
                		%colormap(colorMap);
                                %app.Image.Colormap(colorMap)
                                %app.UIFigure.Colormap(colorMap)
                		%whos colorMap;
                		%fprintf('About to apply the colormap...\n');
                		% Thanks to Jeff Mather at the Mathworks to helping to get this working for an indexed image.
                               
                		colorbar(app.Image_2);
                		%fprintf('Done applying the colormap.\n');
                	end
                	% Show the file time and date.
                	txtInfo = sprintf('%s\n\n%s', txtInfo, fileInfo.date);
                	app.Result_2.Value = txtInfo;
                        % Plot the histogram if requested.
                	plotHistogram = app.ChekPlotHistogramsImagesCheckBox.Value;
                        cla(app.Plot);
                	if plotHistogram
                                 % fprintf('About to plot histogram...\n');
                		PlotImageHistogram(app, imageArray);
                                 % fprintf('Done plotting histogram.\n');
                	end
                catch ME
                      errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);               	
                       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');      
                end
            
        end
        function  PlotImageHistogram(app, imageArray)
                        try
                        	numberOfDimensions = ndims(imageArray);
                        	if numberOfDimensions == 1 || numberOfDimensions == 2
                        		% Grayscale 2D or 1D image.
                        		PlotGrayscaleHistogram(app, imageArray);
                        	elseif numberOfDimensions == 3
                        		% True color RGB image.
                        		PlotRGBHistograms(app, imageArray);
                        	else
                        		errorMessage = sprintf('Histograms are only supported for grayscale (2D) or color (3D) images');
                        		f = app.UIFigure;
                                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                           uialert(f,errorMessage,'Warning',...
                                           'Icon','warning');
                        	end
                        catch ME
                        	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                        		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                        	f = app.UIFigure;
                                   %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                        end
            
        end
          function [minGL, maxGL] = PlotGrayscaleHistogram(app, grayImage)
                try
                	% Plot the histogram in the histogram viewport.
                	cla(app.Plot);
                        axis(app.Plot,'on');  % makes existing axes handles.axesPlot the current axes.
                	% Get the histogram     
                	%[pixelCounts, grayLevels] = imhist(grayImage);
                	histogram(app.Plot,  grayImage); 
                	% Plot it.
                	%bar(pixelCounts);
                	title(app.Plot, 'Histogram of Gray Image', 'FontSize', 12);
                        app.Plot.XLabel.String ='pixel intensity' ;
                        app.Plot.YLabel.String = ' number of pixels' ;
                	%grid on;
                	% Get the min and max GL for fun.
                	minGL = min(grayImage(:));
                	maxGL = max(grayImage(:));
                	% Set up the x axis of the histogram to go from 0 to the max value allowed for this type of integer (uint8 or uint16).
                	%theClass = class(grayImage);
                	%%% Only set xlim for integers.  For example the demo image blobs.png is logical not integer even though it's grayscale.
                	%%%if strfind(theClass, 'int')
                	%	xlim([0 intmax(theClass)]);
                	%%%%%end
                catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                end
            
        end
        
        function [minGL, maxGL, gl1Percentile, gl99Percentile] = PlotRGBHistograms(app, imgColorImageArray)
                try
                                cla(app.Plot);
                                axis(app.Plot,'on');      
                        title(app.Plot, 'Red, Green, and Blue Histograms', 'FontSize', 12);
                        app.Plot.XLabel.String ='pixel intensity' ;
                        app.Plot.YLabel.String = ' number of pixels' ;
                	% Get a histogram of the entire image.  But imhist only allows 2D images, not color ones.
                	% First get individual channels from the original image.
                        %clf(app.Plot);
                	redBand = imgColorImageArray(:,:,1);
                	greenBand = imgColorImageArray(:,:,2);
                	blueBand = imgColorImageArray(:,:,3);	
                	% Use 256 bins.
                	[redCounts, redGLs] = imhist(redBand, 256);    % make sure you label the axes after imhist because imhist will destroy them.
                	[greenCounts, ~] = imhist(greenBand, 256);    % make sure you label the axes after imhist because imhist will destroy them.
                	[blueCounts, ~] = imhist(blueBand, 256);    % make sure you label the axes after imhist because imhist will destroy them.
                	% GLs goes from 0 (at element 1) to 255 (at element 256) but only some
                	% these bins have data in them.  The upper ones may be 0.  Find the last
                	% non-zero bin so we can plot just up to there to get better horizontal resolution.
                	maxBinUsedR = find(redCounts, 1, 'last' );
                	maxBinUsedG = find(greenCounts, 1, 'last' );
                	maxBinUsedB = find(blueCounts, 1, 'last' );
                	% If the entire image is zero, have the max bin be 0.
                        	if isempty(maxBinUsedR)
                                    maxBinUsedR = 1; 
                         end  
                        	if isempty(maxBinUsedG) 
                             maxBinUsedG = 1; 
                         end
                        	if isempty(maxBinUsedB)
                             maxBinUsedB = 1; 
                         end
                	% Take the largest one overall for plotting them all.
                	maxBinUsed = max([maxBinUsedR maxBinUsedG maxBinUsedB]);
                	% Get subportion of array that has non-zero data.
                	redCounts = redCounts(1:maxBinUsed);
                	greenCounts = greenCounts(1:maxBinUsed);
                	blueCounts = blueCounts(1:maxBinUsed);
                	GLs = redGLs(1:maxBinUsed);
                	% Assign the max and min for the 3 color bands.
                	minGL(1) = GLs(maxBinUsedR);
                	maxGL(2) = GLs(maxBinUsedG);
                	maxGL(3) = GLs(maxBinUsedB);
                	% Calculate the 1% and 99% value of the CDF for the 3 color bands.
                	gl1Percentile = ones(3, 1);	    % Preallocate one element for each color.
                	gl99Percentile = ones(3, 1);		% Preallocate one element for each color.
                	for color = 1:3
                		switch color
                			case 1
                				counts = redCounts;
                			case 2
                				counts = greenCounts;
                			case 3
                				counts = blueCounts;
                		end
                		summed = sum(counts);
                		cdf = 0;
                		for bin = 1 : maxBinUsed
                			cdf = cdf + counts(bin);
                			if cdf < 0.01 * summed
                				gl1Percentile(color) = GLs(bin);
                			end
                			if cdf > 0.99 * summed
                				break;
                			end
                		end
                		gl99Percentile(color) = GLs(bin);
                	end
                	% Plot the histogram in the histogram viewport.
                	%axes(app.Plot);  % makes existing axes handles.axesPlot the current axes.
                        %histogram(app.Plot,  imgColorImageArray);
                        %plot(app.Plot,GLs, blueCounts, 'b', 'LineWidth', 3 )
                        
                	% Plot the histogram as a line curve.
                	%plot(GLs, blueCounts, 'b', 'LineWidth', 3);
                        hold (app.Plot, 'on');
                        plot(app.Plot,GLs, blueCounts, 'b', 'LineWidth', 3  );
                	%hold on;
                	%plot(GLs, greenCounts, 'g', 'LineWidth', 3);
                        plot(app.Plot,GLs, greenCounts, 'g', 'LineWidth', 3 );
                	%plot(GLs, redCounts, 'r', 'LineWidth', 3);
                        plot(app.Plot,GLs, greenCounts, 'r', 'LineWidth', 3 );
                	hold (app.Plot, 'off');
                	%app.Plot.Title.String = 'Red, Green, and Blue Histograms';
                        %title('Red, Green, and Blue Histograms');
                	maxCountInHistogram = max([redCounts(:); greenCounts(:); blueCounts(:)]);
                	set(app.Plot,'YLim',[0 maxCountInHistogram]);
                	
                	% Plot the sum of all of them
                	% 		sumCounts = redCounts + greenCounts + blueCounts;
                	% 		plot(GLs, sumCounts, 'k', 'LineWidth', 1);  % Plot sum in black color.
                	% 		set(handles.axesPlot,'YLim',[0 max(sumCounts)]);
                	
                	% Set up custom tickmarks.
                	set(app.Plot,'XTick',      [0 20 40 60 80 100 120 140 160 180 200 220 240 255]);
                	set(app.Plot,'XTickLabel', {0 20 40 60 80 100 120 140 160 180 200 220 240 255});
                	set(get(app.Plot,'XLabel'),'string','Gray Level');
                	%app.ylabel('# of Pixels');
                	%xlabel('Gray Level');
                	%xlim([0 255]);	
                	%axes(app.Image);	% Switch current figure back to image box.
                catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                end
        end
       function  LoadImageList(app)
                try
                	ListOfImageNames = {};
                	folder = app.ImageFolder;
                	if ~isempty(app.ImageFolder)
                		if exist(folder,'dir') == false
                			errorMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an image folder.', app.ImageFolder);
                			f = app.UIFigure;
                                       %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                       uialert(f,errorMessage,'Warning',...
                                       'Icon','warning');
                			% Make it something, so that we can continue.
                			app.ImageFolder = pwd;
                		end
                	else
                		msgboxw('No folder specified as input for function LoadImageList.');
                		app.ImageFolder = pwd;
                	end
                	% If it gets to here, the folder is good.
                	ImageFiles = dir([app.ImageFolder '/*.*']);
                	for Index = 1:length(ImageFiles)
                       		baseFileName = ImageFiles(Index).name;
                		[~, ~, extension] = fileparts(baseFileName);
                		extension = upper(extension);
                		switch lower(extension)
                			case {'.png', '.bmp', '.jpg', '.tif'}
                				% Allow only PNG, TIF, JPG, or BMP images
                				ListOfImageNames = [ListOfImageNames baseFileName];
                			otherwise
                		end
                	end
                        app.FrameList.Items = ListOfImageNames;
                        app.SelectionOptionButton.Text = 'Select All';
                        %app.List.Value = [];
                        %set(app.List.Items,'string',ListOfImageNames);
                	% If the current listbox has fewer items in it than the value of the selected item in the last folder,
                	% then the selected item will be off the end of the list, like you were looking at image 10 but the new folder has only 2 items in it.
                	% In cases like that, the entire listbox will not show up.  So you have to make sure that the selected item is not more than the
                	% number of items in the list.  We'll just deselect everything to make sure that doesn't happen.
                	% Select none of the items in the listbox.
                	%set(app.List, 'value', []);
                	% Change caption to say Select All
                	%%%%%%%%%%%%%%%%%%% 
                      
                        %app.SelectOptionLabel.Text = 'Select All';
                catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                            f = app.UIFigure;
                            uialert(f,errorMessage,'Warning',...
                            'Icon','warning');
                end
            
        end
        
        function LoadVideoInformation(app)
            try
                bitsPerPixel = app.Video.BitsPerPixel;
                Duration = app.Video.Duration;
                FrameRate = app.Video.FrameRate;
                NumberOfFrames = app.Video.NumberOfFrames;
                Height = app.Video.Height;
                Width = app.Video.Width;
                txtInfo = sprintf('Information about %s ',app.Video.Name);
                txtInfo = sprintf('%s\n\nBits Per Pixel = %g',txtInfo,(bitsPerPixel));
                txtInfo = sprintf('%s\nDuration = %g seconds', txtInfo,(Duration));
                txtInfo = sprintf('%s\nFrameRate = %g fps', txtInfo,(FrameRate));
                txtInfo = sprintf('%s\nNumberOfFrames = %g', txtInfo,(NumberOfFrames));
                txtInfo = sprintf('%s\nHeight = %g pixels', txtInfo,(Height));
                txtInfo = sprintf('%s\nWidth = %g pixels', txtInfo,(Width));
                app.Result.Value = txtInfo;
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);     
                            f = app.UIFigure;
                            uialert(f,errorMessage,'Warning',...
                            'Icon','warning');
            end
            
        end
        
        function WriteFramesOfVideo(app,OutputFolder)
            try
                    app.VisualizationLamp_2Label.Text = 'Processing';
                    app.VisualizationLamp_2.Color = [1 0 0];
                    drawnow;
                    startTime = tic;
                    outputFolder = OutputFolder;
                    
                    NumberOfFrames = app.Video.NumberOfFrames; 
                    [folder, name, extension] = fileparts(app.VideoListBox.Value);
                     numberOfFramesWritten = 0;
                     
                   for frame = 1 : NumberOfFrames
                                        if frame<10
                                        nume = sprintf('000%d.tif', frame);
                                        end
                                        if frame>=10 && frame<100
                                        nume = sprintf('00%d.tif', frame);
                                        end
                                        if frame>=100 && frame<1000
                                        nume = sprintf('0%d.tif', frame);
                                        end
                                        if frame>=1000 
                                        nume = sprintf('0%d.tif', frame);
                                        end
                                        baseFileNameFrame =  [name ' ' nume];
                			% Construct an output image file name.
                			%outputBaseFileName = sprintf('Frame %4.4d.png', frame);
                			outputFullFileName = fullfile(outputFolder, baseFileNameFrame);
                						% Stamp the name and frame number onto the image.
                			% At this point it's just going into the overlay,
                			% not actually getting written into the pixel values.
                			%text(5, 15, outputBaseFileName, 'FontSize', 20);
                			%Extract the image with the text "burned into" it.
                			%frameWithText = getframe(gca);
                			% frameWithText.cdata is the image with the text
                			% actually written into the pixel values.
                			% Write it out to disk.
                                        thisFrame = read (app.Video, frame);
                                        if app.GrayImageCheckBox.Value
                                            GrayImage=LoadFrameInformation(app,thisFrame);
                                            %Display_Image(app,GrayImage,baseImageFileName,app.Image);
                                            thisFrame = GrayImage;
                                        end
                			imwrite(thisFrame, outputFullFileName, 'tif');
                                        numberOfFramesWritten = numberOfFramesWritten + 1;
                                        if app.FinishNowCheckBox_2.Value
            			                 break;
                                        end
                                        if app.CheckProcessCheckBox.Value
            			               Display_Image(app,thisFrame,baseFileNameFrame,app.Image);  
                                        end
                                        
                    elapsedSeconds = toc(startTime);
                    app.Result.Value = sprintf('Building Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	    frame, NumberOfFrames, baseFileNameFrame, elapsedSeconds);
            	    drawnow;   
                    end
                     if frame == NumberOfFrames
                            finishedMessage = sprintf('Done!  It wrote %d frames to folder\n"%s"', numberOfFramesWritten, outputFolder);
                     end
                     if frame ~= NumberOfFrames
                            finishedMessage = sprintf('Done!  It wrote %d frames to folder\n"%s"', numberOfFramesWritten, outputFolder);
                    end
                app.Result.Value = finishedMessage; % Write to command window.
	       % uiwait(msgbox(finishedMessage)); % Also pop up a message box.
		% Exit if they didn't write any individual frames out to disk.
                    app.VisualizationLamp_2Label.Text = 'Done';
                    app.VisualizationLamp_2.Color = [0 0 1];
                    drawnow;
                    
           catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               %message = sprintf('Fire hazard! \n Consider reducing temperature.');
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
            
        end
        
        function Display_Image(app, thisFrame,titleOfFile,Axes)
            try
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        RI = imref2d(size(thisFrame));
                        imshow(thisFrame, RI, 'InitialMagnification', 'fit', 'Parent', Axes);
	                %axis('image', 'on');
                	% Display a title above the image.
                	%[folder, basefilename, extension] = fileparts(sourceFrameFullFileName);
                	%extension = lower(extension);
                	% Display the title.  
                	%caption = [basefilename, extension];
                        %app.Image.Title.String = caption;
                	title(Axes, titleOfFile, 'Interpreter', 'none', 'FontSize', 14);
                        Axes.XLabel.String = 'PIXELS X AXIS '; 
                        Axes.XLim =[0,columns]; 
                        Axes.YLim =[0,rows]; 
                        Axes.YLabel.String = 'PIXELS Y AXIS ';
                        %grid on;
                        %PlotImageHistogram2(app,thisFrame)
                   catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                         app.FinishNowCheckBox_2.Value = true; 
                               
            end      
        end
        
        function GrayImage=LoadFrameInformation(app,firstFrame)
            try      
                   %GrayImage = rgb2gray(firstFrame);
               [~, ~, numberOfColorChannels] = size(firstFrame);
                if numberOfColorChannels > 1
                    % It's a true color RGB image.  We need to convert to gray scale.
                    GrayImage = rgb2gray(firstFrame);
                else
                    % It's already gray scale.  No need to convert.
                    GrayImage = firstFrame;
                end       
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
            
        end
        
        function LoadFrameOfVideoInList(app,outputfolder)
            try
                ListOfVideosNames = {};
                InputDirectory = outputfolder;
                if ~isempty(app.VideoFolder)
                    if exist(InputDirectory, 'dir') == false
                        warningMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an video folder.', app.VideoFolder);
                        f = app.UIFigure;
                        uialert(f,warningMessage,'Warning',...
                        'Icon','warning');
			% Make it something, so that we can continue.
			outputfolder = pwd;
                    end
                else
                     f = app.UIFigure;
                     uialert(f,'No folder specified as input for function LoadImageList.','Warning',...
                     'Icon','warning');
                     outputfolder = pwd;
                end
                % If it gets to here, the folder is good.
                VideoFiles = dir([outputfolder '/*.*']);
                %Read all video Files
                for Index = 1:length(VideoFiles)
                       	baseFileName = VideoFiles(Index).name;
                	[folder,name, extension] = fileparts(baseFileName);
                	extension = upper(extension);
                	switch lower(extension)
                		case {'.png', '.bmp', '.jpg', '.tif'}
                			% Allow only PNG, TIF, JPG, or BMP images
                			ListOfVideosNames = [ListOfVideosNames baseFileName];
                		otherwise
                	end
                end
                app.FramesOfVideoList.Items = ListOfVideosNames;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
            
        end
        
        function LoadImageFilteringList(app)
            try 
                if isempty(app.FrameList.Value)
                    %app.FrameListToFilter.Items = app.FrameList.Items;  
                    ListOfVideosNames = {};
                        outputfolder = char(app.DirectoryFolderFrames.Value);
                        InputDirectory = outputfolder;
                        if ~isempty( app.OutputFinalFramesFolder)
                            if exist(InputDirectory, 'dir') == false
                                warningMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an video folder.', app.VideoFolder);
                                f = app.UIFigure;
                                uialert(f,warningMessage,'Warning',...
                                'Icon','warning');
        			% Make it something, so that we can continue.
        			outputfolder = pwd;
                            end
                        else
                             f = app.UIFigure;
                             uialert(f,'No folder specified as input for function LoadImageList.','Warning',...
                             'Icon','warning');
                             outputfolder = pwd;
                        end
                        % If it gets to here, the folder is good.
                        FramesFiles = dir([outputfolder '/*.*']);
                        %Read all video Files
                        for Index = 1:length(FramesFiles)
                               	baseFileName = FramesFiles(Index).name;
                        	[folder,name, extension] = fileparts(baseFileName);
                        	extension = upper(extension);
                        	switch lower(extension)
                        		case {'.png', '.bmp', '.jpg', '.tif'}
                        			% Allow only PNG, TIF, JPG, or BMP images
                        			ListOfVideosNames = [ListOfVideosNames baseFileName];
                        		otherwise
                        	end
                        end
                        app.FrameListToFilter.Items = ListOfVideosNames;
                end
                old_list = app.FrameListToFilter.Items;
                new_list = app.FrameList.Value;
            	app.FrameListToFilter.Items = [old_list new_list];
                %app.listImageFiltered.Items = {};
                drawnow;
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
            
        end
        
        function  PlotImageHistogram2(app, imageArray)
                        try
                        	numberOfDimensions = ndims(imageArray);
                        	if numberOfDimensions == 1 || numberOfDimensions == 2
                        		% Grayscale 2D or 1D image.
                        		PlotGrayscaleHistogram2(app, imageArray);
                        	elseif numberOfDimensions == 3
                        		% True color RGB image.
                        		PlotRGBHistograms2(app, imageArray);
                        	else
                        		errorMessage = sprintf('Histograms are only supported for grayscale (2D) or color (3D) images');
                        		f = app.UIFigure;
                                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                           uialert(f,errorMessage,'Warning',...
                                           'Icon','warning');
                        	end
                        catch ME
                        	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                        		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                        	f = app.UIFigure;
                                   %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                        end
            
        end
          function [minGL, maxGL] = PlotGrayscaleHistogram2(app, grayImage)
                try
                	% Plot the histogram in the histogram viewport.
                	cla(app.Plot_2);
                        axis(app.Plot_2,'on');  % makes existing axes handles.axesPlot the current axes.
                	% Get the histogram     
                	%[pixelCounts, grayLevels] = imhist(grayImage);
                	histogram(app.Plot_2,  grayImage); 
                	% Plot it.
                	%bar(pixelCounts);
                	title(app.Plot_2, 'Histogram of Gray Image', 'FontSize', 12);
                        app.Plot_2.XLabel.String ='pixel intensity' ;
                        app.Plot_2.YLabel.String = ' number of pixels' ;
                	%grid on;
                	% Get the min and max GL for fun.
                	minGL = min(grayImage(:));
                	maxGL = max(grayImage(:));
                	% Set up the x axis of the histogram to go from 0 to the max value allowed for this type of integer (uint8 or uint16).
                	%theClass = class(grayImage);
                	%%% Only set xlim for integers.  For example the demo image blobs.png is logical not integer even though it's grayscale.
                	%%%if strfind(theClass, 'int')
                	%	xlim([0 intmax(theClass)]);
                	%%%%%end
                catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                end
            
        end
        
        function [minGL, maxGL, gl1Percentile, gl99Percentile] = PlotRGBHistograms2(app, imgColorImageArray)
                try
                                cla(app.Plot_2);
                                axis(app.Plot_2,'on');      
                        title(app.Plot_2, 'Red, Green, and Blue Histograms', 'FontSize', 12);
                        app.Plot_2.XLabel.String ='pixel intensity' ;
                        app.Plot_2.YLabel.String = ' number of pixels' ;
                	% Get a histogram of the entire image.  But imhist only allows 2D images, not color ones.
                	% First get individual channels from the original image.
                        %clf(app.Plot);
                	redBand = imgColorImageArray(:,:,1);
                	greenBand = imgColorImageArray(:,:,2);
                	blueBand = imgColorImageArray(:,:,3);	
                	% Use 256 bins.
                	[redCounts, redGLs] = imhist(redBand, 256);    % make sure you label the axes after imhist because imhist will destroy them.
                	[greenCounts, ~] = imhist(greenBand, 256);    % make sure you label the axes after imhist because imhist will destroy them.
                	[blueCounts, ~] = imhist(blueBand, 256);    % make sure you label the axes after imhist because imhist will destroy them.
                	% GLs goes from 0 (at element 1) to 255 (at element 256) but only some
                	% these bins have data in them.  The upper ones may be 0.  Find the last
                	% non-zero bin so we can plot just up to there to get better horizontal resolution.
                	maxBinUsedR = find(redCounts, 1, 'last' );
                	maxBinUsedG = find(greenCounts, 1, 'last' );
                	maxBinUsedB = find(blueCounts, 1, 'last' );
                	% If the entire image is zero, have the max bin be 0.
                        	if isempty(maxBinUsedR)
                                    maxBinUsedR = 1; 
                         end  
                        	if isempty(maxBinUsedG) 
                             maxBinUsedG = 1; 
                         end
                        	if isempty(maxBinUsedB)
                             maxBinUsedB = 1; 
                         end
                	% Take the largest one overall for plotting them all.
                	maxBinUsed = max([maxBinUsedR maxBinUsedG maxBinUsedB]);
                	% Get subportion of array that has non-zero data.
                	redCounts = redCounts(1:maxBinUsed);
                	greenCounts = greenCounts(1:maxBinUsed);
                	blueCounts = blueCounts(1:maxBinUsed);
                	GLs = redGLs(1:maxBinUsed);
                	% Assign the max and min for the 3 color bands.
                	minGL(1) = GLs(maxBinUsedR);
                	maxGL(2) = GLs(maxBinUsedG);
                	maxGL(3) = GLs(maxBinUsedB);
                	% Calculate the 1% and 99% value of the CDF for the 3 color bands.
                	gl1Percentile = ones(3, 1);	    % Preallocate one element for each color.
                	gl99Percentile = ones(3, 1);		% Preallocate one element for each color.
                	for color = 1:3
                		switch color
                			case 1
                				counts = redCounts;
                			case 2
                				counts = greenCounts;
                			case 3
                				counts = blueCounts;
                		end
                		summed = sum(counts);
                		cdf = 0;
                		for bin = 1 : maxBinUsed
                			cdf = cdf + counts(bin);
                			if cdf < 0.01 * summed
                				gl1Percentile(color) = GLs(bin);
                			end
                			if cdf > 0.99 * summed
                				break;
                			end
                		end
                		gl99Percentile(color) = GLs(bin);
                	end
                	% Plot the histogram in the histogram viewport.
                	%axes(app.Plot);  % makes existing axes handles.axesPlot the current axes.
                        %histogram(app.Plot,  imgColorImageArray);
                        %plot(app.Plot,GLs, blueCounts, 'b', 'LineWidth', 3 )
                        
                	% Plot the histogram as a line curve.
                	%plot(GLs, blueCounts, 'b', 'LineWidth', 3);
                        hold (app.Plot_2, 'on');
                        plot(app.Plot_2,GLs, blueCounts, 'b', 'LineWidth', 3  );
                	%hold on;
                	%plot(GLs, greenCounts, 'g', 'LineWidth', 3);
                        plot(app.Plot_2,GLs, greenCounts, 'g', 'LineWidth', 3 );
                	%plot(GLs, redCounts, 'r', 'LineWidth', 3);
                        plot(app.Plot_2,GLs, greenCounts, 'r', 'LineWidth', 3 );
                	hold (app.Plot_2, 'off');
                	%app.Plot.Title.String = 'Red, Green, and Blue Histograms';
                        %title('Red, Green, and Blue Histograms');
                	maxCountInHistogram = max([redCounts(:); greenCounts(:); blueCounts(:)]);
                	set(app.Plot_2,'YLim',[0 maxCountInHistogram]);
                	
                	% Plot the sum of all of them
                	% 		sumCounts = redCounts + greenCounts + blueCounts;
                	% 		plot(GLs, sumCounts, 'k', 'LineWidth', 1);  % Plot sum in black color.
                	% 		set(handles.axesPlot,'YLim',[0 max(sumCounts)]);
                	
                	% Set up custom tickmarks.
                	set(app.Plot_2,'XTick',      [0 20 40 60 80 100 120 140 160 180 200 220 240 255]);
                	set(app.Plot_2,'XTickLabel', {0 20 40 60 80 100 120 140 160 180 200 220 240 255});
                	set(get(app.Plot_2,'XLabel'),'string','Gray Level');
                	%app.ylabel('# of Pixels');
                	%xlabel('Gray Level');
                	%xlim([0 255]);	
                	%axes(app.Image);	% Switch current figure back to image box.
                catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                end
        end
        
         function [Denoised_Image] = denoised_CNN(app, input_Image)
            try
                    net = denoisingNetwork('DnCNN');
                    noisyI = input_Image;
                    Denoised_Image = denoiseImage(noisyI, net);
            catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
            
         end
        
        function SaveFilteredImages(app,outputfolder)
            try
                        % Specify the input folder name.
                	%inputFolder = outputfolder;
                	% Get the output filename.
                        startTime = tic;
                	outputFolder = outputfolder;
                        %%%%%%%%%%%%%%single denoised image
                        ListOfImageNames = app.FrameAfterFilter.Items;
                	selectedFiles = app.FrameAfterFilter.Value;
                	numberOfFrames = length(ListOfImageNames);
                        % If none are selected, select them all
                                  if numberOfFrames == 0      
                                    		errorMessage = sprintf('Image no selected');
                            	                 f = app.UIFigure;
                                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                           uialert(f,errorMessage,'Warning',...
                                           'Icon','warning');
                                           numberOfFrames = length(ListOfImageNames);
                		           selectedFiles = 1 : numberOfFrames;
                                  end
                                   % Specify the input folder name.
                	            %inputFolder = app.imageFolder;
                                    numberOfFramesWritten = 0;
                        
                        for frame = 1 : numberOfFrames
                        outputBaseFileName = char(app.FrameAfterFilter.Items{frame});
                        fullFileName = fullfile(outputFolder, outputBaseFileName);
                	[~, baseFileNameNoExt, ext] = fileparts(fullFileName);
                        imwrite(app.FilteredImage{frame}, fullFileName, 'tif');
                        thisFrame = app.FilteredImage{frame};
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                %drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end   
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing ');
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                 
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        
                    end
                
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
  
        end
        
        function StatusFilters(app)
            try
                if isempty(app.ListFilterUsed.Items) 
                    app.ApplyFiltersButton.Visible = 'Off';
                else
                    app.ApplyFiltersButton.Visible = 'On';
                end
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
        end
        
        function LoadImageBackgroundList(app)
            try 
                if isempty(app.FrameListBackground.Value)
                    %app.FrameListBackground.Items = app.FrameList.Items;
                    
                    ListOfVideosNames = {};
                        InputDirectory = app.FinalFilteredFolder;
                        if ~isempty(app.FinalFilteredFolder)
                            if exist(InputDirectory, 'dir') == false
                                warningMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an video folder.', app.BackgroundFolder);
                                f = app.UIFigure;
                                uialert(f,warningMessage,'Warning',...
                                'Icon','warning');
        			% Make it something, so that we can continue.
        			app.FinalFilteredFolder = pwd;
                            end
                        else
                             f = app.UIFigure;
                             uialert(f,'No folder specified as input for function LoadImageList.','Warning',...
                             'Icon','warning');
                             app.FinalFilteredFolder = pwd;
                        end
                        % If it gets to here, the folder is good.
                        Backgroundfolder = dir([app.FinalFilteredFolder '/*.*']);
                        %Read all video Files
                        for Index = 1:length(Backgroundfolder)
                               	baseFileName = Backgroundfolder(Index).name;
                        	[folder,name, extension] = fileparts(baseFileName);
                        	extension = upper(extension);
                        	switch lower(extension)
                        		case {'.png', '.bmp', '.jpg', '.tif'}
                        			% Allow only PNG, TIF, JPG, or BMP images
                        			ListOfVideosNames = [ListOfVideosNames baseFileName];
                        		otherwise
                        	end
                        end
                        app.FrameListBackground.Items = ListOfVideosNames;
                end
                %old_list = app.FrameListBackground.Items; 
                %new_list = app.FrameListToFilter.Value;
            	%app.FrameListBackground.Items = [old_list new_list];
                %app.listImageFiltered.Items = {};
                drawnow;
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
        function LoadBackgroundList(app)
            try
                ListOfVideosNames = {};
                InputDirectory = app.BackgroundFolder;
                if ~isempty(app.BackgroundFolder)
                    if exist(InputDirectory, 'dir') == false
                        warningMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an video folder.', app.BackgroundFolder);
                        f = app.UIFigure;
                        uialert(f,warningMessage,'Warning',...
                        'Icon','warning');
			% Make it something, so that we can continue.
			app.BackgroundFolder = pwd;
                    end
                else
                     f = app.UIFigure;
                     uialert(f,'No folder specified as input for function LoadImageList.','Warning',...
                     'Icon','warning');
                     app.BackgroundFolder = pwd;
                end
                % If it gets to here, the folder is good.
                Backgroundfolder = dir([app.BackgroundFolder '/*.*']);
                %Read all video Files
                for Index = 1:length(Backgroundfolder)
                       	baseFileName = Backgroundfolder(Index).name;
                	[folder,name, extension] = fileparts(baseFileName);
                	extension = upper(extension);
                	switch lower(extension)
                		case {'.png', '.bmp', '.jpg', '.tif'}
                			% Allow only PNG, TIF, JPG, or BMP images
                			ListOfVideosNames = [ListOfVideosNames baseFileName];
                		otherwise
                	end
                end
                app.BackgroundList.Items = ListOfVideosNames; 
                app.BackgroundList_3.Items = ListOfVideosNames;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        
            
        end


        function SaveBackgroundModels(app,outputfolder)
        
            try
                        % Specify the input folder name.
                	%inputFolder = outputfolder;
                	% Get the output filename.
                        startTime = tic;
                	outputFolder = outputfolder;
                        %%%%%%%%%%%%%%single denoised image
                        %itemsBackgroundModellist = app.BackgroundModellist.Items;
                        %SelectedBackgroundModelList = app.BackgroundModellist.Value;   
                        %NumberOfSelected = length(SelectedBackgroundModelList);
                        ListOfImageNames = app.BackgroundModellist.Items;
                	selectedFiles = app.BackgroundModellist.Value;  
                	numberOfFrames = length(ListOfImageNames);
                        % If none are selected, select them all
                                  if numberOfFrames == 0      
                                    		errorMessage = sprintf('Image no selected');
                            	                 f = app.UIFigure;
                                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                           uialert(f,errorMessage,'Warning',...
                                           'Icon','warning');
                                           numberOfFrames = length(ListOfImageNames);
                		           selectedFiles = 1 : numberOfFrames;
                                  end
                                   % Specify the input folder name.
                	            %inputFolder = app.imageFolder;
                                    numberOfFramesWritten = 0;
                        
                    for frame = 1 : numberOfFrames
                        
                        outputBaseFileName = char(app.BackgroundModellist.Items{frame});
                        
                        
                	if ~contains(outputBaseFileName, '.')
                		% No format specified.  Add .tif
                		outputBaseFileName = [outputBaseFileName, '.tif'];
                	end
                        
                        fullFileName = fullfile(outputFolder, outputBaseFileName);
                	[~, baseFileNameNoExt, ext] = fileparts(fullFileName);
                        imwrite(app.BackgroundModel{frame}, fullFileName, 'tif');
                        thisFrame = app.BackgroundModel{frame};
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        plotWindows = app.CheckProcessCheckBox_3.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_4);
                                %PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                %drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end   
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Processing ');
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_4.Value = txtinfo;  
                        drawnow;   
                 
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        
                    end
                
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
  
        end

        function SaveVideoOfFrames(app,outputfolder)
            try
                
                % Then get list of all of the filenames in the list,
                % regardless of whether they are selected or not.
                ListOfImageNames = app.FrameList.Items;
                selectedFiles = app.FrameList.Value;
                numberOfFrames = length(selectedFiles);
                % If none are selected, select them all
                if numberOfFrames == 0
                		numberOfFrames = length(ListOfImageNames);
                		selectedFiles = 1 : numberOfFrames;
                end
                % Specify the input folder name.
                inputFolder = app.ImageFolder;
                % Get the output filename.
                outputFolder = outputfolder;
                if ~exist(outputFolder, 'dir')
                		mkdir(outputFolder);
                end
                baseFileName = char(app.OutputBaseFileNameTextArea.Value);
                if length(baseFileName) < 1
            		baseFileName = 'Time Lapse Movie.avi';
                end
            	if ~contains(baseFileName, '.')
            		% No format specified.  Add .avi
            		baseFileName = [baseFileName, '.avi'];
            	end
            	fullFileName = fullfile(outputFolder, baseFileName);
            	[~, baseFileNameNoExt, ext] = fileparts(fullFileName);
            	if contains(ext, 'mp', 'IgnoreCase', true)
            		% Make sure it's mp4, not mp3
            		ext = '.mp4';
            		fullFileName = fullfile(outputFolder, [baseFileNameNoExt, ext]);
            	end
                % Create a VideoWriter object to write the video out to a new, different file.
            	if contains(ext, 'mp', 'IgnoreCase', true)
            		% MP4 format.
            		% MPEG sometimes gives bad jaggies on smooth gradient regions.
            		writerObj = VideoWriter(fullFileName, 'MPEG-4');
            	else
            		% AVI format.
            		writerObj = VideoWriter(fullFileName);
            	end
            	writerObj.FrameRate = round(app.FrameRateSpinner.Value);
            	open(writerObj);
                % Define the output size
                startTime = tic;
                % If they don't have "resize" checked, then will still need to resize if the images are different sizes,
            	% but the images will just be resized to the size of the first frame.
            	% If they do have "Resize" checked, the images will all be resized to the sizes in the edit fields.
            	% First find out the size of the first frame.
            	%sourceBaseFileName = ListOfImageNames{1};
                sourceBaseFileName = selectedFiles{1};
            	sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
            	% Read the image in from disk.
            	thisFrame = imread(sourceFrameFullFileName);
            	[frame1Height, frame1Width, ~] = size(thisFrame);
            	%frame1ActualWidthToHeightRatio = frame1Width / frame1Height;
            	% Set default size of the output video to the first frame's size.
            	videoRows = frame1Height;
            	videoColumns = frame1Width;
                if app.ResizeImagesCheckBox.Value
                		videoRows = (app.RowsEditField.Value);
                		videoColumns = (app.ColumnsEditField.Value);
                end
                % Set up a way to Finish out if someone want to.
            	app.FinishNowCheckBox.Visible ='on';
            	app.FinishNowCheckBox.Value = false;
                app.FinishNowCheckBox.FontColor =  [1 0 0];
                % Read the frames back in from disk, and convert them to a movie.
            	% Preallocate constructedMovie, which will be an array of structures.
            	% First get a cell array with all the frames.
            	allTheFrames = cell(numberOfFrames,1);
            	allTheFrames(:) = {zeros(videoRows, videoColumns, 3, 'uint8')};
            	% Next get a cell array with all the colormaps.
            	allTheColorMaps = cell(numberOfFrames,1);
            	allTheColorMaps(:) = {zeros(256, 3)};
            	% Now combine these to make the array of structures.
            	constructedMovie = struct('cdata', allTheFrames, 'colormap', allTheColorMaps);
            	%axes(app.Image);
               numberOfProcessedFiles = 0;
                for frame = 1 : numberOfFrames
            		%index = char(selectedFiles(frame)); % Get the actual index in the listbox of whatever they selected.
            		% Construct an output image file name.
            		sourceBaseFileName = selectedFiles{frame};
            		sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
            		% Read the image in from disk.
            		[thisFrame,map]  = imread(sourceFrameFullFileName);
                        %%%oscar
                        if ~isempty(map)
                            thisFrame = ind2rgb(thisFrame,map);
                        end
                        % The orientation should be 1.  If the orientation is 3, the image is upside down and needs to be rotated.
            		info = imfinfo(sourceFrameFullFileName);
            		hasField = isfield(info, 'Orientation');
            		if hasField
            			if info.Orientation == 3
            				thisFrame = imrotate(thisFrame, 180);
            			end
            		end
            		% Resize image if needed.
            		% Get the rows and columns of the current frame.
            		[thisRows, thisColumns, ~] = size(thisFrame);
            		if thisRows ~= videoRows || thisColumns ~= videoColumns
            			thisFrame = imresize(thisFrame, [videoRows, videoColumns]);
            		end
                        % Display the image in the GUI.
            		%cla;
            		%imshow(thisFrame);
                       
                        
                        %RI = imref2d([videoRows, videoColumns]);
                        %imshow(thisFrame, RI, 'InitialMagnification', 'fit', 'parent', app.Image_2);
                        %axis('image', 'on');   
                        %axis image
                	caption = sprintf('Frame %d of %d: %s', frame, numberOfFrames, sourceBaseFileName);
                        if app.ChekPlotHistogramsImagesCheckBox.Value
                            Display_Image(app,thisFrame,caption,app.Image_2);   
                        end
                	%title(caption, 'Interpreter', 'none', 'FontSize', fontSize); 
                        %title(app.Image_2, caption, 'Interpreter', 'none', 'FontSize', 14);
                        %app.Image_2.XLabel.String = 'PIXELS X AXIS '; 
                        %app.Image_2.XLim =[0,videoRows]; 
                        %app.Image_2.YLim =[0,videoColumns]; 
                        %app.Image_2.YLabel.String = 'PIXELS Y AXIS ';
                        cmap = gray(256);
                        constructedMovie(frame) = im2frame(thisFrame,cmap);
        		% Write this frame out to a new video file.
                          if frame == 1
                              firstFrameRepeat = str2double(app.NumberoftimestorepeatfirstframeEditField.Value);
                            			% Write out the first frame this many times.  It might be the title slide.
                            			for k = 1 : firstFrameRepeat
                            				writeVideo(writerObj, thisFrame);
                            			end
                          else
                              % Write out frame just once.
                            			writeVideo(writerObj, thisFrame);
                          end
                          
            		numberOfProcessedFiles = numberOfProcessedFiles + 1;
            		elapsedSeconds = toc(startTime);
            		app.Result_2.Value = sprintf('Building Movie...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            		frame, numberOfFrames, sourceBaseFileName, elapsedSeconds);
            		
            		% Check if they want to quit
            		if app.FinishNowCheckBox.Value
            			break;
            		end
                    drawnow;
                end
                % Close the movie file.
            	close(writerObj);
            	% Reset way to bail out if they want to.
            	app.FinishNowCheckBox.Value = false;
                app.FinishNowCheckBox.FontColor = [0 0 1];
                app.FinishNowCheckBox.Visible ='off';
                %drawnow;
                
                % Let user know we're done, and what the movie filename is.
            	message = sprintf('\nYour %d frame movie is in file:\n%s', numberOfProcessedFiles, fullFileName);
            	%set(handles.txtInfo, 'String', message);
            	app.Result_2.Value = message;
                    
            	message = sprintf('\nYour %d frame movie is in file:\n%s\n\nClick OK to play it', numberOfProcessedFiles, fullFileName);
            	titleBarCaption = 'Play movie?';
            	buttonText = questdlg(message, titleBarCaption, 'OK, Play It', 'Skip playing', 'OK, Play It');
            	if contains(buttonText, 'OK', 'IgnoreCase', true)
            		% Play the movie in the default program (like Windows Media Player).
            		% I think winopen() only works for the Windows operating system.
            		if contains(computer, 'PCWIN')
            			% It's a windows computer.
            			winopen(outputfolder);
            		else
            			% It's a not a Windows computer.
            			disp('iMac');
            		end
            	end
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
            
        end
        
        function LoadBackgroundToAnalyzeList(app)
            try 
                app.BackgroundList_2.Items = {};
              
                if isempty(app.BackgroundList_2.Value)
                    %app.FrameListBackground.Items = app.FrameList.Items;
                    
                    ListOfVideosNames = {};
                        InputDirectory = app.FinalBackgroundFolder;
                        if ~isempty(app.FinalFilteredFolder)
                            if exist(InputDirectory, 'dir') == false
                                warningMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an video folder.', app.FinalBackgroundFolder);
                                f = app.UIFigure;
                                uialert(f,warningMessage,'Warning',...
                                'Icon','warning');
        			% Make it something, so that we can continue.
        			app.FinalBackgroundFolder = pwd;
                            end
                        else
                             f = app.UIFigure;
                             uialert(f,'No folder specified as input for function LoadImageList.','Warning',...
                             'Icon','warning');
                             app.FinalBackgroundFolder = pwd;
                        end
                        % If it gets to here, the folder is good.
                        Backgroundfolder = dir([app.FinalBackgroundFolder '/*.*']);
                        %Read all video Files
                        for Index = 1:length(Backgroundfolder)
                               	baseFileName = Backgroundfolder(Index).name;
                        	[folder,name, extension] = fileparts(baseFileName);
                        	extension = upper(extension);
                        	switch lower(extension)
                        		case {'.png', '.bmp', '.jpg', '.tif'}
                        			% Allow only PNG, TIF, JPG, or BMP images
                        			ListOfVideosNames = [ListOfVideosNames baseFileName];
                        		otherwise
                        	end
                        end
                        app.BackgroundList_2.Items = ListOfVideosNames;
                end
                %old_list = app.BackgroundList_3.Items; 
                %new_list = app.BackgroundList_3.Value;
            	%app.BackgroundList_3.Items = [old_list new_list];
                %app.listImageFiltered.Items = {};
                drawnow;
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
        function LoadImageToAnalyzeList(app)
             try 
                 app.ImagesToAnalizeList.Items = {};
                if isempty(app.ImagesToAnalizeList.Value)
                    %app.FrameListBackground.Items = app.FrameList.Items;
                    
                    ListOfVideosNames = {};
                        InputDirectory = app.FramesFolderUsed;
                        if ~isempty(app.FinalFilteredFolder)
                            if exist(InputDirectory, 'dir') == false
                                warningMessage = sprintf('Note: the folder used when this program was last run:\n%s\ndoes not exist on this computer.\nPlease run Step 1 to select an video folder.', app.FramesFolderUsed);
                                f = app.UIFigure;
                                uialert(f,warningMessage,'Warning',...
                                'Icon','warning');
        			% Make it something, so that we can continue.
        			app.FramesFolderUsed = pwd;
                            end
                        else
                             f = app.UIFigure;
                             uialert(f,'No folder specified as input for function LoadImageList.','Warning',...
                             'Icon','warning');
                             app.FramesFolderUsed = pwd;
                        end
                        % If it gets to here, the folder is good.
                        FramesToAnalyze = dir([app.FramesFolderUsed '/*.*']);
                        %Read all video Files
                        for Index = 1:length(FramesToAnalyze)
                               	baseFileName = FramesToAnalyze(Index).name;
                        	[folder,name, extension] = fileparts(baseFileName);
                        	extension = upper(extension);
                        	switch lower(extension)
                        		case {'.png', '.bmp', '.jpg', '.tif'}
                        			% Allow only PNG, TIF, JPG, or BMP images
                        			ListOfVideosNames = [ListOfVideosNames baseFileName];
                        		otherwise
                        	end
                        end
                        app.ImagesToAnalizeList.Items = ListOfVideosNames;
                end
                %old_list = app.BackgroundList_3.Items; 
                %new_list = app.BackgroundList_3.Value;
            	%app.BackgroundList_3.Items = [old_list new_list];
                %app.listImageFiltered.Items = {};
                drawnow;
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
        function [ForeGroundImage, ImageDilated, SmoothPixel, ResultPerimeter, Segmentation, Perimeter, RelativeConcentration] = ProcessImageBackground(app,ImageToProcess, BackgroundModelToProcess)
            try
                %foregroun dBinary using threshold
                NewFrameForeGround = abs(ImageToProcess-BackgroundModelToProcess);
                thresholdValue = app.ThresholdThsEditField.Value;
                binaryImage = NewFrameForeGround > thresholdValue;
                ForeGroundImage=binaryImage;
                %%ImageDilatedBy square function
                ss = strel('square',app.DilatePixelSizeEditField.Value);
                ImageFirstFilter = imdilate(ForeGroundImage,ss);
                ImageDilated = ImageFirstFilter;
                %%Image Smooth By diamond function
                seD = strel('diamond',app.SmoothPixelSizeEditField.Value);
                Image_Second_Filter = imerode(ImageDilated,seD);
                SmoothPixel =  Image_Second_Filter;
                %result perimeter image
                Filter_outline = bwperim(SmoothPixel);
                ResultPerimeter = Filter_outline;
                %%Segmentation out line 
                Perimeter =  imoverlay(ImageToProcess,ResultPerimeter,'red');
                %% segetantion color
                Segmentation =  imoverlay(ImageToProcess,SmoothPixel,'red'); 
                RelativeConcentration = ((sum(ForeGroundImage(:)))/(numel((ForeGroundImage(:)))))*100;
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
        function Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,options,axes)
            try
                if strcmp(char(options.Value),('Original Image'))
                    Display_Image(app, ImageToProcess,baseImageFileName,axes);
                end
                if strcmp(char(options.Value),('Background Image'))
                     Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,axes);
                end
                if strcmp(char(options.Value),('Foreground Binary Image'))
                      Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),axes);
                end
                if strcmp(char(options.Value),('Image Dilate'))
                      Display_Image(app, ImageDilated,char(['Image Dilated',baseImageFileName]),axes);
                end
                 if strcmp(char(options.Value),('Smooth Image'))
                      Display_Image(app, SmoothPixel,char(['Smooth Pixel Image',baseImageFileName]),axes);
                 end
                 if strcmp(char(options.Value),('Image Segmentation'))
                      Display_Image(app, ResultPerimeter,char(['Image Result Perimeter',baseImageFileName]),axes);
                end


            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
            
        end
        
        function [minGL, maxGL] = PlotGrayscaleHistogram3(app, grayImage)
          
                try
                	% Plot the histogram in the histogram viewport.
                	cla(app.HistogramVideoFrame);
                        axis(app.HistogramVideoFrame,'on');  % makes existing axes handles.axesPlot the current axes.
                	% Get the histogram     
                	%[pixelCounts, grayLevels] = imhist(grayImage);
                	histogram(app.HistogramVideoFrame,  grayImage); 
                	% Plot it.
                	%bar(pixelCounts);
                	title(app.HistogramVideoFrame, 'Histogram of Gray Image', 'FontSize', 12);
                        app.HistogramVideoFrame.XLabel.String ='pixel intensity' ;
                        app.HistogramVideoFrame.YLabel.String = ' number of pixels' ;
                	%grid on;
                	% Get the min and max GL for fun.
                	minGL = min(grayImage(:));
                	maxGL = max(grayImage(:));
                	% Set up the x axis of the histogram to go from 0 to the max value allowed for this type of integer (uint8 or uint16).
                	%theClass = class(grayImage);
                	%%% Only set xlim for integers.  For example the demo image blobs.png is logical not integer even though it's grayscale.
                	%%%if strfind(theClass, 'int')
                	%	xlim([0 intmax(theClass)]);
                	%%%%%end
                catch ME
                	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                               
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
                end


        end
        
        function loadFramesHamamatsuVideo(app)
            try
                 baseFileName = char(app.OutputBaseFileNameTextArea_5.Value);
                if length(baseFileName) < 1
                		baseFileName = 'Video Hamamatsu Camera ';
                end
                if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                end
                 %%output base file name in list
                 outputBaseFileName = baseFileName;
                 [~,numberfFrames] = size(app.FramesToPIV);
                 
                 for frame = 1:numberfFrames
                                        if frame<10
                                        nume = sprintf('000%d.tif', frame);
                                        end
                                        if frame>=10 && frame<100
                                        nume = sprintf('00%d.tif', frame);
                                        end
                                        if frame>=100 && frame<1000
                                        nume = sprintf('0%d.tif', frame);
                                        end
                                        if frame>=1000 
                                        nume = sprintf('0%d.tif', frame);
                                        end
                                        %baseFileNameFrame =  [name ' ' nume];
                     baseFileNameFrame = [outputBaseFileName ' ' nume];
                     app.FramesToPIVList_2.Items{frame}=baseFileNameFrame;
                 end


            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
        end
        
        function loadFramesHamamatsu(app)
            try
                 baseFileName = char(app.OutputBaseFileNameTextArea_5.Value);
                if length(baseFileName) < 1
                		baseFileName = 'Hamamatsu Camera ';
                end
                if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                end
                 %%output base file name in list
                 outputBaseFileName = baseFileName;
                 [~,numberfFrames] = size(app.FramesHamamatsu);
                 
                 for frame = 1:numberfFrames
                                        if frame<10
                                        nume = sprintf('000%d.tif', frame);
                                        end
                                        if frame>=10 && frame<100
                                        nume = sprintf('00%d.tif', frame);
                                        end
                                        if frame>=100 && frame<1000
                                        nume = sprintf('0%d.tif', frame);
                                        end
                                        if frame>=1000 
                                        nume = sprintf('0%d.tif', frame);
                                        end
                                        %baseFileNameFrame =  [name ' ' nume];
                     baseFileNameFrame = [outputBaseFileName ' ' nume];
                     app.FrameHamamatsuList.Items{frame}=baseFileNameFrame;
                 end


            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
        end
        
        function SaveFramesHamamatsu(app,outputfolder)
        try
                        % Specify the input folder name.
                	%inputFolder = outputfolder;
                	% Get the output filename.
                        startTime = tic;
                	outputFolder = outputfolder;
                        %%%%%%%%%%%%%%single denoised image
                        %itemsBackgroundModellist = app.BackgroundModellist.Items;
                        %SelectedBackgroundModelList = app.BackgroundModellist.Value;   
                        %NumberOfSelected = length(SelectedBackgroundModelList);
                        ListOfImageNames = app.FrameHamamatsuList.Items;
                	selectedFiles = app.FrameHamamatsuList.Value;  
                	numberOfFrames = length(ListOfImageNames);
                        % If none are selected, select them all
                                  if numberOfFrames == 0      
                                    		errorMessage = sprintf('Image no selected');
                            	                 f = app.UIFigure;
                                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                           uialert(f,errorMessage,'Warning',...
                                           'Icon','warning');
                                           numberOfFrames = length(ListOfImageNames);
                		           selectedFiles = 1 : numberOfFrames;
                                  end
                                   % Specify the input folder name.
                	            %inputFolder = app.imageFolder;
                                    numberOfFramesWritten = 0;
                        
                    for frame = 1 : numberOfFrames
                        
                        outputBaseFileName = char(app.FrameHamamatsuList.Items{frame});
                        
                        
                	if ~contains(outputBaseFileName, '.')
                		% No format specified.  Add .tif
                		outputBaseFileName = [outputBaseFileName, '.tif'];
                	end
                        
                        fullFileName = fullfile(outputFolder, outputBaseFileName);
                	[~, baseFileNameNoExt, ext] = fileparts(fullFileName);
                        imwrite(app.FramesHamamatsu{frame}, fullFileName, 'tif');
                        thisFrame = app.FramesHamamatsu{frame};
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        plotWindows = app.HistogramCheckBox.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.FrameRVideo);
                                %PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                %drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end   
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Processing ');
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.TextArea.Value = txtinfo;  
                        drawnow;   
                 
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        
                    end
                
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure;
                                   uialert(f,errorMessage,'Warning',...
                                   'Icon','warning');
            end
  
            
        end
        
        function [Foreground, Perimeter,Segmentation] = BackgroundSimple(app,ImageToProcess,BackgroundModelToProcess)
            try
                %foregroun dBinary using threshold
                NewFrameForeGround = abs(ImageToProcess-BackgroundModelToProcess);
                thresholdValue = app.ThresholdThsEditField_2.Value;
                binaryImage = NewFrameForeGround > thresholdValue;
                Foreground=binaryImage;
                %%ImageDilatedBy square function
                %ss = strel('square',app.DilatePixelSizeEditField.Value);
                %ImageFirstFilter = imdilate(Foreground,ss);
                %ImageDilated = ImageFirstFilter;
                %%Image Smooth By diamond function
                %seD = strel('diamond',app.SmoothPixelSizeEditField.Value);
                %Image_Second_Filter = imerode(ImageDilated,seD);
                %SmoothPixel =  Image_Second_Filter;
                %result perimeter image
                Filter_outline = bwperim(Foreground);
                %ResultPerimeter = Filter_outline;
                %%Segmentation out line 
                Perimeter =  imoverlay(ImageToProcess,Foreground,'red');
                %% segetantion color
                Segmentation =  imoverlay(ImageToProcess,Filter_outline,'red'); 
                %RelativeConcentration = ((sum(ForeGroundImage(:)))/(numel((ForeGroundImage(:)))))*100;
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
        function [ImageProcessedResult] = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage, HighGraylimit)
              try
                  ForeGroundinImage = ImageToProcess.*uint8(ForeGroundImage);
                  BackGroundinImage = BackgroundModelToProcess.*uint8(~ForeGroundImage);
                  %BackGroundinImage = (28/2)*((10.^(BackGroundinImage/255))-1);
                  %BackGroundinImage = (200/255)*BackGroundinImage;
                  
                  BackGroundinImage = (HighGraylimit/255)*BackGroundinImage;
                  ImageProcessedResult = ForeGroundinImage + BackGroundinImage;
               
              catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
              end
        end
        
        function SaveFramesToPIV(app,outputfolder)
                    try
                                    % Specify the input folder name.
                            	%inputFolder = outputfolder;
                            	% Get the output filename.
                                startTime = tic;
                            	outputFolder = outputfolder;
                                    %%%%%%%%%%%%%%single denoised image
                                    %itemsBackgroundModellist = app.BackgroundModellist.Items;
                                    %SelectedBackgroundModelList = app.BackgroundModellist.Value;   
                                    %NumberOfSelected = length(SelectedBackgroundModelList);
                                ListOfImageNames = app.FramesToPIVList.Items;
                            	selectedFiles = app.FramesToPIVList.Value;  
                            	numberOfFrames = length(ListOfImageNames);
                                    % If none are selected, select them all
                                              if numberOfFrames == 0      
                                                		errorMessage = sprintf('Image no selected');
                                        	                 f = app.UIFigure;
                                                       %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                                                       uialert(f,errorMessage,'Warning',...
                                                       'Icon','warning');
                                                       numberOfFrames = length(ListOfImageNames);
                            		           selectedFiles = 1 : numberOfFrames;
                                              end
                                               % Specify the input folder name.
                            	            %inputFolder = app.imageFolder;
                                                numberOfFramesWritten = 0;
                                    
                                for frame = 1 : numberOfFrames
                                    
                                    outputBaseFileName = char(app.FramesToPIVList.Items{frame});
                                    
                                    
                            	if ~contains(outputBaseFileName, '.')
                            		% No format specified.  Add .tif
                            		outputBaseFileName = [outputBaseFileName, '.tif'];
                            	end
                                    
                                    fullFileName = fullfile(outputFolder, outputBaseFileName);
                            	[~, baseFileNameNoExt, ext] = fileparts(fullFileName);
                                    imwrite(app.FramesToPIV{frame}, fullFileName, 'tif');
                                    thisFrame = app.FramesToPIV{frame};
                                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                    plotWindows = app.CheckProcessCheckBox_4.Value;
                                    if plotWindows
                             	  %fprintf('About to plot histogram...\n');
                                            Display_Image(app,thisFrame,outputBaseFileName,app.Image_25);
                                            %PlotImageHistogram2(app,thisFrame);
                            		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                            %drawnow;
                           		 % fprintf('Done plotting histogram.\n');
                                    end   
                                    elapsedSeconds = toc(startTime);
                                    txtinfo = sprintf('\n Processing ');
                                    txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
                        	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                                    app.Result_7.Value = txtinfo;  
                                    drawnow;   
                             
                                    
                                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                    
                                    
                                end
                            
                            
                    catch ME
                            errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                            	f = app.UIFigure;
                                               uialert(f,errorMessage,'Warning',...
                                               'Icon','warning');
                    end
            
        end
        
        function [ImageLowPassFiltering] = LowPassFiltering(app,ImageToProcess)
              try
                    A = fft2(double(ImageToProcess)); % compute FFT of the grey image
                    A1=fftshift(A); % frequency scaling
                    % Gaussian Filter Response Calculation
                    [M,N]=size(A); % image size
                    R=10; % filter size parameter 
                    X=0:N-1;
                    Y=0:M-1;
                    Cx=0.5*N;
                    Cy=0.5*M;
                    Lo=exp(-((X-Cx).^2+(Y-Cy).^2)./(2*R.^2));
                    Hi=1-Lo; % High pass filter=1-low pass filter
                    % Filtered image=ifft(filter response*fft(original image))
                    J=A1.*Lo;
                    J1=ifftshift(J);
                    ImageLowPassFiltering=ifft2(J1);
               
              catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
              end
        end
        
        function [ForeGroundImage, ImageDilated, SmoothPixel, ResultPerimeter, Segmentation, Perimeter, RelativeConcentration, BackgroundFuzzyRunning_Average] = FuzzyBKGsubtraction (app,ImageToProcess, BackgroundModelToProcess ,Ths ,Thu, ThFs, alpha)
            try
                %foregroun dBinary using threshold
                [M,N] = size (ImageToProcess); 
                cores = str2num(app.CoresNumberDropDown_2.Value);
                FuzzyBackgroundSustraction = zeros(M,N);
                BackgroundFuzzyRunning_Average =  zeros(M,N);
                alphan = zeros(M,N);
                LowPassFilter = app.LowPassFilteringCheckBox.Value;
                        if cores ~= 1
                             if isempty(gcp('nocreate')) == true
                                 parpool('local',cores);
                                 parpool('IdleTimeout', 240);
                             end


                                    parfor i=1:M
                                        for j=1:N
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)
                                                    FuzzyBackgroundSustraction (i,j) = 1;      
                                                else 
                                                     FuzzyBackgroundSustraction (i,j)= abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))/Ths;    
                                                end 
                                                
                                                if LowPassFilter
                                                        FuzzyBackgroundSustraction(i,j) = LowPassFiltering(app,FuzzyBackgroundSustraction(i,j)); 
                                                end
                                                if (FuzzyBackgroundSustraction(i,j)>=ThFs)    
                                                    binaryImage(i,j)=1;    
                                                else
                                                    binaryImage(i,j)=0;
                                                end
                                                DoubleFBS = double(FuzzyBackgroundSustraction(i,j));
                                                alphan(i,j)=1-(1-alpha)*exp(-5*DoubleFBS);
                                                BackgroundFuzzyRunning_Average(i,j) = alphan(i,j)* BackgroundModelToProcess(i,j)+(1-alphan(i,j))*ImageToProcess(i,j);
                                                BackgroundFuzzyRunning_Average(i,j)  = uint8(BackgroundFuzzyRunning_Average(i,j));
                                        end
                                    end        
                        else
                                    for i=1:M
                                            for j=1:N
                                                    if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)
                                                        FuzzyBackgroundSustraction (i,j) = 1;      
                                                    else 
                                                         FuzzyBackgroundSustraction (i,j)= abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))/Ths;    
                                                    end 
                                                    
                                                    if app.LowPassFilteringCheckBox.Value
                                                            FuzzyBackgroundSustraction(i,j) = LowPassFiltering(app,FuzzyBackgroundSustraction(i,j)); 
                                                    end
                                                    if (FuzzyBackgroundSustraction(i,j)>=ThFs)    
                                                        binaryImage(i,j)=1;    
                                                    else
                                                        binaryImage(i,j)=0;
                                                    end
                                                    DoubleFBS = double(FuzzyBackgroundSustraction(i,j));
                                                    alphan(i,j)=1-(1-alpha)*exp(-5*DoubleFBS);
                                                    BackgroundFuzzyRunning_Average(i,j) = alphan(i,j)* BackgroundModelToProcess(i,j)+(1-alphan(i,j))*ImageToProcess(i,j);
                                                    BackgroundFuzzyRunning_Average(i,j)  = uint8(BackgroundFuzzyRunning_Average(i,j));
                                            end
                                    end  
                        end
              
                        %for i=1:M
                        %    for j=1:N
                        %            if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)
                        %                FuzzyBackgroundSustraction (i,j) = 1;      
                        %            else 
                        %                 FuzzyBackgroundSustraction (i,j)= abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))/Ths;    
                        %            end 
                        %            
                        %            if app.LowPassFilteringCheckBox.Value
                        %                    FuzzyBackgroundSustraction(i,j) = LowPassFiltering(app,FuzzyBackgroundSustraction(i,j)); 
                        %            end
                        %            if (FuzzyBackgroundSustraction(i,j)>=ThFs)    
                        %                binaryImage(i,j)=1;    
                        %            else
                        %                binaryImage(i,j)=0;
                        %            end
                        %            DoubleFBS = double(FuzzyBackgroundSustraction(i,j));
                        %            alphan(i,j)=1-(1-alpha)*exp(-5*DoubleFBS);
                        %            BackgroundFuzzyRunning_Average(i,j) = alphan(i,j)* BackgroundModelToProcess(i,j)+(1-alphan(i,j))*ImageToProcess(i,j);
                        %    end
                        %end
               
                
                %NewFrameForeGround = abs(ImageToProcess-BackgroundModelToProcess);
                %thresholdValue = app.ThresholdThsEditField.Value;
                %binaryImage = NewFrameForeGround > thresholdValue;
                ForeGroundImage=binaryImage;
                %%ImageDilatedBy square function
                ss = strel('square',app.DilatePixelSizeEditField.Value);
                ImageFirstFilter = imdilate(ForeGroundImage,ss);
                ImageDilated = ImageFirstFilter;
                %%Image Smooth By diamond function
                seD = strel('diamond',app.SmoothPixelSizeEditField.Value);
                Image_Second_Filter = imerode(ImageDilated,seD);
                SmoothPixel =  Image_Second_Filter;
                %result perimeter image
                Filter_outline = bwperim(SmoothPixel);
                ResultPerimeter = Filter_outline;
                %%Segmentation out line 
                Perimeter =  imoverlay(ImageToProcess,ResultPerimeter,'red');
                %% segetantion color
                Segmentation =  imoverlay(ImageToProcess,SmoothPixel,'red'); 
                RelativeConcentration = ((sum(ForeGroundImage(:)))/(numel((ForeGroundImage(:)))))*100;
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        
        
        end 
        
        function [ForeGroundImage, Segmentation, Perimeter, BackgroundFuzzyRunning_Average] = FuzzyBKGsubtractionRealTime (app,ImageToProcess, BackgroundModelToProcess ,Ths ,Thu, ThFs, alpha)
            try
                %foregroun dBinary using threshold
                [M,N] = size (ImageToProcess); 
                cores = str2num(app.CoresNumberDropDown_2.Value);
                FuzzyBackgroundSustraction = zeros(M,N);
                BackgroundFuzzyRunning_Average =  zeros(M,N);
                alphan = zeros(M,N);
                LowPassFilter = app.LowPassFilteringCheckBox.Value;
                        if cores ~= 1
                             if isempty(gcp('nocreate')) == true
                                 parpool('local',cores);
                                 parpool('IdleTimeout', 240);
                             end


                                    parfor i=1:M
                                        for j=1:N
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)
                                                    FuzzyBackgroundSustraction (i,j) = 1;      
                                                else 
                                                     FuzzyBackgroundSustraction (i,j)= abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))/Ths;    
                                                end 
                                                
                                                if LowPassFilter
                                                        FuzzyBackgroundSustraction(i,j) = LowPassFiltering(app,FuzzyBackgroundSustraction(i,j)); 
                                                end
                                                if (FuzzyBackgroundSustraction(i,j)>=ThFs)    
                                                    binaryImage(i,j)=1;    
                                                else
                                                    binaryImage(i,j)=0;
                                                end
                                                DoubleFBS = double(FuzzyBackgroundSustraction(i,j));
                                                alphan(i,j)=1-(1-alpha)*exp(-5*DoubleFBS);
                                                BackgroundFuzzyRunning_Average(i,j) = alphan(i,j)* BackgroundModelToProcess(i,j)+(1-alphan(i,j))*ImageToProcess(i,j);
                                                BackgroundFuzzyRunning_Average(i,j)  = uint8(BackgroundFuzzyRunning_Average(i,j));
                                        end
                                    end        
                        else
                                    for i=1:M
                                            for j=1:N
                                                    if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)
                                                        FuzzyBackgroundSustraction (i,j) = 1;      
                                                    else 
                                                         FuzzyBackgroundSustraction (i,j)= abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))/Ths;    
                                                    end 
                                                    
                                                    if app.LowPassFilteringCheckBox.Value
                                                            FuzzyBackgroundSustraction(i,j) = LowPassFiltering(app,FuzzyBackgroundSustraction(i,j)); 
                                                    end
                                                    if (FuzzyBackgroundSustraction(i,j)>=ThFs)    
                                                        binaryImage(i,j)=1;    
                                                    else
                                                        binaryImage(i,j)=0;
                                                    end
                                                    DoubleFBS = double(FuzzyBackgroundSustraction(i,j));
                                                    alphan(i,j)=1-(1-alpha)*exp(-5*DoubleFBS);
                                                    BackgroundFuzzyRunning_Average(i,j) = alphan(i,j)* BackgroundModelToProcess(i,j)+(1-alphan(i,j))*ImageToProcess(i,j);
                                                    BackgroundFuzzyRunning_Average(i,j)  = uint8(BackgroundFuzzyRunning_Average(i,j));
                                            end
                                    end  
                        end
              
                        %for i=1:M
                        %    for j=1:N
                        %            if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)
                        %                FuzzyBackgroundSustraction (i,j) = 1;      
                        %            else 
                        %                 FuzzyBackgroundSustraction (i,j)= abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))/Ths;    
                        %            end 
                        %            
                        %            if app.LowPassFilteringCheckBox.Value
                        %                    FuzzyBackgroundSustraction(i,j) = LowPassFiltering(app,FuzzyBackgroundSustraction(i,j)); 
                        %            end
                        %            if (FuzzyBackgroundSustraction(i,j)>=ThFs)    
                        %                binaryImage(i,j)=1;    
                        %            else
                        %                binaryImage(i,j)=0;
                        %            end
                        %            DoubleFBS = double(FuzzyBackgroundSustraction(i,j));
                        %            alphan(i,j)=1-(1-alpha)*exp(-5*DoubleFBS);
                        %            BackgroundFuzzyRunning_Average(i,j) = alphan(i,j)* BackgroundModelToProcess(i,j)+(1-alphan(i,j))*ImageToProcess(i,j);
                        %    end
                        %end
               
                
                %NewFrameForeGround = abs(ImageToProcess-BackgroundModelToProcess);
                %thresholdValue = app.ThresholdThsEditField.Value;
                %binaryImage = NewFrameForeGround > thresholdValue;
                ForeGroundImage=binaryImage;
                %%ImageDilatedBy square function
                %%ss = strel('square',app.DilatePixelSizeEditField.Value);
                %%ImageFirstFilter = imdilate(ForeGroundImage,ss);
                %%ImageDilated = ImageFirstFilter;
                %%Image Smooth By diamond function
                %%seD = strel('diamond',app.SmoothPixelSizeEditField.Value);
                %%Image_Second_Filter = imerode(ImageDilated,seD);
                %%SmoothPixel =  Image_Second_Filter;
                %result perimeter image
                Filter_outline = bwperim(ForeGroundImage);
                %%ResultPerimeter = Filter_outline;
                %%Segmentation out line 
                Perimeter =  imoverlay(ImageToProcess,ForeGroundImage,'red');
                %% segetantion color
                Segmentation =  imoverlay(ImageToProcess,ForeGroundImage,'red'); 
                %%RelativeConcentration = ((sum(ForeGroundImage(:)))/(numel((ForeGroundImage(:)))))*100;
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
       function [ForeGroundImage, Segmentation, Perimeter, BackgroundRunning_Average] = RunningAverageRealTime (app,ImageToProcess, BackgroundModelToProcess ,Ths ,Thu, ThFs, alpha)
            try
                %foregroun dBinary using threshold
                [M,N] = size (ImageToProcess); 
                cores = str2num(app.CoresNumberDropDown_3.Value);
                BackgroundRunning_Average = zeros(M,N);
                        if cores ~= 1
                              if isempty(gcp('nocreate')) == true
                                 parpool('local',cores);
                                 parpool('IdleTimeout', 240);
                              end
                                    parfor i=1:M
                                        for j=1:N
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))<Thu)
                                                    BackgroundRunning_Average(i,j) = alpha*BackgroundModelToProcess(i,j)+(1-alpha)*ImageToProcess(i,j);      
                                                else 
                                                     BackgroundRunning_Average(i,j)= BackgroundModelToProcess(i,j);    
                                                end 
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)    
                                                    binaryImage(i,j)=1;    
                                                else
                                                    binaryImage(i,j)=0;
                                                end
                                                BackgroundRunning_Average(i,j)  = uint8(BackgroundRunning_Average(i,j));
                                        end
                                    end      
                        else
                                    for i=1:M
                                        for j=1:N
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))<Thu)
                                                    BackgroundRunning_Average(i,j) = alpha*BackgroundModelToProcess(i,j)+(1-alpha)*ImageToProcess(i,j);      
                                                else 
                                                     BackgroundRunning_Average(i,j)= BackgroundModelToProcess(i,j);    
                                                end 
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)    
                                                    binaryImage(i,j)=1;    
                                                else
                                                    binaryImage(i,j)=0;
                                                end
                                                BackgroundRunning_Average(i,j)  = uint8(BackgroundRunning_Average(i,j));
                                        end
                                    end  
                        end
                
                
                       % for i=1:M
                       %    for j=1:N
                       %             if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))<Thu)
                       %                 BackgroundRunning_Average(i,j) = alpha*BackgroundModelToProcess(i,j)+(1-alpha)*ImageToProcess(i,j);      
                       %             else 
                       %                  BackgroundRunning_Average(i,j)= BackgroundModelToProcess(i,j);    
                       %             end 
                       %             if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)    
                       %                 binaryImage(i,j)=1;    
                       %             else
                       %                 binaryImage(i,j)=0;
                       %             end
                       %     end
                       % end
               
                
                %NewFrameForeGround = abs(ImageToProcess-BackgroundModelToProcess);
                %thresholdValue = app.ThresholdThsEditField.Value;
                %binaryImage = NewFrameForeGround > thresholdValue;
                ForeGroundImage=binaryImage;
                %%ImageDilatedBy square function
                %%ss = strel('square',app.DilatePixelSizeEditField.Value);
                %%ImageFirstFilter = imdilate(ForeGroundImage,ss);
                %%ImageDilated = ImageFirstFilter;
                %%Image Smooth By diamond function
                %%seD = strel('diamond',app.SmoothPixelSizeEditField.Value);
                %%Image_Second_Filter = imerode(ImageDilated,seD);
                %%SmoothPixel =  Image_Second_Filter;
                %%result perimeter image
                Filter_outline = bwperim(ForeGroundImage);
                %%ResultPerimeter = Filter_outline;
                %%Segmentation out line 
                Perimeter =  imoverlay(ImageToProcess,ForeGroundImage,'red');
                %% segetantion color
                Segmentation =  imoverlay(ImageToProcess,ForeGroundImage,'red'); 
                %%RelativeConcentration = ((sum(ForeGroundImage(:)))/(numel((ForeGroundImage(:)))))*100;
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        
        end
        
        
        function [ForeGroundImage, ImageDilated, SmoothPixel, ResultPerimeter, Segmentation, Perimeter, RelativeConcentration, BackgroundRunning_Average] = RunningAverage (app,ImageToProcess, BackgroundModelToProcess ,Ths ,Thu, ThFs, alpha)
            try
                %foregroun dBinary using threshold
                [M,N] = size (ImageToProcess); 
                cores = str2num(app.CoresNumberDropDown_2.Value);
                BackgroundRunning_Average = zeros(M,N);
                        if cores ~= 1
                              if isempty(gcp('nocreate')) == true
                                 parpool('local',cores);
                                 parpool('IdleTimeout', 240);
                              end
                                    parfor i=1:M
                                        for j=1:N
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))<Thu)
                                                    BackgroundRunning_Average(i,j) = alpha*BackgroundModelToProcess(i,j)+(1-alpha)*ImageToProcess(i,j);      
                                                else 
                                                     BackgroundRunning_Average(i,j)= BackgroundModelToProcess(i,j);    
                                                end 
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)    
                                                    binaryImage(i,j)=1;    
                                                else
                                                    binaryImage(i,j)=0;
                                                end
                                                BackgroundRunning_Average(i,j)  = uint8(BackgroundRunning_Average(i,j));
                                        end
                                    end      
                        else
                                    for i=1:M
                                        for j=1:N
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))<Thu)
                                                    BackgroundRunning_Average(i,j) = alpha*BackgroundModelToProcess(i,j)+(1-alpha)*ImageToProcess(i,j);      
                                                else 
                                                     BackgroundRunning_Average(i,j)= BackgroundModelToProcess(i,j);    
                                                end 
                                                if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)    
                                                    binaryImage(i,j)=1;    
                                                else
                                                    binaryImage(i,j)=0;
                                                end
                                                BackgroundRunning_Average(i,j)  = uint8(BackgroundRunning_Average(i,j));
                                        end
                                    end  
                        end
                
                
                       % for i=1:M
                       %    for j=1:N
                       %             if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))<Thu)
                       %                 BackgroundRunning_Average(i,j) = alpha*BackgroundModelToProcess(i,j)+(1-alpha)*ImageToProcess(i,j);      
                       %             else 
                       %                  BackgroundRunning_Average(i,j)= BackgroundModelToProcess(i,j);    
                       %             end 
                       %             if (abs((ImageToProcess(i,j)-BackgroundModelToProcess(i,j)))>Ths)    
                       %                 binaryImage(i,j)=1;    
                       %             else
                       %                 binaryImage(i,j)=0;
                       %             end
                       %     end
                       % end
               
                
                %NewFrameForeGround = abs(ImageToProcess-BackgroundModelToProcess);
                %thresholdValue = app.ThresholdThsEditField.Value;
                %binaryImage = NewFrameForeGround > thresholdValue;
                ForeGroundImage=binaryImage;
                %%ImageDilatedBy square function
                ss = strel('square',app.DilatePixelSizeEditField.Value);
                ImageFirstFilter = imdilate(ForeGroundImage,ss);
                ImageDilated = ImageFirstFilter;
                %%Image Smooth By diamond function
                seD = strel('diamond',app.SmoothPixelSizeEditField.Value);
                Image_Second_Filter = imerode(ImageDilated,seD);
                SmoothPixel =  Image_Second_Filter;
                %result perimeter image
                Filter_outline = bwperim(SmoothPixel);
                ResultPerimeter = Filter_outline;
                %%Segmentation out line 
                Perimeter =  imoverlay(ImageToProcess,ResultPerimeter,'red');
                %% segetantion color
                Segmentation =  imoverlay(ImageToProcess,SmoothPixel,'red'); 
                RelativeConcentration = ((sum(ForeGroundImage(:)))/(numel((ForeGroundImage(:)))))*100;
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                	f = app.UIFigure; 
                                   uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                %Initialize some variables
               
                app.ProgramFolder = cd;
                app.ImageFolder   = cd;
                app.VideoFolder   = cd;
                app.BackgroundFolder = cd;
                app.OutputFramesFolder = cd;
                app.OutputVideoFolder  = cd;
                app.OutputFinalFramesFolder = cd;
                app.OutputFramesFilteredFolder = cd;
                app.OutputBackgroundFolder = cd;
                app.FinalFilteredFolder = cd;
                app.FinalBackgroundFolder = cd;
                app.FramesFolderUsed = cd;
                app.OutputFramesToPIVFolder = cd;


                %Load up the initial Values From the mat file
                 f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
                [directory,~,~] = fileparts(mfilename('fullpath'));
                app.ProgramFolder = directory;
                LoadUserSettings(app);   
                %if the last-used image demos folder does not exist, but the
                %image folder exists, then point them to that MATLAB image
                %demos folder instead
                if exist(app.VideoFolder, 'dir') == 0
                    %folder stored in the mat file does not exist, try the
                    %im demos folder instead
                    imdemosFolder = fileparts(which('cameraman.tif')); % determine where demo images folder is 
                    if exist(imdemosFolder, 'dir') == 0
                        %image demos folder exist then use it
                        app.VideoFolder = imdemosFolder;
                        app.ImageFolder = imdemosFolder;
                    else
                        %imdemos folder doest not exist,, use current
                        %folder
                        app.VideoFolder = cd;
                        app.ImageFolder = cd;
                    end           
                end
                app.DirectoryFolder.Value = app.VideoFolder;
                % Load list of images in the image folder.
            	LoadVideoList(app);
            	% Select none of the items in the listbox.
            	app.VideoListBox.Value = {};
                %Load a Splash Image
                app.DirectoryFolderFrames.Value = app.ImageFolder;
                % Load list of images in the image folder.
               
            	LoadImageList(app);
            	% Select none of the items in the listbox.
            	app.FrameList.Value = {};
                %Load a Splash Image
                LoadImageFilteringList(app);
            	% Select none of the items in the listbox.
            	app.FrameListToFilter.Value = {};
                %Load a Splash Image
                LoadImageBackgroundList(app);
            	% Select none of the items in the listbox.
            	app.FrameListBackground.Value = {};
                %Load a Splash Image
                LoadBackgroundList(app);
            	% Select none of the items in the listbox.
            	app.BackgroundList.Value = {};
                %Load a Splash Image
                LoadBackgroundToAnalyzeList(app);
                app.BackgroundList_2.Value = {};
                
                LoadImageToAnalyzeList(app);
                app.ImagesToAnalizeList.Value = {};
                
                app.DirectoryFolderBackground_2.Value = app.BackgroundFolder;
                
                fullSplashImageName = fullfile(app.ProgramFolder, '/Splash Images/MakeMovie.png');
            	if exist(fullSplashImageName, 'file')
            		% Display splash image.
            		splashImage = imread(fullSplashImageName);
            	else
            		% Display something so it's not just blank.
            		splashImage = peaks(300);
            		fprintf('Warning: Splash image not found: %s\n', fullSplashImageName);
            	end
                % Display image in the "axesImage" axes control on the user interface.
            	%hold off;	% IMPORTANT NOTE: hold needs to be off in order for the "fit" feature to work correctly.
            	imshow(splashImage, 'InitialMagnification', 'fit', 'parent', app.Image);
                imshow(splashImage, 'InitialMagnification', 'fit', 'parent', app.Image_2);
                imshow(splashImage, 'InitialMagnification', 'fit', 'parent', app.Image_3);
                imshow(splashImage, 'InitialMagnification', 'fit', 'parent', app.Image_4);
                imshow(splashImage, 'InitialMagnification', 'fit', 'parent', app.Image_5);
                %InitialMagnification', 'fit', 'parent'
            	%axis off;
                app.Image.Title.String = 'Initialization......';
                app.Image.XLabel.String = '';
                app.Image.YLabel.String = '';
            	txtInfo = sprintf('\nCenter for Clean Technology Research (CCTR).\nby MOVIES');
            	%set(handles.txtInfo, 'string', txtInfo);
            	app.Result.Value = txtInfo;
                %Frames Option
                %axis off;
                app.Image_2.Title.String = 'Initialization......';
                app.Image_2.XLabel.String = '';
                app.Image_2.YLabel.String = '';
            	txtInfo = sprintf('\nCenter for Clean Technology Research (CCTR).\nby MOVIES');
            	%set(handles.txtInfo, 'string', txtInfo);
            	app.Result_2.Value = txtInfo;
                %axis off;
                app.Image_3.Title.String = 'Initialization......';
                app.Image_3.XLabel.String = '';
                app.Image_3.YLabel.String = '';
            	txtInfo = sprintf('\nCenter for Clean Technology Research (CCTR).\nby MOVIES');
            	%set(handles.txtInfo, 'string', txtInfo);
            	app.Result_3.Value = txtInfo;
                %axis off;
                app.Image_4.Title.String = 'Initialization......';
                app.Image_4.XLabel.String = '';
                app.Image_4.YLabel.String = '';
            	txtInfo = sprintf('\nCenter for Clean Technology Research (CCTR).\nby MOVIES');
            	%set(handles.txtInfo, 'string', txtInfo);
            	app.Result_4.Value = txtInfo;
                app.Image_5.Title.String = 'Initialization......';
                app.Image_5.XLabel.String = '';
                app.Image_5.YLabel.String = '';
            	txtInfo = sprintf('\nCenter for Clean Technology Research (CCTR).\nby MOVIES');
            	%set(handles.txtInfo, 'string', txtInfo);
            	app.Result_5.Value = txtInfo;
                progressdlg.delete();
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
               mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
               f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning'); 
            end
        end

        % Value changed function: SelectInputFolderButton
        function SelectInputFolderButtonValueChanged(app, event)
            %value = app.SelectInputFolderButton.Value;
            try
                Input_Directory = uigetdir(app.VideoFolder,'Select Folder');
                %Input_Directory will be 0 (a double) if the selection is canceled
                %Input_Directory will be the path (a string) if the selection is ok
                if Input_Directory ~= 0
                    % Assing the value if the selection wasn't cancel
                    app.VideoFolder = Input_Directory;
                    LoadVideoList(app);
                    app.DirectoryFolder.Value = app.VideoFolder;
                    % Save the image folder in our ini file.
    		    SaveUserSettings(app);
                end
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            SaveUserSettings(app);
            delete(app)
            
        end

        % Value changed function: SelectInputFolderButton_2
        function SelectInputFolderButton_2ValueChanged(app, event)
            %value = app.SelectInputFolderButton_2.Value;
            try
                FramesDirectory = uigetdir(app.ImageFolder,'Select Folder');
                % FramesDirectory will be 0 (a double) if they click cancel.
	        % FramesDirectory will be the path (a string) if they clicked OK.
                if FramesDirectory ~= 0
                    % Assign the value if they didn't click cancel.
                    app.ImageFolder = FramesDirectory;
	   	    LoadImageList(app);
                    app.DirectoryFolderFrames.Value = app.ImageFolder;
                    % Save the image folder in our ini file.
    		    SaveUserSettings(app);
                end
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: SortAlphabeticallyCheckBox
        function SortAlphabeticallyCheckBoxValueChanged(app, event)
            %value = app.SortAlphabeticallyCheckBox.Value;
            try
                textinfo = sprintf('Files will be sorted alphanumerically.');
                app.Result_2.Value = textinfo;
		ListOfImageNames = app.FrameList;
		ListOfImageNames = sort(ListOfImageNames);
		app.FrameList = ListOfImageNames; 
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning'); 
            end
        end

        % Value changed function: SortbyfiledatetimeCheckBox
        function SortbyfiledatetimeCheckBoxValueChanged(app, event)
            %value = app.SortbyfiledatetimeCheckBox.Value;
            try
                textInfo = sprintf('Files will be sorted by file date/time.');
                app.Result_2.Value = textInfo;
                errorMessage = sprintf('Not implemented yet ^_^');
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
                'Icon','warning'); 
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning'); 
            end
        end

        % Value changed function: PauseAftereachimageCheckBox
        function PauseAftereachimageCheckBoxValueChanged(app, event)
            try
                value = app.PauseAftereachimageCheckBox.Value;
                if value
                	app.Result.Value = ( 'Now you will be able to inspect the results before it processes the next image.');
                else
                	app.Result.Value = (  'Now the image(s) will be analyzed without pausing for you will be able to inspect the results in between images.');
                end
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end     
        end

        % Value changed function: FrameRateSpinner
        function FrameRateSpinnerValueChanged(app, event)
            %value = app.FrameRateSpinner.Value;
            try
             SpinnerValueChanged(app);
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            try 
                returnValue = uigetdir(app.OutputVideoFolder,'Select output folder');
            	% returnValue will be 0 (a double) if they click cancel.
            	% returnValue will be the path (a string) if they clicked OK.
             if returnValue ~= 0
                 % Assign the value if they didn't click cancel.
            	 app.OutputVideoFolder = returnValue;
                 app.OutputFolder.Value = app.OutputVideoFolder;   
             end         
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            try
                app.VisualizationLampLabel.Text = 'Processing..';
                 app.VisualizationLamp.Color = [1 0 0];
                 %drawnow;
                 
                 %NameFolder = [];
                 
                ListOfImageNames = app.FrameList.Items;
                selectedFiles = app.FrameList.Value;  
                numberOfFrames = length(selectedFiles);
                        % If none are selected, select them all
                  if numberOfFrames == 0      
                    		errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                            app.VisualizationLampLabel.Text = 'Done';
                            app.VisualizationLamp.Color = [0 0 1];
                            drawnow;
                           return;
                  end
               
                 promptMessage = sprintf('Do you want to save the frames filtered  to individual disk files?');
                 button = questdlg(promptMessage,'Save filtered frames?', 'Yes','No','Yes');
                 if strcmp(button,'Yes')
                    %[~,name,~] = fileparts(app.VideoListBox.Value);
                    baseFolderName = char(app.OutputBaseFileNameTextArea.Value);
                    if length(baseFolderName) < 1
            		baseFolderName = 'Video of frames';
                    end
                    %inputFolder = app.BackgroundFolder;
                    [~,name,~] = fileparts(app.FrameList.Value{1});
                    baseImageFileName  = char(name);
                    %index = find(isletter(baseImageFileName), 1);
                    name  = regexprep(baseImageFileName, '\d' , '' );
                    baseFolderName = char([baseFolderName,name])
                    outputfolder = sprintf('%s/%s',app.OutputVideoFolder,baseFolderName);
                    
                    if  exist(outputfolder,'dir')
                         errorMessage = sprintf('A folder already exists in the directory please choose a different name or delete the old version folder');
            	         f = app.UIFigure;         
                         uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                        return;
                    end
                    if ~exist(outputfolder,'dir') 
                        
                        mkdir(outputfolder);
                                                
                        SaveVideoOfFrames(app,outputfolder);
                        
                        app.FinalFilteredFolder = outputfolder;
                        
                       % Let user know we're done, and what the movie filename is.
            	
                    end
                  app.VisualizationLampLabel.Text = 'Done';
                  app.VisualizationLamp.Color = [0 0 1];
                  drawnow;
                end   
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
                mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                if contains(errorMessage, 'Permission Denied')
                % They probably still have the movie open in the Video Player (Windows Media Player).
                    errorMessage = sprintf('%s\n\nIf you have the movie open in the Video Player program,\nyou must close it before a new one can be created.', errorMessage);
                end
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
                'Icon','warning');
            end
        end

        % Value changed function: FrameList
        function FrameListValueChanged(app, event)
            value = app.FrameList.Value;
            try
                % Update the number of images in the Analyze button caption.
                UpdateAnalyzeButtonCaption(app);
                SpinnerValueChanged(app);
                % Get image name
            	selectedListboxItem = app.FrameList.Value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
            		return;
            	end
                % If only one is selected, display it.
                app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = strcat(cell2mat(value));
        	fullImageFileName = fullfile(app.ImageFolder,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
            			imgOriginal = DisplayImage(app, fullImageFileName);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(imgOriginal)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: SelectionOptionButton
        function SelectionOptionButtonPushed(app, event)
            try
                ButtonCaption = app.SelectionOptionButton.Text;
                if strcmp(ButtonCaption, 'Select All') == 1
            		% Select all items in the listbox.
            		% Need to find out how many items are in the listbox (both selected and
            		% unselected).  It's quirky and inefficient but it's the only way I
            		% know how to do it.
            		% First get the whole damn listbox text into a cell array.
            		caListboxString =  app.FrameList.Items;
            		%NumberOfItems = length(caListboxString);    % Get length of that cell array.
            		%AllIndices=1:NumberOfItems; % Make a vector of all indices.
            		% Select all indices.
                        app.FrameList.Value = caListboxString ; 
            		% Finally, change caption to say "Select None"
            		app.SelectionOptionButton.Text = 'Select None';
            		% It scrolls to the bottom of the list.  Use the following line
            		% if you want the first item at the top of the list.
            		%set(app.List.set, 'ListboxTop', 1);
            	else
            		% Select none of the items in the listbox.
            		app.FrameList.Value = {};
            		% Change caption to say Select All
            		app.SelectionOptionButton.Text = 'Select All';
                end
            	% Update the number of images in the Analyze button caption.
            	UpdateAnalyzeButtonCaption(app);
            	SpinnerValueChanged(app);
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: SelectOutputFolderButton_2
        function SelectOutputFolderButton_2Pushed(app, event)
            try 
                returnValue = uigetdir(app.OutputFramesFolder,'Select output folder');
            	% returnValue will be 0 (a double) if they click cancel.
            	% returnValue will be the path (a string) if they clicked OK.
             if returnValue ~= 0
                 % Assign the value if they didn't click cancel.
            	 app.OutputFramesFolder = returnValue;
                 app.OutputFolder_2.Value = app.OutputFramesFolder;   
             end         
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: VideoListBox
        function VideoListBoxValueChanged(app, event)
            value = app.VideoListBox.Value;
            try
                app.FinishNowCheckBox_2.Value = false;
                selectedListboxItem = app.VideoListBox.Value;
                	if isempty(selectedListboxItem)
                		% Bail out if nothing was selected.
                		% Change mouse pointer (cursor) to an arrow.
                		%%%%%%%%%%%set(gcf,'Pointer','arrow');
                		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                                %progressdlg.delete();
                		return;
                	end
                	% If more than one is selected, bail out.
                	%if length(selectedListboxItem) > 1
                	%	baseImageFileName = '';
                		% Change mouse pointer (cursor) to an arrow.
                		%%%%%%%%%%%set(gcf,'Pointer','arrow');
                		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                                %progressdlg.delete();
                	%	return;
                	%end
                	% If only one is selected, display it.
                        %app.Plot.Visible = 'off';
                	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
                	%ListOfImageNames = app.List.Items;
                	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                        baseImageFileName                = value;  
                	fullImageFileName = fullfile(app.VideoFolder,baseImageFileName);	% Prepend folder.
                        app.Video = VideoReader(fullImageFileName);
                        VideoHelp = VideoReader(fullImageFileName);
                        LoadVideoInformation(app);
                        firstFrame = read(VideoHelp,app.FrameVisualizationSpinner.Value);
                        Display_Image(app,firstFrame,baseImageFileName,app.Image);
                        if app.GrayImageCheckBox.Value
                            GrayImage=LoadFrameInformation(app,firstFrame);
                            Display_Image(app,GrayImage,baseImageFileName,app.Image);
                        end


            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            		                mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');    
            end
        end

        % Button pushed function: GetandSaveFramesButton
        function GetandSaveFramesButtonPushed(app, event)
            try
                promptMessage = sprintf('Do you want to save the individual frames out to individual disk files?');
                button = questdlg(promptMessage,'Save individual frames?', 'Yes','No','Yes');
                if strcmp(button,'Yes')
                    [~,name,~] = fileparts(app.VideoListBox.Value);
                    baseFolderName = char(app.OutputBaseFileNameTextArea_2.Value);
                    if length(baseFolderName) < 1
            		baseFolderName = 'Frames From';
                    end
                    outputfolder = sprintf('%s/%s %s',app.OutputFramesFolder,baseFolderName,name);
                    if  exist(outputfolder,'dir')
                         errorMessage = sprintf('A folder already exists in the directory please choose a different name or delete the old version folder THIS FOLDER WILL HAVE AN EXTRA I');
            	         f = app.UIFigure;         
                         uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                        extra = 'I';
                        outputfolder = sprintf('%s%s',outputfolder,extra);
                    end
                    if ~exist(outputfolder,'dir')
                        mkdir(outputfolder);
                        WriteFramesOfVideo(app,outputfolder);
                         message = sprintf('\nYour image  is in file:\n%s\n\nClick OK to play it', outputfolder);
                	titleBarCaption = 'Open it?';
                	buttonText = questdlg(message, titleBarCaption, 'OK, Open It', 'Skip opeing', 'OK, open It');
                	if contains(buttonText, 'OK', 'IgnoreCase', true)
                		% Play the movie in the default program (like Windows Media Player).
                		% I think winopen() only works for the Windows operating system.
                		if contains(computer, 'PCWIN')
                			% It's a windows computer.
                			winopen(outputfolder);
                		else
                			% It's a not a Windows computer.
                			disp('iMac');
                		end
                	end
                    end
                  LoadFrameOfVideoInList(app,outputfolder);
                  app.OutputFinalFramesFolder = outputfolder;
                end   
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Value changed function: FrameVisualizationSpinner
        function FrameVisualizationSpinnerValueChanged(app, event)
            %value = app.FrameVisualizationSpinner.Value;
            try
                 VideoListBoxValueChanged(app, event)
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Value changed function: GrayImageCheckBox
        function GrayImageCheckBoxValueChanged(app, event)
            %value = app.GrayImageCheckBox.Value;
            try
                VideoListBoxValueChanged(app, event);
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            try
                app.ImageFolder = app.OutputFinalFramesFolder;
                app.DirectoryFolderFrames.Value = app.ImageFolder;
                % Load list of images in the image folder.
            	LoadImageList(app);
            	% Select none of the items in the listbox.
            	app.FrameList.Value = {};   
                app.TabGroup.SelectedTab = app.FRAMESTOVIDEOTab;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Value changed function: FramesOfVideoList
        function FramesOfVideoListValueChanged(app, event)
            value = app.FramesOfVideoList.Value;
            try
                % Update the number of images in the Analyze button caption.
                %UpdateAnalyzeButtonCaption(app);
                %SpinnerValueChanged(app);
                % Get image name
            	selectedListboxItem = app.FramesOfVideoList.Value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	%if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
            	%	return;
            	%end
                % If only one is selected, display it.
                %app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = value;
        	fullImageFileName = fullfile(app.OutputFinalFramesFolder,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
                                thisFrame = imread(fullImageFileName);
            			Display_Image(app,thisFrame,baseImageFileName,app.Image);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(thisFrame)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: EXPORTTOIMAGEFILTERINGButton
        function EXPORTTOIMAGEFILTERINGButtonPushed(app, event)
             try
                app.FrameListToFilter.Items = {};
                app.DirectoryFolderFramesToFilter.Value = app.DirectoryFolderFrames.Value;
                %app.ImageFolder = app.OutputFinalFramesFolder;
                % Load list of images in the image folder.
            	LoadImageFilteringList(app);
            	% Select none of the items in the listbox.
            	app.FrameListToFilter.Value = {};   
                app.TabGroup.SelectedTab = app.FILTERINGTab;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Callback function
        function EXPORTButton_3Pushed(app, event)
            try
                app.FilteredImage = {};
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Button pushed function: DeleteFrameButton
        function DeleteFrameButtonPushed(app, event)
            try
                [~,idx] = ismember(app.FrameListToFilter.Value,app.FrameListToFilter.Items);
               app.FrameListToFilter.Items(idx) = [];
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: SelectAllSamplesButton
        function SelectAllSamplesButtonPushed(app, event)
           try
                app.FrameAfterFilter.Items = {};
            	% Find out button caption and take appropriate action.
            	ButtonCaption = app.SelectAllSamplesButton.Text;
            	if strcmp(ButtonCaption, 'Select All Samples') == 1
            		% Select all items in the listbox.
            		% Need to find out how many items are in the listbox (both selected and
            		% unselected).  It's quirky and inefficient but it's the only way I
            		% know how to do it.
            		% First get the whole damn listbox text into a cell array.
            		caListboxString =  app.FrameListToFilter.Items;
            		NumberOfItems = length(caListboxString);    % Get length of that cell array.
            		AllIndices=1:NumberOfItems; % Make a vector of all indices.
            		% Select all indices.
                        app.FrameListToFilter.Value = caListboxString ; 
                        
            		% Finally, change caption to say "Select None"
            		app.SelectAllSamplesButton.Text = 'Select None';
            		% It scrolls to the bottom of the list.  Use the following line
            		% if you want the first item at the top of the list.
                        
            		%set(app.List.set, 'ListboxTop', 1);
            	else
            		% Select none of the items in the listbox.
            		app.FrameListToFilter.Value = {};
            		% Change caption to say Select All
            		app.SelectAllSamplesButton.Text = 'Select All Samples';
            	end
            	% Update the number of images in the Analyze button caption.
            	%UpdateAnalyzeButtonCaption(app);
            	%SpinnerValueChanged(app);
            	
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: FrameListToFilter
        function FrameListToFilterValueChanged(app, event)
            value = app.FrameListToFilter.Value;
            try
                app.FrameAfterFilter.Items = {};
                StatusFilters(app);
                % Update the number of images in the Analyze button caption.
                %UpdateAnalyzeButtonCaption(app);
                %SpinnerValueChanged(app);
                % Get image name
            	selectedListboxItem = app.FrameListToFilter.Value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
            		return;
            	end
                % If only one is selected, display it.
                %app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = char(value);
        	fullImageFileName = fullfile(app.ImageFolder,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
                                thisFrame = imread(fullImageFileName);
            			Display_Image(app,thisFrame,baseImageFileName,app.Image_3);
                           
                                [rows, columns, numberOfColorChannels] = size(thisFrame);
                            	% Get the file date
                            	fileInfo = dir(fullImageFileName);
                            	txtInfo = sprintf('%s\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                            	[baseImageFileName], rows, columns, numberOfColorChannels);
                                % Show the file time and date.
                            	txtInfo = sprintf('%s\n%s', txtInfo, fileInfo.date);
                            	app.Result_3.Value = txtInfo;
               
                                PlotImageHistogram2(app,thisFrame);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(thisFrame)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: ImageAdjustButton
        function ImageAdjustButtonPushed(app, event)
            try
                %StatusFilters(app);
                app.VisualizationLamp_3Label.Text = 'Processing';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                startTime = tic;
                %filter used
                if isequal(app.ApplyFiltersButton.Text,'Apply Filters')
                    old_list = app.ListFilterUsed.Items;
                    new_list = {app.ImageAdjustButton.Text};
                    app.ListFilterUsed.Items = [old_list new_list];          
                end 
                if isequal(app.ApplyFiltersButton.Text,'Filtering')
                    old_list2 = app.ListFiltersApplied.Items;
                    new_list2 = {app.ImageAdjustButton.Text};
                    app.ListFiltersApplied.Items = [old_list2 new_list2];          
                end 
                
                %First application of the filter
                if length(app.FrameAfterFilter.Items) == 0
                    ListOfImageNames = app.FrameListToFilter;
    	            selectedFiles = app.FrameListToFilter.Value;
    	            numberOfFrames = length(selectedFiles);
                    % if none are selected
                    if numberOfFrames == 0
                        errorMessage = sprintf('Image no selected');
            	        f = app.UIFigure;
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    numberOfFramesWritten = 0;
                    %write FIltered Frames
                    for frame = 1 : numberOfFrames
                        %name of base file name
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        %%output base file name in list
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        %read image
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        [thisFrame,map]  = imread(sourceFrameFullFileName);
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %if the picture is color map
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end 
                        %%%%%%%%%%%%%%%%%imagen adjust
                        Image_Adjust = imadjust(thisFrame,stretchlim(thisFrame),[]);
                        %Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Image_Adjust;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.ImageAdjustButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                    end
                else
                    ListOfImageNames = app.FrameListToFilter.Items;
	            selectedFiles = app.FrameListToFilter.Value;
	            numberOfFrames = length(selectedFiles);
                    % if none are selected, select them all
                    if numberOfFrames == 0
                           errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    for frame = 1 : numberOfFrames
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        Image_Frame = app.FilteredImage{frame};
                        thisFrame = Image_Frame;
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %Check color
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end
                        %%%%%%%%%%%%%%%%%imagen adjust
                        Image_Adjust = imadjust(thisFrame,stretchlim(thisFrame),[]);
                        thisFrame = Image_Adjust;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.ImageAdjustButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;       
                    end    
                end
                app.VisualizationLamp_3Label.Text = 'Done';
                app.VisualizationLamp_3.Color = [0 0 1];
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            try
                app.VisualizationLamp_3Label.Text = 'Processing';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                startTime = tic;
                %filter used
                %old_list = app.ListFilterUsed.Items;
                %new_list = {app.HistogramEqualizationButton.Text};
            	%app.ListFilterUsed.Items = [old_list new_list];
                if isequal(app.ApplyFiltersButton.Text,'Apply Filters')
                    old_list = app.ListFilterUsed.Items;
                    new_list = {app.HistogramEqualizationButton.Text};
                    app.ListFilterUsed.Items = [old_list new_list];          
                end 
                if isequal(app.ApplyFiltersButton.Text,'Filtering')
                    old_list2 = app.ListFiltersApplied.Items;
                    new_list2 = {app.HistogramEqualizationButton.Text};
                    app.ListFiltersApplied.Items = [old_list2 new_list2];          
                end 
                %First application of the filter
                if length(app.FrameAfterFilter.Items) == 0
                    ListOfImageNames = app.FrameListToFilter;
    	            selectedFiles = app.FrameListToFilter.Value;
    	            numberOfFrames = length(selectedFiles);
                    % if none are selected
                    if numberOfFrames == 0
                        errorMessage = sprintf('Image no selected');
            	        f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    numberOfFramesWritten = 0;
                    %write FIltered Frames
                    for frame = 1 : numberOfFrames
                        %name of base file name
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        %%output base file name in list
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        %read image
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        [thisFrame,map]  = imread(sourceFrameFullFileName);
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %if the picture is color map
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end 
                        %%%%%%%%%%%%%%%%%iimagen Image_histogram_equalization
                        Image_histogram_equalization = histeq(thisFrame);
                        %Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Image_histogram_equalization;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.HistogramEqualizationButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                    end
                else
                    ListOfImageNames = app.FrameListToFilter.Items;
	            selectedFiles = app.FrameListToFilter.Value;
	            numberOfFrames = length(selectedFiles);
                    % if none are selected, select them all
                    if numberOfFrames == 0
                           errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    for frame = 1 : numberOfFrames
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        Image_Frame = app.FilteredImage{frame};
                        thisFrame = Image_Frame;
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %Check color
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end
                       %%%%%%%%%%%%%%%%%imagen Image_histogram_equalization
                        Image_histogram_equalization = histeq(thisFrame);
                        %Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Image_histogram_equalization;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.HistogramEqualizationButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;       
                    end    
                end
                app.VisualizationLamp_3Label.Text = 'Done';
                app.VisualizationLamp_3.Color = [0 0 1];
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: AdaptiveHistogramEquButton
        function AdaptiveHistogramEquButtonPushed(app, event)
            try
                app.VisualizationLamp_3Label.Text = 'Processing';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                startTime = tic;
                %filter used
                %old_list = app.ListFilterUsed.Items;
                %new_list = {app.AdaptiveHistogramEquButton.Text};
            	%app.ListFilterUsed.Items = [old_list new_list];
                if isequal(app.ApplyFiltersButton.Text,'Apply Filters')
                    old_list = app.ListFilterUsed.Items;
                    new_list = {app.AdaptiveHistogramEquButton.Text};
                    app.ListFilterUsed.Items = [old_list new_list];          
                end 
                if isequal(app.ApplyFiltersButton.Text,'Filtering')
                    old_list2 = app.ListFiltersApplied.Items;
                    new_list2 = {app.AdaptiveHistogramEquButton.Text};
                    app.ListFiltersApplied.Items = [old_list2 new_list2];          
                end
                %First application of the filter
                if length(app.FrameAfterFilter.Items) == 0
                    ListOfImageNames = app.FrameListToFilter;
    	            selectedFiles = app.FrameListToFilter.Value;
    	            numberOfFrames = length(selectedFiles);
                    % if none are selected
                    if numberOfFrames == 0
                        errorMessage = sprintf('Image no selected');
            	        f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    numberOfFramesWritten = 0;
                    %write FIltered Frames
                    for frame = 1 : numberOfFrames
                        %name of base file name
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        %%output base file name in list
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        %read image
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        [thisFrame,map]  = imread(sourceFrameFullFileName);
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %if the picture is color map
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end 
                       %%%%%%%%%%%%%%%%%imagen Image_adaptive_histogram_equalizatio
                        Image_adaptive_histogram_equalization = adapthisteq(thisFrame);
                        %Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Image_adaptive_histogram_equalization;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.AdaptiveHistogramEquButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                    end
                else
                    ListOfImageNames = app.FrameListToFilter.Items;
	            selectedFiles = app.FrameListToFilter.Value;
	            numberOfFrames = length(selectedFiles);
                    % if none are selected, select them all
                    if numberOfFrames == 0
                           errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    for frame = 1 : numberOfFrames
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        Image_Frame = app.FilteredImage{frame};
                        thisFrame = Image_Frame;
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %Check color
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end
                      %%%%%%%%%%%%%%%%%imagen Image_adaptive_histogram_equalizatio
                        Image_adaptive_histogram_equalization = adapthisteq(thisFrame);
                        %Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Image_adaptive_histogram_equalization;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.AdaptiveHistogramEquButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;       
                    end    
                end
                app.VisualizationLamp_3Label.Text = 'Done';
                app.VisualizationLamp_3.Color = [0 0 1];
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: FlatFieldcorrectionButton
        function FlatFieldcorrectionButtonPushed(app, event)
            try
                app.VisualizationLamp_3Label.Text = 'Processing';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                startTime = tic;
                %filter used
                %old_list = app.ListFilterUsed.Items;
                %new_list = {app.FlatFieldcorrectionButton.Text};
            	%app.ListFilterUsed.Items = [old_list new_list];
                if isequal(app.ApplyFiltersButton.Text,'Apply Filters')
                    old_list = app.ListFilterUsed.Items;
                    new_list = {app.FlatFieldcorrectionButton.Text};
                    app.ListFilterUsed.Items = [old_list new_list];          
                end 
                if isequal(app.ApplyFiltersButton.Text,'Filtering')
                    old_list2 = app.ListFiltersApplied.Items;
                    new_list2 = {app.FlatFieldcorrectionButton.Text};
                    app.ListFiltersApplied.Items = [old_list2 new_list2];          
                end
                %First application of the filter
                if length(app.FrameAfterFilter.Items) == 0
                    ListOfImageNames = app.FrameListToFilter;
    	            selectedFiles = app.FrameListToFilter.Value;
    	            numberOfFrames = length(selectedFiles);
                    % if none are selected
                    if numberOfFrames == 0
                        errorMessage = sprintf('Image no selected');
            	        f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    numberOfFramesWritten = 0;
                    %write FIltered Frames
                    for frame = 1 : numberOfFrames
                        %name of base file name
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        %%output base file name in list
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        %read image
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        [thisFrame,map]  = imread(sourceFrameFullFileName);
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %if the picture is color map
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end 
                       %%%%%%%%%%%%%%%%%imagen flat field
                         sigma = app.Sigma.Value;
                        Iflatfield = imflatfield(thisFrame,sigma);
                        thisFrame = Iflatfield;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.FlatFieldcorrectionButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                    end
                else
                    ListOfImageNames = app.FrameListToFilter.Items;
	            selectedFiles = app.FrameListToFilter.Value;
	            numberOfFrames = length(selectedFiles);
                    % if none are selected, select them all
                    if numberOfFrames == 0
                           errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    for frame = 1 : numberOfFrames
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        Image_Frame = app.FilteredImage{frame};
                        thisFrame = Image_Frame;
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %Check color
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end
                      %%%%%%%  %%%%%%%%%%%%%%%%%imagen flat field
                        sigma = app.Sigma.Value;
                        Iflatfield = imflatfield(thisFrame,sigma);
                        thisFrame = Iflatfield;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.FlatFieldcorrectionButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;       
                    end    
                end
                app.VisualizationLamp_3Label.Text = 'Done';
                app.VisualizationLamp_3.Color = [0 0 1];
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: MedianfilteringButton
        function MedianfilteringButtonPushed(app, event)
            try
                app.VisualizationLamp_3Label.Text = 'Processing';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                startTime = tic;
                %filter used
                %old_list = app.ListFilterUsed.Items;
                %new_list = {app.MedianfilteringButton.Text};
            	%app.ListFilterUsed.Items = [old_list new_list];
                if isequal(app.ApplyFiltersButton.Text,'Apply Filters')
                    old_list = app.ListFilterUsed.Items;
                    new_list = {app.MedianfilteringButton.Text};
                    app.ListFilterUsed.Items = [old_list new_list];          
                end 
                if isequal(app.ApplyFiltersButton.Text,'Filtering')
                    old_list2 = app.ListFiltersApplied.Items;
                    new_list2 = {app.MedianfilteringButton.Text};
                    app.ListFiltersApplied.Items = [old_list2 new_list2];          
                end
                %First application of the filter
                if length(app.FrameAfterFilter.Items) == 0
                    ListOfImageNames = app.FrameListToFilter;
    	            selectedFiles = app.FrameListToFilter.Value;
    	            numberOfFrames = length(selectedFiles);
                    % if none are selected
                    if numberOfFrames == 0
                        errorMessage = sprintf('Image no selected');
            	        f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    numberOfFramesWritten = 0;
                    %write FIltered Frames
                    for frame = 1 : numberOfFrames
                        %name of base file name
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        %%output base file name in list
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        %read image
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        [thisFrame,map]  = imread(sourceFrameFullFileName);
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %if the picture is color map
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end 
                       %%%%%%%%%%%%%%%%%imagen mean filter
                        Median_Filter = medfilt2(thisFrame);
                        thisFrame = Median_Filter;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.MedianfilteringButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                    end
                else
                    ListOfImageNames = app.FrameListToFilter.Items;
	            selectedFiles = app.FrameListToFilter.Value;
	            numberOfFrames = length(selectedFiles);
                    % if none are selected, select them all
                    if numberOfFrames == 0
                           errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    for frame = 1 : numberOfFrames
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        Image_Frame = app.FilteredImage{frame};
                        thisFrame = Image_Frame;
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %Check color
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end
                      %%%%%%%%%%%%%%%%%imagen mean filter
                        Median_Filter = medfilt2(thisFrame);
                        thisFrame = Median_Filter;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.MedianfilteringButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;       
                    end    
                end
                app.VisualizationLamp_3Label.Text = 'Done';
                app.VisualizationLamp_3.Color = [0 0 1];
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: AdaptiveFilteringButton
        function AdaptiveFilteringButtonPushed(app, event)
            try
                app.VisualizationLamp_3Label.Text = 'Processing';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                startTime = tic;
                %filter used
                %old_list = app.ListFilterUsed.Items;
                %new_list = {app.AdaptiveFilteringButton.Text};
            	%app.ListFilterUsed.Items = [old_list new_list];
                if isequal(app.ApplyFiltersButton.Text,'Apply Filters')
                    old_list = app.ListFilterUsed.Items;
                    new_list = {app.AdaptiveFilteringButton.Text};
                    app.ListFilterUsed.Items = [old_list new_list];          
                end 
                if isequal(app.ApplyFiltersButton.Text,'Filtering')
                    old_list2 = app.ListFiltersApplied.Items;
                    new_list2 = {app.AdaptiveFilteringButton.Text};
                    app.ListFiltersApplied.Items = [old_list2 new_list2];          
                end
                %First application of the filter
                if length(app.FrameAfterFilter.Items) == 0
                    ListOfImageNames = app.FrameListToFilter;
    	            selectedFiles = app.FrameListToFilter.Value;
    	            numberOfFrames = length(selectedFiles);
                    % if none are selected
                    if numberOfFrames == 0
                        errorMessage = sprintf('Image no selected');
            	        f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    numberOfFramesWritten = 0;
                    %write FIltered Frames
                    for frame = 1 : numberOfFrames
                        %name of base file name
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        %%output base file name in list
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        %read image
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        [thisFrame,map]  = imread(sourceFrameFullFileName);
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %if the picture is color map
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end 
                        %%%%%%%%%%%%%%%%%iRemove Noise By Adaptive Filtering
                        Adaptive_Filtering = wiener2(thisFrame,[5 5]);
                        %Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Adaptive_Filtering;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.AdaptiveFilteringButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                    end
                else
                    ListOfImageNames = app.FrameListToFilter.Items;
	            selectedFiles = app.FrameListToFilter.Value;
	            numberOfFrames = length(selectedFiles);
                    % if none are selected, select them all
                    if numberOfFrames == 0
                           errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    for frame = 1 : numberOfFrames
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        Image_Frame = app.FilteredImage{frame};
                        thisFrame = Image_Frame;
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %Check color
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end
                        %%%%%%%%%%%%%%%%%iRemove Noise By Adaptive Filtering
                        Adaptive_Filtering = wiener2(thisFrame,[5 5]);
                        %Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Adaptive_Filtering;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.AdaptiveFilteringButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;       
                    end    
                end
                app.VisualizationLamp_3Label.Text = 'Done';
                app.VisualizationLamp_3.Color = [0 0 1];
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: DenoisedButton
        function DenoisedButtonPushed(app, event)
            try
                app.VisualizationLamp_3Label.Text = 'Processing';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                startTime = tic;
                %filter used
                %old_list = app.ListFilterUsed.Items;
                %new_list = {app.DenoisedButton.Text};
            	%app.ListFilterUsed.Items = [old_list new_list];
                if isequal(app.ApplyFiltersButton.Text,'Apply Filters')
                    old_list = app.ListFilterUsed.Items;
                    new_list = {app.DenoisedButton.Text};
                    app.ListFilterUsed.Items = [old_list new_list];          
                end 
                if isequal(app.ApplyFiltersButton.Text,'Filtering')
                    old_list2 = app.ListFiltersApplied.Items;
                    new_list2 = {app.DenoisedButton.Text};
                    app.ListFiltersApplied.Items = [old_list2 new_list2];          
                end
                %First application of the filter
                if length(app.FrameAfterFilter.Items) == 0
                    ListOfImageNames = app.FrameListToFilter;
    	            selectedFiles = app.FrameListToFilter.Value;
    	            numberOfFrames = length(selectedFiles);
                    % if none are selected
                    if numberOfFrames == 0
                        errorMessage = sprintf('Image no selected');
            	        f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    numberOfFramesWritten = 0;
                    %write FIltered Frames
                    for frame = 1 : numberOfFrames
                        %name of base file name
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        %%output base file name in list
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        %read image
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        [thisFrame,map]  = imread(sourceFrameFullFileName);
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %if the picture is color map
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end 
                        %%%%%%%%%%%%%%%%%iRemove Noise By denoised
                        Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Denoised_Image;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                                PlotImageHistogram2(app,thisFrame);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.DenoisedButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;   
                    end
                else
                    ListOfImageNames = app.FrameListToFilter.Items;
	            selectedFiles = app.FrameListToFilter.Value;
	            numberOfFrames = length(selectedFiles);
                    % if none are selected, select them all
                    if numberOfFrames == 0
                           errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                    end
                    %Specify the input folder name
                    inputFolder = app.ImageFolder;
                    for frame = 1 : numberOfFrames
                        baseFileName = char(app.OutputBaseFileNameTextArea_3.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Filtered ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.FrameListToFilter.Value{frame}];
                        app.FrameAfterFilter.Items(frame) = {outputBaseFileName};
                        sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                        sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        Image_Frame = app.FilteredImage{frame};
                        thisFrame = Image_Frame;
                        [rows, columns, numberOfColorChannels] = size(thisFrame);
                        % Check if they want to quit
                		if app.FinishNowCheckBox_3.Value
                			break;
                		end
                        %Check color
                        if numberOfColorChannels > 1
                          % It's a true color RGB image.  We need to convert to gray scale.
                          thisFrame = rgb2gray(thisFrame);
                        else
                              % It's already gray scale.  No need to convert.
                              thisFrame = thisFrame;
                        end
                        %%%%%%%%%%%%%%%%%iRemove Noise By denoised
                        Denoised_Image = denoised_CNN(app,thisFrame);
                        thisFrame = Denoised_Image;
                        plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,outputBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                        app.FilteredImage{frame} = thisFrame;
                        elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing %s',app.DenoisedButton.Text);
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	        txtinfo,frame, numberOfFrames, outputBaseFileName, elapsedSeconds);
                        app.Result_3.Value = txtinfo;  
                        drawnow;       
                    end    
                end
                app.VisualizationLamp_3Label.Text = 'Done';
                app.VisualizationLamp_3.Color = [0 0 1];
                drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            try
                app.FrameAfterFilter.Items = {};
                app.ListFiltersApplied.Items = {};
                app.FilteredImage = {};
                app.ListFilterUsed.Items = {};
                FrameListToFilterValueChanged(app, event);
                
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end       
        end

        % Value changed function: FrameAfterFilter
        function FrameAfterFilterValueChanged(app, event)
            value = app.FrameAfterFilter.Value;
            try
                ListOfImageNames = app.FrameAfterFilter.Items;
	        selectedFiles = app.FrameAfterFilter.Value;
	        numberOfFrames = length(selectedFiles);
            % If none are selected, select them all
                  if numberOfFrames == 0      
                    		errorMessage = sprintf('Image no selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning'); 
                  end
                  inputFolder = app.ImageFolder;
                  sourceBaseFileName = char(app.FrameAfterFilter.Value);
                  sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                  [~,idx] = ismember(app.FrameAfterFilter.Value,app.FrameAfterFilter.Items);
                  thisFrame = app.FilteredImage{idx};
                  plotWindows = app.CheckProcessCheckBox_2.Value;
                        if plotWindows
                 	  %fprintf('About to plot histogram...\n');
                                Display_Image(app,thisFrame,sourceBaseFileName,app.Image_3);
                		%Plot_Image_Filtered(app, thisFrame,sourceFrameFullFileName);
                                PlotImageHistogram2(app,thisFrame);
                                drawnow;
               		 % fprintf('Done plotting histogram.\n');
                        end 
                  
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end    
        end

        % Button pushed function: DeleteFilterButton
        function DeleteFilterButtonPushed(app, event)
            try
               [~,idx] = ismember(app.ListFilterUsed.Value,app.ListFilterUsed.Items);
               app.ListFilterUsed.Items(idx) = [];
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
                
        end

        % Button pushed function: ApplyFiltersButton
        function ApplyFiltersButtonPushed(app, event)
            try
                app.VisualizationLamp_3Label.Text = 'Processing..';
                app.VisualizationLamp_3.Color = [1 0 0];
                drawnow;
                app.ApplyFiltersButton.Text = 'Filtering';  
                drawnow;
                inputFolder = app.ImageFolder;
                % Get the output filename.
                %outputFolder = app.outputImageFolder;
                ListOfImageNames = app.ListFilterUsed.Items;
                selectedFiles = app.ListFilterUsed.Value;
                numberOfFrames = length(ListOfImageNames);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               % If none are selected, select them all
                  if numberOfFrames == 0      
                    		errorMessage = sprintf('No filter selected');
            	                 f = app.UIFigure;
                           %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
                           
                  end
                   % Specify the input folder name.   
                    numberOfFramesWritten = 0;
                    for filter = 1 : numberOfFrames
                        option = char(app.ListFilterUsed.Items{filter});
                        if app.FinishNowCheckBox_3.Value
                			break;
                	end
                         %inputFolder = app.imageFolder;isequal
                        if isequal(app.DenoisedButton.Text , option)
                            DenoisedButtonPushed(app, event);
                            drawnow;
                        end
                        if isequal(option , app.ImageAdjustButton.Text)
                            ImageAdjustButtonPushed(app,event);
                            drawnow;
                        end
                        if isequal(option , app.HistogramEqualizationButton.Text)
                            HistogramEqualizationButtonPushed(app,event);
                            drawnow;
                        end
                        if isequal(option , app.AdaptiveFilteringButton.Text)
                            AdaptiveFilteringButtonPushed(app,event);
                            drawnow;
                        end
                        if isequal(option , app.FlatFieldcorrectionButton.Text)
                            FlatFieldcorrectionButtonPushed(app,event);
                            drawnow;
                        end
                        if isequal(option , app.MedianfilteringButton.Text)
                            MedianfilteringButtonPushed(app,event);
                            drawnow;
                        end
                        if isequal(option , app.AdaptiveHistogramEquButton.Text)
                            AdaptiveHistogramEquButtonPushed(app,event);
                            drawnow;
                        end   
                    end        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            app.VisualizationLamp_3Label.Text = 'Done';
            app.VisualizationLamp_3.Color = [0 0 1];
            drawnow;
            app.ApplyFiltersButton.Text = 'Apply Filters';  
            drawnow;
            %app.ProcessingLabel.Visible = 'on';
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end   
        end

        % Button pushed function: SelectOutputFolderButton_3
        function SelectOutputFolderButton_3Pushed(app, event)
            try 
                returnValue = uigetdir(app.OutputVideoFolder,'Select output folder');
            	% returnValue will be 0 (a double) if they click cancel.
            	% returnValue will be the path (a string) if they clicked OK.
             if returnValue ~= 0
                 % Assign the value if they didn't click cancel.
            	 app.OutputFramesFilteredFolder = returnValue;
                 app.OutputFolder_3.Value = app.OutputVideoFolder;   
             end         
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: SaveImageFilteredButton
        function SaveImageFilteredButtonPushed(app, event)
             try 
                 app.VisualizationLamp_3Label.Text = 'Processing..';
                 app.VisualizationLamp_3.Color = [1 0 0];
                 drawnow;
                 NameFolder = [];
                 itemsFilterbefore = app.ListFilterUsed.Items;
                 itemsFilterAfter = app.ListFiltersApplied.Items;
                 numberOfFilterB = length(itemsFilterbefore);
                 numberOfFilterA = length(itemsFilterAfter);
                 if numberOfFilterA == 0
                     if numberOfFilterB == 0
                          errorMessage = sprintf('No filter used');
            	         f = app.UIFigure;         
                         uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                     else
                         for filters = 1 : numberOfFilterB
                             NameFolder = [NameFolder  app.ListFilterUsed.Items{filters}]
                         end    
                     end
                 else
                     for filters = 1 : numberOfFilterB
                             NameFolder = [NameFolder app.ListFiltersApplied.Items{filters}]
                     end
                 end
                FullNameFolder = char(NameFolder);
                promptMessage = sprintf('Do you want to save the frames filtered  to individual disk files?');
                button = questdlg(promptMessage,'Save filtered frames?', 'Yes','No','Yes');
                if strcmp(button,'Yes')
                    %[~,name,~] = fileparts(app.VideoListBox.Value);
                    
                    baseFolderName = char(app.OutputBaseFileNameTextArea_3.Value);
                    if length(baseFolderName) < 1
            		baseFolderName = 'Filter Operation';
                    end
                    outputfolder = sprintf('%s/%s %s',app.OutputFramesFilteredFolder,baseFolderName,FullNameFolder);
                    if  exist(outputfolder,'dir')
                         errorMessage = sprintf('A folder already exists in the directory please choose a different name or delete the old version folder');
            	         f = app.UIFigure;         
                         uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                    end
                    if ~exist(outputfolder,'dir')  
                        mkdir(outputfolder);
                        
                        
                        SaveFilteredImages(app,outputfolder);
                        app.FinalFilteredFolder = outputfolder;
                        
                         message = sprintf('\nYour images filteredes  is in file:\n%s\n\nClick OK to play it', outputfolder);
                	titleBarCaption = 'Open it?';
                	buttonText = questdlg(message, titleBarCaption, 'OK, Open It', 'Skip opeing', 'OK, open It');
                	if contains(buttonText, 'OK', 'IgnoreCase', true)
                		% Play the movie in the default program (like Windows Media Player).
                		% I think winopen() only works for the Windows operating system.
                		if contains(computer, 'PCWIN')
                			% It's a windows computer.
                			winopen(outputfolder);
                		else
                			% It's a not a Windows computer.
                			disp('iMac');
                		end
                	end
                    end
                  app.VisualizationLamp_3Label.Text = 'Done';
                  app.VisualizationLamp_3.Color = [0 0 1];
                  drawnow;
                end   
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Button pushed function: EXPORTTOBKGSUBSTRACTIONButton_2
        function EXPORTTOBKGSUBSTRACTIONButton_2Pushed(app, event)
            try
                app.FrameListBackground.Items = {};
                app.DirectoryFolderFramesToBackground.Value = app.FinalFilteredFolder;
                %app.ImageFolder = app.OutputFinalFramesFolder;
                % Load list of images in the image folder.
            	LoadImageBackgroundList(app);
            	% Select none of the items in the listbox.
            	app.FrameListToFilter.Value = {};   
                app.TabGroup.SelectedTab = app.BACKGROUNDSUBTRACTIONMODELTab;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Button pushed function: DeleteFrameButton_2
        function DeleteFrameButton_2Pushed(app, event)
            try
                [~,idx] = ismember(app.FrameListBackground.Value,app.FrameListBackground.Items);
               app.FrameListBackground.Items(idx) = [];
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: SelectAllSamplesButton_2
        function SelectAllSamplesButton_2Pushed(app, event)
             try
                app.FrameAfterFilter.Items = {};
            	% Find out button caption and take appropriate action.
            	ButtonCaption = app.SelectAllSamplesButton_2.Text;
            	if strcmp(ButtonCaption, 'Select All Samples') == 1
            		% Select all items in the listbox.
            		% Need to find out how many items are in the listbox (both selected and
            		% unselected).  It's quirky and inefficient but it's the only way I
            		% know how to do it.
            		% First get the whole damn listbox text into a cell array.
            		caListboxString =  app.FrameListBackground.Items;
            		NumberOfItems = length(caListboxString);    % Get length of that cell array.
            		AllIndices=1:NumberOfItems; % Make a vector of all indices.
            		% Select all indices.
                        app.FrameListBackground.Value = caListboxString ; 
                        
            		% Finally, change caption to say "Select None"
            		app.SelectAllSamplesButton_2.Text = 'Select None';
            		% It scrolls to the bottom of the list.  Use the following line
            		% if you want the first item at the top of the list.
                        
            		%set(app.List.set, 'ListboxTop', 1);
            	else
            		% Select none of the items in the listbox.
            		app.FrameListBackground.Value = {};
            		% Change caption to say Select All
            		app.SelectAllSamplesButton_2.Text = 'Select All Samples';
            	end
            	% Update the number of images in the Analyze button caption.
            	%UpdateAnalyzeButtonCaption(app);
            	%SpinnerValueChanged(app);
            	
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: SelectInputFolderButton_3
        function SelectInputFolderButton_3ValueChanged(app, event)
            value = app.SelectInputFolderButton_3.Value;
             %value = app.SelectInputFolderButton_2.Value;
            try
                FramesDirectory = uigetdir(app.BackgroundFolder,'Select Folder');
                % FramesDirectory will be 0 (a double) if they click cancel.
	        % FramesDirectory will be the path (a string) if they clicked OK.
                if FramesDirectory ~= 0
                    % Assign the value if they didn't click cancel.
                    app.BackgroundFolder = FramesDirectory;
	   	    LoadBackgroundList(app);
                    app.DirectoryFolderFrames_2.Value = app.BackgroundFolder;
                    % Save the image folder in our ini file.
    		    SaveUserSettings(app);
                end
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: FrameListBackground
        function FrameListBackgroundValueChanged(app, event)
            value = app.FrameListBackground.Value;   
            try
                app.Create3DARRAYButton.Visible = 'On';
                app.Create3DARRAYButton_2.Visible = 'Off';
                app.BackgroundList.Value= {};
                app.BackgroundModellist.Value= {};
                %app.BackgroundModellist.Items = {};
                %StatusFilters(app);
                % Update the number of images in the Analyze button caption.
                %UpdateAnalyzeButtonCaption(app);
                %SpinnerValueChanged(app);
                % Get image name
            	selectedListboxItem = app.FrameListBackground.Value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  %s\n.',txtinfo,char(value(1)), length(selectedListboxItem), app.FinalFilteredFolder );
                        app.Result_4.Value = txtinfo; 
            		return;
            	end
                % If only one is selected, display it.
                %app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = char(value);
        	fullImageFileName = fullfile(app.FinalFilteredFolder,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
                                thisFrame = imread(fullImageFileName);
            			Display_Image(app,thisFrame,baseImageFileName,app.Image_4);
                           
                                [rows, columns, numberOfColorChannels] = size(thisFrame);
                            	% Get the file date
                            	fileInfo = dir(fullImageFileName);
                            	txtInfo = sprintf('%s\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                            	[baseImageFileName], rows, columns, numberOfColorChannels);
                                % Show the file time and date.
                            	txtInfo = sprintf('%s\n%s', txtInfo, fileInfo.date);
                            	app.Result_4.Value = txtInfo;
               
                                %PlotImageHistogram2(app,thisFrame);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(thisFrame)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: BackgroundList
        function BackgroundListValueChanged(app, event)
            value = app.BackgroundList.Value;
            try
                app.Create3DARRAYButton.Visible = 'Off';
                app.Create3DARRAYButton_2.Visible = 'On';
                app.FrameListBackground.Value = {};
                app.BackgroundModellist.Value= {};
                %app.BackgroundModellist.Items = {};
                %StatusFilters(app);
                % Update the number of images in the Analyze button caption.
                %UpdateAnalyzeButtonCaption(app);
                %SpinnerValueChanged(app);
                % Get image name
            	selectedListboxItem = app.BackgroundList.Value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  %s\n.',txtinfo,char(value(1)), length(selectedListboxItem), app.BackgroundFolder );
                        app.Result_4.Value = txtinfo; 
            		return;
            	end
                % If only one is selected, display it.
                %app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = char(value);
        	fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
                                thisFrame = imread(fullImageFileName);
            			Display_Image(app,thisFrame,baseImageFileName,app.Image_4);
                           
                                [rows, columns, numberOfColorChannels] = size(thisFrame);
                            	% Get the file date
                            	fileInfo = dir(fullImageFileName);
                            	txtInfo = sprintf('%s\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                            	[baseImageFileName], rows, columns, numberOfColorChannels);
                                % Show the file time and date.
                            	txtInfo = sprintf('%s\n%s', txtInfo, fileInfo.date);
                            	app.Result_4.Value = txtInfo;
               
                                %PlotImageHistogram2(app,thisFrame);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(thisFrame)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: GetCoresInfoButton
        function GetCoresInfoButtonPushed(app, event)
            try
                ore_info = evalc('feature(''numcores'')');
                 % Show the file time and date.
                txtInfo = sprintf(' Cores Information');
                txtInfo = sprintf('%s\n%s', txtInfo, ore_info);
                app.Result_4.Value = txtInfo;
                
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: Create3DARRAYButton
        function Create3DARRAYButtonPushed(app, event)
            try 
                 app.VisualizationLamp_4Label.Text= 'Processing..';
                 app.VisualizationLamp_4.Color = [1 0 0];
                 drawnow;
                 value = app.FrameListBackground.Value; 
                 ListOfImageNames = app.FrameListBackground.Items;
                 selectedFiles = app.FrameListBackground.Value;
                 numberOfFrames = length(selectedFiles);
                 if numberOfFrames == 0
                      errorMessage = sprintf('Image no selected');
                       f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                       selectedFiles = app.FrameListBackground.Items;
                       numberOfFrames = length(selectedFiles);
                 end
                 
                
                 cores = str2num(app.CoresNumberDropDown.Value);
                 
                 inputFolder =app.FinalFilteredFolder;
                 app.FramesFolderUsed = app.FinalFilteredFolder;
                 if cores ~= 1
                         %txt = app.Result_4;
                         parpool('local',cores);
                         startTime = tic
                         parfor frame = 1: numberOfFrames
                           %  
                         %  if frame < app.NumberOfFEditField.Value
                         %  First_Frame = app.Image2BKG{frame};
                         %  Second_Frame = app.Image2BKG{frame+1}
                          % end
                           baseImageFileName                = char(value{frame});
                	   fullImageFileName = fullfile(inputFolder,baseImageFileName);
                           thisFrame = imread(fullImageFileName);
                           Array(:,:,frame) = thisFrame ;
                           %elapsedSeconds = toc(startTime);
                           % txtinfo = sprintf('\n Precessing ');
                           % txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
                	   % txtinfo,frame, numberOfFrames, baseImageFileName, elapsedSeconds);
                            %txt.Value = txtinfo;
                         end
                         elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing ');
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn  of %d: \nElapsed time = %.1f seconds.', ...
            	        txtinfo, numberOfFrames, elapsedSeconds);
                        app.Result_4.Value = txtinfo; 
                        drawnow;
                          
                 else
                     startTime = tic;
                     for frame = 1: numberOfFrames
                          
                     %  if frame < app.NumberOfFEditField.Value
                     %  First_Frame = app.Image2BKG{frame};
                     %  Second_Frame = app.Image2BKG{frame+1}
                      % end
                       baseImageFileName                = char(value{frame});
            	       fullImageFileName = fullfile(inputFolder,baseImageFileName);
                       thisFrame = imread(fullImageFileName);
                       Array(:,:,frame) = thisFrame ;
                       if app.FinishNowCheckBox_4.Value
            			break;
                       end
                        elapsedSeconds = toc(startTime);
                          if app.CheckProcessCheckBox_3.Value
            		     txtinfo = sprintf('\n Precessing ');
                             txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	             txtinfo,frame, numberOfFrames, baseImageFileName, elapsedSeconds);
                             app.Result_4.Value = txtinfo; 
                             drawnow;
                          end
                        
            	       
                     end
                 end
                 app.ArrayOfImages = Array;
                  [rows, columns, frames] = size(app.ArrayOfImages);
                  if app.CheckProcessCheckBox_3.Value
                  txtinfo = sprintf('%sProcessed ',txtinfo);
                  else
                  txtinfo = sprintf('Processed ');   
                  end
                  txtinfo = sprintf('%s\nRows : %d',txtinfo,rows );
                  txtinfo = sprintf('%s\nColumns : %d',txtinfo,columns );
                  txtinfo = sprintf('%s\nFrames : %d',txtinfo,frames );
                  app.Result_4.Value = txtinfo; 
                 
               %  gputest = gpuArray(app.ArrayOfImages);
               %  app.ArrayOfImages = gputest;
                app.VisualizationLamp_4Label.Text = 'Done..';
                app.VisualizationLamp_4.Color = [0 0 1];
                drawnow;
                delete(gcp('nocreate'))
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               %message = sprintf('Fire hazard! \n Consider reducing temperature.');
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
               app.VisualizationLamp_4Label.Text = 'Done..';
                app.VisualizationLamp_4.Color = [0 0 1];
                drawnow;
            end
        end

        % Button pushed function: Create3DARRAYButton_2
        function Create3DARRAYButton_2Pushed(app, event)
             try 
                 app.VisualizationLamp_4Label.Text = 'Processing..';
                 app.VisualizationLamp_4.Color = [1 0 0];
                 drawnow;
                 value = app.BackgroundList.Value; 
                 ListOfImageNames = app.BackgroundList.Items;
                 selectedFiles = app.BackgroundList.Value;
                 numberOfFrames = length(selectedFiles);
                 if numberOfFrames == 0
                      errorMessage = sprintf('Image no selected');
                       f = app.UIFigure;
                        %message = sprintf('Fire hazard! \n Consider reducing temperature.');
                        uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                       selectedFiles = app.BackgroundList.Items;
                       numberOfFrames = length(selectedFiles);
                 end
                 
                
                 cores = str2num(app.CoresNumberDropDown.Value);
                 
                 inputFolder = app.BackgroundFolder;
                 app.FramesFolderUsed = app.FinalFilteredFolder;
                 if cores ~= 1
                         %txt = app.Result_4;
                         parpool('local',cores);
                         startTime = tic
                         parfor frame = 1: numberOfFrames
                           %  
                         %  if frame < app.NumberOfFEditField.Value
                         %  First_Frame = app.Image2BKG{frame};
                         %  Second_Frame = app.Image2BKG{frame+1}
                          % end
                           baseImageFileName                = char(value{frame});
                	   fullImageFileName = fullfile(inputFolder,baseImageFileName);
                           thisFrame = imread(fullImageFileName);
                           Array(:,:,frame) = thisFrame ;
                           %elapsedSeconds = toc(startTime);
                           % txtinfo = sprintf('\n Precessing ');
                           % txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
                	   % txtinfo,frame, numberOfFrames, baseImageFileName, elapsedSeconds);
                            %txt.Value = txtinfo;
                         end
                         elapsedSeconds = toc(startTime);
                        txtinfo = sprintf('\n Precessing ');
                        txtinfo = sprintf('%s\nBuilding Frames...\nOn  of %d: \nElapsed time = %.1f seconds.', ...
            	        txtinfo, numberOfFrames, elapsedSeconds);
                        app.Result_4.Value = txtinfo; 
                        drawnow;
                          
                 else
                     startTime = tic;
                     for frame = 1: numberOfFrames
                          
                     %  if frame < app.NumberOfFEditField.Value
                     %  First_Frame = app.Image2BKG{frame};
                     %  Second_Frame = app.Image2BKG{frame+1}
                      % end
                       baseImageFileName                = char(value{frame});
            	       fullImageFileName = fullfile(inputFolder,baseImageFileName);
                       thisFrame = imread(fullImageFileName);
                       Array(:,:,frame) = thisFrame ;
                       if app.FinishNowCheckBox_4.Value
            			break;
                       end
                        elapsedSeconds = toc(startTime);
                          if app.CheckProcessCheckBox_3.Value
            		     txtinfo = sprintf('\n Precessing ');
                             txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: %s\nElapsed time = %.1f seconds.', ...
            	             txtinfo,frame, numberOfFrames, baseImageFileName, elapsedSeconds);
                             app.Result_4.Value = txtinfo; 
                             drawnow;
                          end
                        
            	       
                     end
                 end
                 app.ArrayOfImages = Array;
                  [rows, columns, frames] = size(app.ArrayOfImages);
                  if app.CheckProcessCheckBox_3.Value
                  txtinfo = sprintf('%sProcessed ',txtinfo);
                  else
                  txtinfo = sprintf('Processed ');   
                  end
                  txtinfo = sprintf('%s\nRows : %d',txtinfo,rows );
                  txtinfo = sprintf('%s\nColumns : %d',txtinfo,columns );
                  txtinfo = sprintf('%s\nFrames : %d',txtinfo,frames );
                  app.Result_4.Value = txtinfo; 
                 
               %  gputest = gpuArray(app.ArrayOfImages);
               %  app.ArrayOfImages = gputest;
                app.VisualizationLamp_4Label.Text = 'Done..';
                app.VisualizationLamp_4.Color = [0 0 1];
                drawnow;
                delete(gcp('nocreate'))
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
               app.VisualizationLamp_4Label.Text = 'Done..';
                app.VisualizationLamp_4.Color = [0 0 1];
                drawnow;
            end
        end

        % Button pushed function: BackGroundMedianOperationButton
        function BackGroundMedianOperationButtonPushed(app, event)
            try
                a = app.ArrayOfImages;
                app.VisualizationLamp_4Label.Text = 'Processing..';
                app.VisualizationLamp_4.Color = [1 0 0];
                drawnow;
                if isempty(app.ArrayOfImages)
                             errorMessage = sprintf('Error in program please create first the array of images to calculate mean or any filter');
                      	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                    return;
                end
                ImportImages = app.FrameListBackground.Items;
	        selectedImportImages = app.FrameListBackground.Value;
	        numberOfImportImages = length(selectedImportImages);
                if numberOfImportImages > 1 
                     inputFolder = app.FinalFilteredFolder;
                     [~,name,~] = fileparts(app.FrameListBackground.Value{1});
                     baseImageFileName  = char(name);
                     %index = find(isletter(baseImageFileName), 1);
                     name  = regexprep(baseImageFileName, '\d' , '' );            
                end
         
                NewImages = app.BackgroundList.Items;
	        selectedNewImages = app.BackgroundList.Value;
	        numberOfNewImages = length(selectedNewImages);
                if numberOfNewImages> 1 
                     inputFolder = app.BackgroundFolder;
                     [~,name,~] = fileparts(app.BackgroundList.Value{1});
                     baseImageFileName  = char(name);
                     %index = find(isletter(baseImageFileName), 1);
                     name  = regexprep(baseImageFileName, '\d' , '' );
                end
         
                 ListOfBackgroundModels = app.BackgroundModellist.Items;
	        %selectedFiles = app.BackgroundModellist.Value;
	        NumberOfBackgrounds = length(ListOfBackgroundModels);
                if NumberOfBackgrounds == 0 
                  app.BackgroundModel{1} = uint8(median(app.ArrayOfImages,3));   
                  a = app.ArrayOfImages;
                end
                if NumberOfBackgrounds >= 1
                  app.BackgroundModel{NumberOfBackgrounds+1} = uint8(median(app.ArrayOfImages,3));  
                  a = app.ArrayOfImages;
                end
                
                baseFileName = char(app.OutputBaseFileNameTextArea_4.Value);
                if length(baseFileName) < 1
                		baseFileName = 'Median Blackground ';
                end
                if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                end
                 %%output base file name in list
                 outputBaseFileName = [baseFileName, name];
                 
                 
                 [Number,idx] = ismember(outputBaseFileName, app.BackgroundModellist.Items); 
                 if  Number == 0
                     app.BackgroundModellist.Items(NumberOfBackgrounds+1) = {outputBaseFileName};
                 else
                     numberofrepetition = sum(Number());
                     outputBaseFileName= [outputBaseFileName, num2str(numberofrepetition)];
                     app.BackgroundModellist.Items(NumberOfBackgrounds+1) = {outputBaseFileName};
                 end


                 %read image
                 %sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                 %imwrite(thisFrame, outputFullFileName, 'tif');
                 Display_Image(app, app.BackgroundModel{NumberOfBackgrounds+1},outputBaseFileName,app.Image_4);
                 
                 [rows, columns, numberOfColorChannels] = size(app.BackgroundModel{NumberOfBackgrounds+1});
                	% Get the file date
                	%fileInfo = dir(inputFolder);
                	txtInfo = sprintf('%s\n\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                		outputBaseFileName, rows, columns, numberOfColorChannels);
                	% Tell user the type of image it is.
                	if numberOfColorChannels == 3
                		%colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a true color, RGB image.', txtInfo);
                	elseif numberOfColorChannels == 1 
                		%colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a gray scale image', txtInfo);
                	end
                	% Show the file time and date.
                	txtInfo = sprintf('%s\n\n OF %s', txtInfo, inputFolder);
                	app.Result_4.Value = txtInfo;
                 
                
                app.VisualizationLamp_4Label.Text = 'Done..';
                app.VisualizationLamp_4.Color = [0 0 1];
                drawnow;
                %app.ArrayOfImages = {};
                %a = app.ArrayOfImages;
            catch ME
                  errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
            
        end

        % Value changed function: BackgroundModellist
        function BackgroundModellistValueChanged(app, event)
            value = app.BackgroundModellist.Value;
            try
                ListOfImageNames = app.BackgroundModellist.Items;
	        selectedFiles = app.BackgroundModellist.Value;
	        numberOfFrames = length(selectedFiles);


            	if isempty(selectedFiles)
            		
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedFiles) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  \n.',txtinfo,char(value(1)), numberOfFrames );
                        app.Result_4.Value = txtinfo; 
            		return;
            	end
               % [~,idx] = ismember(value,ListOfImageNames);      
               % Display_Image(app, app.BackgroundModel{idx},char(app.BackgroundModellist.Items(idx)),app.Image_4);
                
                [~,idx] = ismember(value,ListOfImageNames);      
                Display_Image(app, app.BackgroundModel{idx},char(app.BackgroundModellist.Items(idx)),app.Image_4);
            	txtinfo = sprintf('  Background Model ');
                txtinfo = sprintf('%s\nName of  model %s:',txtinfo,char(app.BackgroundModellist.Items(idx)) );
                app.Result_4.Value = txtinfo; 
                
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: SelectOutputFolderButton_4
        function SelectOutputFolderButton_4Pushed(app, event)
             try 
                returnValue = uigetdir(app.OutputBackgroundFolder,'Select output folder');
            	% returnValue will be 0 (a double) if they click cancel.
            	% returnValue will be the path (a string) if they clicked OK.
             if returnValue ~= 0
                 % Assign the value if they didn't click cancel.
            	 app.OutputBackgroundFolder = returnValue;
                 app.OutputFolder_3.Value = app.OutputBackgroundFolder;   
             end         
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end           
        end

        % Button pushed function: SelectAllSamplesButton_3
        function SelectAllSamplesButton_3Pushed(app, event)
            try
                %app.FrameAfterFilter.Items = {};
            	% Find out button caption and take appropriate action.
            	ButtonCaption = app.SelectAllSamplesButton_3.Text;
            	if strcmp(ButtonCaption, 'Select All Samples') == 1
            		% Select all items in the listbox.
            		% Need to find out how many items are in the listbox (both selected and
            		% unselected).  It's quirky and inefficient but it's the only way I
            		% know how to do it.
            		% First get the whole damn listbox text into a cell array.
            		caListboxString =  app.BackgroundModellist.Items;
            		NumberOfItems = length(caListboxString);    % Get length of that cell array.
            		AllIndices=1:NumberOfItems; % Make a vector of all indices.
            		% Select all indices.
                        app.BackgroundModellist.Value = caListboxString ;                      
            		% Finally, change caption to say "Select None"
            		app.SelectAllSamplesButton_3.Text = 'Select None';
            		% It scrolls to the bottom of the list.  Use the following line
            		% if you want the first item at the top of the list.       
            		%set(app.List.set, 'ListboxTop', 1);
            	else
            		% Select none of the items in the listbox.
            		app.BackgroundModellist.Value = {};
            		% Change caption to say Select All
            		app.SelectAllSamplesButton_3.Text = 'Select All Samples';
            	end    	
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: DeleteFrameButton_3
        function DeleteFrameButton_3Pushed(app, event)
            try
                [~,idx] = ismember(app.BackgroundModellist.Value,app.BackgroundModellist.Items);
               app.BackgroundModellist.Items(idx) = [];
               %a=app.BackgroundModel;
               app.BackgroundModel(idx) = [];
               %a=app.BackgroundModel;
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: SaveBackgroundImageButton
        function SaveBackgroundImageButtonPushed(app, event)
             try 
                 app.VisualizationLamp_4Label.Text = 'Processing..';
                 app.VisualizationLamp_4.Color = [1 0 0];
                 drawnow;
                 
                 %NameFolder = [];


                 promptMessage = sprintf('Do you want to save the frames filtered  to individual disk files?');
                 button = questdlg(promptMessage,'Save filtered frames?', 'Yes','No','Yes');
                 if strcmp(button,'Yes')
                    %[~,name,~] = fileparts(app.VideoListBox.Value);
                    baseFolderName = char(app.OutputBaseFileNameTextArea_4.Value);
                    if length(baseFolderName) < 1
            		baseFolderName = 'Background Model';
                    end
                    outputfolder = sprintf('%s/%s',app.OutputBackgroundFolder,baseFolderName);
                    
                    if  exist(outputfolder,'dir')
                         errorMessage = sprintf('A folder already exists in the directory please choose a different name or delete the old version folder');
            	         f = app.UIFigure;         
                         uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                        return;
                    end
                    if ~exist(outputfolder,'dir') 
                        
                        mkdir(outputfolder);
                                                
                        SaveBackgroundModels(app,outputfolder);
                        
                        app.FinalBackgroundFolder = outputfolder;
                        
                        message = sprintf('\nYour images filteredes  is in file:\n%s\n\nClick OK to play it', outputfolder);
                	titleBarCaption = 'Open it?';
                	buttonText = questdlg(message, titleBarCaption, 'OK, Open It', 'Skip opeing', 'OK, open It');
                	if contains(buttonText, 'OK', 'IgnoreCase', true)
                		% Play the movie in the default program (like Windows Media Player).
                		% I think winopen() only works for the Windows operating system.
                		if contains(computer, 'PCWIN')
                			% It's a windows computer.
                			winopen(outputfolder);
                		else
                			% It's a not a Windows computer.
                			disp('iMac');
                		end
                	end
                    end
                  app.VisualizationLamp_4Label.Text = 'Done';
                  app.VisualizationLamp_4.Color = [0 0 1];
                  drawnow;
                end   
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            try
                app.FrameListBackground.Items = {};
                app.FinalFilteredFolder = char(app.DirectoryFolderFramesToBackground.Value);
                LoadImageBackgroundList(app);
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Button pushed function: BackGroundMeanOperationButton
        function BackGroundMeanOperationButtonPushed(app, event)
            try
                a = app.ArrayOfImages;
                app.VisualizationLamp_4Label.Text = 'Processing..';
                app.VisualizationLamp_4.Color = [1 0 0];
                drawnow;
                if isempty(app.ArrayOfImages)
                             errorMessage = sprintf('Error in program please create first the array of images to calculate mean or any filter');
                      	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                    return;
                end
                ImportImages = app.FrameListBackground.Items;
	        selectedImportImages = app.FrameListBackground.Value;
	        numberOfImportImages = length(selectedImportImages);
                if numberOfImportImages > 1 
                     inputFolder = app.FinalFilteredFolder;
                     [~,name,~] = fileparts(app.FrameListBackground.Value{1});
                     baseImageFileName  = char(name);
                     %index = find(isletter(baseImageFileName), 1);
                     name  = regexprep(baseImageFileName, '\d' , '' );            
                end
         
                NewImages = app.BackgroundList.Items;
	        selectedNewImages = app.BackgroundList.Value;
	        numberOfNewImages = length(selectedNewImages);
                if numberOfNewImages> 1 
                     inputFolder = app.BackgroundFolder;
                     [~,name,~] = fileparts(app.BackgroundList.Value{1});
                     baseImageFileName  = char(name);
                     %index = find(isletter(baseImageFileName), 1);
                     name  = regexprep(baseImageFileName, '\d' , '' );
                end
         
                 ListOfBackgroundModels = app.BackgroundModellist.Items;
	        %selectedFiles = app.BackgroundModellist.Value;
	        NumberOfBackgrounds = length(ListOfBackgroundModels);
                if NumberOfBackgrounds == 0 
                  app.BackgroundModel{1} = uint8(mean(app.ArrayOfImages,3));   
                  a = app.ArrayOfImages;
                end
                if NumberOfBackgrounds >= 1
                  app.BackgroundModel{NumberOfBackgrounds+1} = uint8(mean(app.ArrayOfImages,3));  
                  a = app.ArrayOfImages;
                end
                
                baseFileName = char(app.OutputBaseFileNameTextArea_4.Value);
                if length(baseFileName) < 1
                		baseFileName = 'Mean Blackground ';
                end
                if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                end
                 %%output base file name in list
                 outputBaseFileName = [baseFileName, name];
                 
                 
                 [Number,idx] = ismember(outputBaseFileName, app.BackgroundModellist.Items); 
                 if  Number == 0
                     app.BackgroundModellist.Items(NumberOfBackgrounds+1) = {outputBaseFileName};
                 else
                     numberofrepetition = sum(Number());
                     outputBaseFileName= [outputBaseFileName, num2str(numberofrepetition)];
                     app.BackgroundModellist.Items(NumberOfBackgrounds+1) = {outputBaseFileName};
                 end


                 %read image
                 %sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                 %imwrite(thisFrame, outputFullFileName, 'tif');
                 Display_Image(app, app.BackgroundModel{NumberOfBackgrounds+1},outputBaseFileName,app.Image_4);
                 
                 [rows, columns, numberOfColorChannels] = size(app.BackgroundModel{NumberOfBackgrounds+1});
                	% Get the file date
                	%fileInfo = dir(inputFolder);
                	txtInfo = sprintf('%s\n\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                		outputBaseFileName, rows, columns, numberOfColorChannels);
                	% Tell user the type of image it is.
                	if numberOfColorChannels == 3
                		%colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a true color, RGB image.', txtInfo);
                	elseif numberOfColorChannels == 1 
                		%colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a gray scale image', txtInfo);
                	end
                	% Show the file time and date.
                	txtInfo = sprintf('%s\n\n OF %s', txtInfo, inputFolder);
                	app.Result_4.Value = txtInfo;
                 
                
                app.VisualizationLamp_4Label.Text = 'Done..';
                app.VisualizationLamp_4.Color = [0 0 1];
                drawnow;
                %app.ArrayOfImages = {};
                %a = app.ArrayOfImages;
            catch ME
                  errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
            
        end

        % Button pushed function: BackGroundModeOperationButton
        function BackGroundModeOperationButtonPushed(app, event)
            try
                a = app.ArrayOfImages;
                app.VisualizationLamp_4Label.Text = 'Processing..';
                app.VisualizationLamp_4.Color = [1 0 0];
                drawnow;
                if isempty(app.ArrayOfImages)
                             errorMessage = sprintf('Error in program please create first the array of images to calculate mean or any filter');
                      	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                    return;
                end
                ImportImages = app.FrameListBackground.Items;
	        selectedImportImages = app.FrameListBackground.Value;
	        numberOfImportImages = length(selectedImportImages);
                if numberOfImportImages > 1 
                     inputFolder = app.FinalFilteredFolder;
                     [~,name,~] = fileparts(app.FrameListBackground.Value{1});
                     baseImageFileName  = char(name);
                     %index = find(isletter(baseImageFileName), 1);
                     name  = regexprep(baseImageFileName, '\d' , '' );            
                end
         
                NewImages = app.BackgroundList.Items;
	        selectedNewImages = app.BackgroundList.Value;
	        numberOfNewImages = length(selectedNewImages);
                if numberOfNewImages> 1 
                     inputFolder = app.BackgroundFolder;
                     [~,name,~] = fileparts(app.BackgroundList.Value{1});
                     baseImageFileName  = char(name);
                     %index = find(isletter(baseImageFileName), 1);
                     name  = regexprep(baseImageFileName, '\d' , '' );
                end
         
                 ListOfBackgroundModels = app.BackgroundModellist.Items;
	        %selectedFiles = app.BackgroundModellist.Value;
	        NumberOfBackgrounds = length(ListOfBackgroundModels);
                if NumberOfBackgrounds == 0 
                  app.BackgroundModel{1} = uint8(mode(app.ArrayOfImages,3));   
                  a = app.ArrayOfImages;
                end
                if NumberOfBackgrounds >= 1
                  app.BackgroundModel{NumberOfBackgrounds+1} = uint8(mode(app.ArrayOfImages,3));  
                  a = app.ArrayOfImages;
                end
                
                baseFileName = char(app.OutputBaseFileNameTextArea_4.Value);
                if length(baseFileName) < 1
                		baseFileName = 'Mode Blackground ';
                end
                if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                end
                 %%output base file name in list
                 outputBaseFileName = [baseFileName, name];
                 
                 
                 [Number,idx] = ismember(outputBaseFileName, app.BackgroundModellist.Items); 
                 if  Number == 0
                     app.BackgroundModellist.Items(NumberOfBackgrounds+1) = {outputBaseFileName};
                 else
                     numberofrepetition = sum(Number());
                     outputBaseFileName= [outputBaseFileName, num2str(numberofrepetition)];
                     app.BackgroundModellist.Items(NumberOfBackgrounds+1) = {outputBaseFileName};
                 end


                 %read image
                 %sourceBaseFileName = char(app.FrameListToFilter.Value{frame});
                 %imwrite(thisFrame, outputFullFileName, 'tif');
                 Display_Image(app, app.BackgroundModel{NumberOfBackgrounds+1},outputBaseFileName,app.Image_4);
                 
                 [rows, columns, numberOfColorChannels] = size(app.BackgroundModel{NumberOfBackgrounds+1});
                	% Get the file date
                	%fileInfo = dir(inputFolder);
                	txtInfo = sprintf('%s\n\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                		outputBaseFileName, rows, columns, numberOfColorChannels);
                	% Tell user the type of image it is.
                	if numberOfColorChannels == 3
                		%colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a true color, RGB image.', txtInfo);
                	elseif numberOfColorChannels == 1 
                		%colorbar 'off';  % get rid of colorbar.
                		txtInfo = sprintf('%s\nThis is a gray scale image', txtInfo);
                	end
                	% Show the file time and date.
                	txtInfo = sprintf('%s\n\n OF %s', txtInfo, inputFolder);
                	app.Result_4.Value = txtInfo;
                 
                
                app.VisualizationLamp_4Label.Text = 'Done..';
                app.VisualizationLamp_4.Color = [0 0 1];
                drawnow;
                %app.ArrayOfImages = {};
                %a = app.ArrayOfImages;
            catch ME
                  errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: EXPORTTOANALIZEButton
        function EXPORTTOANALIZEButtonPushed(app, event)
             try
                app.BackgroundList_2.Items = {};
                app.ImagesToAnalizeList.Items = {};
                
                app.DirectoryFolderBackground.Value = app.FinalBackgroundFolder;
                app.DirectoryFolderFrames_3.Value =  app.FramesFolderUsed; 
                %app.ImageFolder = app.OutputFinalFramesFolder;
                % Load list of images in the image folder.
            	%LoadImageBackgroundList(app);
                LoadBackgroundToAnalyzeList(app);
                LoadImageToAnalyzeList(app);
            	% Select none of the items in the listbox.
            	app.BackgroundList_2.Value = {};   
                app.ImagesToAnalizeList.Value = {};   
                app.TabGroup.SelectedTab = app.ANALYZETab;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Value changed function: SelectInputImagesFolderButton
        function SelectInputImagesFolderButtonValueChanged(app, event)
            value = app.SelectInputImagesFolderButton.Value;
            try
                FramesDirectory = uigetdir(app.FramesFolderUsed,'Select Folder');
                % FramesDirectory will be 0 (a double) if they click cancel.
	        % FramesDirectory will be the path (a string) if they clicked OK.
                if FramesDirectory ~= 0
                    % Assign the value if they didn't click cancel.
                    app.FramesFolderUsed = FramesDirectory;
	   	    LoadImageToAnalyzeList(app);
                    app.DirectoryFolderFrames_3.Value = app.FramesFolderUsed;
                    % Save the image folder in our ini file.
    		    SaveUserSettings(app);
                end
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: SelectInputBackgroundFolderButton
        function SelectInputBackgroundFolderButtonValueChanged(app, event)
            value = app.SelectInputBackgroundFolderButton.Value;
            try
                FramesDirectory = uigetdir(app.FinalBackgroundFolder,'Select Folder');
                % FramesDirectory will be 0 (a double) if they click cancel.
	        % FramesDirectory will be the path (a string) if they clicked OK.
                if FramesDirectory ~= 0
                    % Assign the value if they didn't click cancel.
                    app.FinalBackgroundFolder = FramesDirectory;
	   	    LoadBackgroundToAnalyzeList(app);
                    app.DirectoryFolderBackground.Value = app.FinalBackgroundFolder;
                    % Save the image folder in our ini file.
    		    SaveUserSettings(app);
                end
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: DeleteFrameButton_4
        function DeleteFrameButton_4Pushed(app, event)
            try
                [~,idx] = ismember(app.ImagesToAnalizeList.Value,app.ImagesToAnalizeList.Items);
               app.ImagesToAnalizeList.Items(idx) = [];
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: SelectAllSamplesButton_4
        function SelectAllSamplesButton_4Pushed(app, event)
            try
                %app.FrameAfterFilter.Items = {};
            	% Find out button caption and take appropriate action.
            	ButtonCaption = app.SelectAllSamplesButton_4.Text;
            	if strcmp(ButtonCaption, 'Select All Samples') == 1
            		% Select all items in the listbox.
            		% Need to find out how many items are in the listbox (both selected and
            		% unselected).  It's quirky and inefficient but it's the only way I
            		% know how to do it.
            		% First get the whole damn listbox text into a cell array.
            		caListboxString =  app.ImagesToAnalizeList.Items;
            		NumberOfItems = length(caListboxString);    % Get length of that cell array.
            		AllIndices=1:NumberOfItems; % Make a vector of all indices.
            		% Select all indices.
                        app.ImagesToAnalizeList.Value = caListboxString ; 
                        
            		% Finally, change caption to say "Select None"
            		app.SelectAllSamplesButton_4.Text = 'Select None';
            		% It scrolls to the bottom of the list.  Use the following line
            		% if you want the first item at the top of the list.
                        
            		%set(app.List.set, 'ListboxTop', 1);
            	else
            		% Select none of the items in the listbox.
            		app.ImagesToAnalizeList.Value = {};
            		% Change caption to say Select All
            		app.SelectAllSamplesButton_4.Text = 'Select All Samples';
            	end
            	% Update the number of images in the Analyze button caption.
            	%UpdateAnalyzeButtonCaption(app);
            	%SpinnerValueChanged(app);
            	
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: ImagesToAnalizeList
        function ImagesToAnalizeListValueChanged(app, event)
            value = app.ImagesToAnalizeList.Value;
            try
                selectedListboxItem = value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  %s\n.',txtinfo,char(value(1)), length(selectedListboxItem), app.FramesFolderUsed );
                        app.Result_5.Value = txtinfo; 
            		return;
            	end
                % If only one is selected, display it.
                %app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = char(value);
        	fullImageFileName = fullfile(app.FramesFolderUsed,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
                                thisFrame = imread(fullImageFileName);
            			Display_Image(app,thisFrame,baseImageFileName,app.Image_5);
                           
                                [rows, columns, numberOfColorChannels] = size(thisFrame);
                            	% Get the file date
                            	fileInfo = dir(fullImageFileName);
                            	txtInfo = sprintf('%s\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                            	[baseImageFileName], rows, columns, numberOfColorChannels);
                                % Show the file time and date.
                            	txtInfo = sprintf('%s\n%s', txtInfo, fileInfo.date);
                            	app.Result_5.Value = txtInfo;
               
                                %PlotImageHistogram2(app,thisFrame);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(thisFrame)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
           
           
        end

        % Value changed function: BackgroundList_2
        function BackgroundList_2ValueChanged(app, event)
            value = app.BackgroundList_2.Value;
            try
                selectedListboxItem = value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  %s\n.',txtinfo,char(value(1)), length(selectedListboxItem), app.FinalBackgroundFolder );
                        app.Result_5.Value = txtinfo; 
            		return;
            	end
                % If only one is selected, display it.
                %app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = char(value);
        	fullImageFileName = fullfile(app.FinalBackgroundFolder,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
                                thisFrame = imread(fullImageFileName);
            			Display_Image(app,thisFrame,baseImageFileName,app.Image_5);
                           
                                [rows, columns, numberOfColorChannels] = size(thisFrame);
                            	% Get the file date
                            	fileInfo = dir(fullImageFileName);
                            	txtInfo = sprintf('%s\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                            	[baseImageFileName], rows, columns, numberOfColorChannels);
                                % Show the file time and date.
                            	txtInfo = sprintf('%s\n%s', txtInfo, fileInfo.date);
                            	app.Result_5.Value = txtInfo;
               
                                %PlotImageHistogram2(app,thisFrame);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(thisFrame)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: BkGStaticProcessFrameButton
        function BkGStaticProcessFrameButtonValueChanged(app, event)
            value = app.BkGStaticProcessFrameButton.Value;
            try 
                 app.VisualizationLamp_5Label.Text = 'Processing..';
                 app.VisualizationLamp_5.Color = [1 0 0];
                 drawnow;
                 ListOfImageNames = app.ImagesToAnalizeList.Items;
	         selectedFiles = app.ImagesToAnalizeList.Value;
	         numberOfFrames = length(selectedFiles);
                      % If none are selected, select them all
                 if numberOfFrames == 0      
                   	errorMessage = sprintf('Image to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 
                 
                 ListOfBackgroundModels = app.BackgroundList_2.Items;
	         selectedBackgroundModels = app.BackgroundList_2.Value;
	         numberOfBackgroundModels = length(selectedBackgroundModels);
                      % If none are selected, select them all
                 if numberOfBackgroundModels == 0      
                   	errorMessage = sprintf('Background to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 inputImageFolder = app.FramesFolderUsed;
                 inputBackgroundFolder = app.FinalBackgroundFolder;
                 
                 baseImageFileName                = char(selectedFiles);
        	 fullImageFileName = fullfile(inputImageFolder,baseImageFileName);	% Prepend folder.
                 ImageToProcess = imread(fullImageFileName);
                    %%%%%using GPU
                        if app.GPUCheckBox_3.Value
                            ImageToProcess = gpuArray(ImageToProcess); 
                        end
                 
                 baseBackgroundFileName                = char(selectedBackgroundModels);
        	 fullBackgroundFileName = fullfile(inputBackgroundFolder,baseBackgroundFileName);	% Prepend folder.
                 BackgroundModelToProcess = imread(fullBackgroundFileName);
                 
                 [ForeGroundImage, ImageDilated, SmoothPixel, ResultPerimeter, Segmentation, Perimeter, RelativeConcentration] = ProcessImageBackground(app,ImageToProcess, BackgroundModelToProcess);
                 
                 if app.CheckProcessCheckBox_4.Value
                     %tab 1
                     %tab 1
                      if app.TabGroup2.SelectedTab == app.Tab_2
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_5);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_5);
                                 end
                                 CharRelativeConcentration = num2str(RelativeConcentration);
                                 app.Result_5.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    %title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                     %   app.Image_22.XLabel.String =('Frame');
                                      %  app.Image_22.YLabel.String = ('Intensity Concentration %');
                                       % legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                      end
                     if app.TabGroup2.SelectedTab == app.Tab2_3
                         %TAB2
                         Display_Image(app, ImageToProcess,baseImageFileName,app.Image_6);
                         Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_7);
                         Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_8);
                         Display_Image(app, ImageDilated,char(['Image Dilated',baseImageFileName]),app.Image_9);
                         Display_Image(app, SmoothPixel,char(['Smooth Pixel Image',baseImageFileName]),app.Image_10);
                         Display_Image(app, ResultPerimeter,char(['Image Result Perimeter',baseImageFileName]),app.Image_11);
                     end
  
                     if app.TabGroup2.SelectedTab == app.Tab3    
                         %TAB 3
                         Display_Image(app, ImageToProcess,baseImageFileName,app.Image_12);                 
                         if app.SegmentationOrPerimeterCheckBox.Value
                             Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_13);
                         else
                             Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_13);
                         end
                         
                         if isempty(app.PlotADropDown.Value)
                        
                         else
                            Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotADropDown,app.Image_12);  
                         end
                         if isempty(app.PlotBDropDown.Value)
                        
                         else
                            Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotBDropDown,app.Image_13);  
                         end
                     end
                     
                     if app.TabGroup2.SelectedTab == app.Tab4
                         %TAB 4
                         Display_Image(app, ImageToProcess,baseImageFileName,app.Image_14);
                         Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_15);
                         Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_16);
                         Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_17);
                     end
                     
                     if app.TabGroup2.SelectedTab == app.Tab5
                         %TAB 5
                         Display_Image(app, ImageToProcess,baseImageFileName,app.Image_18);
                         if app.SegmentationOrPerimeterCheckBox.Value
                             Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_20);
                         else
                             Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_20);
                         end
                         %RelativeConcentration = num2str(RelativeConcentration);
                         app.PercentEditField.Value = RelativeConcentration;
                         Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_19); 
                     end
                     
                     %tab 6
                     if app.TabGroup2.SelectedTab == app.Tab6
                         if app.SegmentationOrPerimeterCheckBox.Value
                             Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                         else
                             Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                         end
                         CharRelativeConcentration = num2str(RelativeConcentration);
                         app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                         Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                     end
                     
                     %tab7
                     if app.TabGroup2.SelectedTab == app.Tab7
                         if app.SegmentationOrPerimeterCheckBox.Value
                             Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                         else
                             Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                         end
                     end
                     
                       %tab8
                     if app.TabGroup2.SelectedTab == app.Tab8
                         
                          ImageProcessedResult = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField.Value);


                             Display_Image(app, ImageProcessedResult,char(['NEW IMAGE ',baseImageFileName]),app.Image_24);
                         
                            
                     end
                     
                     
                 else
                     %tab 6
                     if app.SegmentationOrPerimeterCheckBox.Value
                         Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                     else
                         Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                     end
                     CharRelativeConcentration = num2str(RelativeConcentration);
                     app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                     Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                     
                     %tab7
                     if app.SegmentationOrPerimeterCheckBox.Value
                         Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                     else
                         Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                     end
    
                 end
                 
                 if RelativeConcentration > app.ThresholdPlotEditField.Value
                     app.VisualizationLamp_6.Color = [1 0 0];
                     app.VisualizationLamp_6Label.Text = 'Alert';
                 end
                 app.VisualizationLamp_5Label.Text = 'Done..';
                 app.VisualizationLamp_5.Color = [0 0 1];
                 drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: BkGStaticProcessEntireListButton
        function BkGStaticProcessEntireListButtonValueChanged(app, event)
            value = app.BkGStaticProcessEntireListButton.Value;  
            try 
                 app.VisualizationLamp_5Label.Text = 'Processing..';
                 app.VisualizationLamp_5.Color = [1 0 0];
                 app.VisualizationLamp_6.Color = [1 1 0];
                 app.VisualizationLamp_6Label.Text = 'Processing..';
                 drawnow;
                 ListOfImageNames = app.ImagesToAnalizeList.Items;
	         selectedFiles = app.ImagesToAnalizeList.Value;
	         numberOfFrames = length(ListOfImageNames);
                      % If none are selected, select them all
                 if numberOfFrames == 0      
                   	errorMessage = sprintf('Image to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 
                 
                 ListOfBackgroundModels = app.BackgroundList_2.Items;
	         selectedBackgroundModels = app.BackgroundList_2.Value;
	         numberOfBackgroundModels = length(selectedBackgroundModels);
                      % If none are selected, select them all
                 if numberOfBackgroundModels == 0      
                   	errorMessage = sprintf('Background to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 inputImageFolder = app.FramesFolderUsed;
                 inputBackgroundFolder = app.FinalBackgroundFolder;
                 startTime = tic;
                 for frames=1 : numberOfFrames
                           if app.FinishNowCheckBox_5.Value
            			break;
                           end
                          %startTime = tic;
                         baseImageFileName                = char(app.ImagesToAnalizeList.Items{frames});
                	 fullImageFileName = fullfile(inputImageFolder,baseImageFileName);	% Prepend folder.
                         ImageToProcess = imread(fullImageFileName);
                             %%%%%using GPU
                            if app.GPUCheckBox_3.Value
                                ImageToProcess = gpuArray(ImageToProcess); 
                            end
                         baseBackgroundFileName                = char(selectedBackgroundModels);
                	 fullBackgroundFileName = fullfile(inputBackgroundFolder,baseBackgroundFileName);	% Prepend folder.
                         BackgroundModelToProcess = imread(fullBackgroundFileName);
                            if app.GPUCheckBox_3.Value
                                BackgroundModelToProcess = gpuArray(BackgroundModelToProcess); 
                            end
                         
                         [ForeGroundImage, ImageDilated, SmoothPixel, ResultPerimeter, Segmentation, Perimeter, RelativeConcentration] = ProcessImageBackground(app,ImageToProcess, BackgroundModelToProcess);
                          xValue(frames) = frames;
                          yValue(frames) = RelativeConcentration;
                          yValueThreshold(frames) = app.ThresholdPlotEditField.Value;
                          
                          if RelativeConcentration > app.ThresholdPlotEditField.Value
                             app.VisualizationLamp_6.Color = [1 0 0];
                             app.VisualizationLamp_6Label.Text = 'Alert';
                         else
                             app.VisualizationLamp_6.Color = [0 1 0];
                             app.VisualizationLamp_6Label.Text = 'Normal';
                         end
                          
                         if app.CheckProcessCheckBox_4.Value
                             if app.TabGroup2.SelectedTab == app.Tab_2
                                 %tab 1
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_5);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_5);
                                 end
                                 %CharRelativeConcentration = num2str(RelativeConcentration);
                                 %app.Result_5.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    %title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                     %   app.Image_22.XLabel.String =('Frame');
                                      %  app.Image_22.YLabel.String = ('Intensity Concentration %');
                                       % legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab2_3
                                 %TAB2
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_6);
                                 Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_7);
                                 Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_8);
                                 Display_Image(app, ImageDilated,char(['Image Dilated',baseImageFileName]),app.Image_9);
                                 Display_Image(app, SmoothPixel,char(['Smooth Pixel Image',baseImageFileName]),app.Image_10);
                                 Display_Image(app, ResultPerimeter,char(['Image Result Perimeter',baseImageFileName]),app.Image_11);
                                 %drawnow;
                                        %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab3
                                 %TAB 3
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_12);                 
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_13);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_13);
                                 end
                                 
                                 if isempty(app.PlotADropDown.Value)
                                
                                 else
                                    Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotADropDown,app.Image_12);  
                                 end
                                 if isempty(app.PlotBDropDown.Value)
                                
                                 else
                                    Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotBDropDown,app.Image_13);  
                                 end
                                 %drawnow;
                                            %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab4                            
                                 %TAB 4
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_14);
                                 Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_15);
                                 Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_16);
                                 Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_17);
                                 %drawnow;
                                            %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab5
                                     %TAB 5
                                     Display_Image(app, ImageToProcess,baseImageFileName,app.Image_18);
                                     if app.SegmentationOrPerimeterCheckBox.Value
                                         Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_20);
                                     else
                                         Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_20);
                                     end
                                     %RelativeConcentration = num2str(RelativeConcentration);
                                     app.PercentEditField.Value = RelativeConcentration;
                                     %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_19);
                                     
                                        %hold on;
                                        %xValue(frames) = frames;
                                        %yValue(frames) = RelativeConcentration;
                                        %yValueThreshold(frames) = app.ThresholdPlotEditField.Value;
                                        plot(app.Image_19,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                        title( app.Image_19, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_19.XLabel.String =('Frame');
                                        app.Image_19.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_19, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                                        %hold off;
                             end
                                 
                             
                             if  app.TabGroup2.SelectedTab == app.Tab6
                                 %tab 6
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                                 end
                                 CharRelativeConcentration = num2str(RelativeConcentration);
                                 app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %hold on;
                                   
                                    plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                    %plot(app.Image_22,);
                                    title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_22.XLabel.String =('Frame');
                                        app.Image_22.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                                    %hold off;
                             end
                              
                              if  app.TabGroup2.SelectedTab == app.Tab7
                                 %tab7
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                                 end
                                 %drawnow;
                              end

                                 %tab8
                                 if app.TabGroup2.SelectedTab == app.Tab8       
                                      ImageProcessedResult = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField.Value); 
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             ImageProcessedResult = medfilt2(ImageProcessedResult,[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            ImageProcessedResult = imadjust(ImageProcessedResult); 
                                        end
                                      Display_Image(app, ImageProcessedResult,char(['Frame to PIV ',baseImageFileName]),app.Image_24);
                                 end
                                 
                                 %tab9
                                 if app.TabGroup2.SelectedTab == app.Tab9       
                                      ImageProcessedResult = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField.Value);         
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             ImageProcessedResult = medfilt2(ImageProcessedResult,[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            ImageProcessedResult = imadjust(ImageProcessedResult); 
                                        end
                                      
                                      Display_Image(app, ImageProcessedResult,char(['Frame TO PIV ',baseImageFileName]),app.Image_25);
                                      
                                       elapsedSeconds = toc(startTime);
                                        txtinfo = sprintf('\n Processing ');
                                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: \nElapsed time = %.1f seconds.', ...
                        	        txtinfo,frames, numberOfFrames, elapsedSeconds);
                                        app.Result_7.Value = txtinfo;  
                                        drawnow;   
                                 end


                              drawnow;
                         else
                             if app.TabGroup2.SelectedTab == app.Tab_2
                                 %tab 1
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_5);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_5);
                                 end
                                % CharRelativeConcentration = num2str(RelativeConcentration);
                                % app.Result_5.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    %title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                     %   app.Image_22.XLabel.String =('Frame');
                                      %  app.Image_22.YLabel.String = ('Intensity Concentration %');
                                       % legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                             if  app.TabGroup2.SelectedTab == app.Tab6
                                 %tab 6
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                                 end
                                 CharRelativeConcentration = num2str(RelativeConcentration);
                                 app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_22.XLabel.String =('Frame');
                                        app.Image_22.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                              
                              if  app.TabGroup2.SelectedTab == app.Tab7
                                 %tab7
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                                 end
                                    %drawnow;
                              end
                              drawnow;
                         end
                         
                         %%NEW IMAGE FOR PIV
                         app.FramesToPIV{frames} = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage, app.HighGraylimitEditField.Value);   
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             app.FramesToPIV{frames} = medfilt2(app.FramesToPIV{frames},[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            app.FramesToPIV{frames} = imadjust(app.FramesToPIV{frames}); 
                                        end
                         
                         %%FILL LIST 
                         baseFileName = char(app.OutputBaseFileNameTextArea_6.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Frames to PIV ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.ImagesToAnalizeList.Items{frames}];
                        app.FramesToPIVList.Items(frames) = {outputBaseFileName};
                        sourceBaseFileName = char(app.ImagesToAnalizeList.Items{frames});
                        %sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        %Image_Frame = app.FilteredImage{frame};
                         
                         %drawnow;
                 end
                 
                 app.VisualizationLamp_5Label.Text = 'Done..';
                 app.VisualizationLamp_5.Color = [0 0 1];
                 drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: CheckCameraButton
        function CheckCameraButtonPushed(app, event)
            try      
                    f=app.UIFigure;
                    progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
                    %pause(0.1);
                    imaqreset;
                    infom = imaqhwinfo;
                    for k = 1:length(infom.InstalledAdaptors)
                        info = imaqhwinfo(infom.InstalledAdaptors{k});
                        if ~isempty(info.DeviceIDs)
                            Installedadaptor = infom.InstalledAdaptors{k};
                            break;
                        end
                    end
                    
                   if ~exist('Installedadaptor', 'var')
                        h = ('Video camera not connected !!! Warning !!replace');
                        app.StatusCamera.Value = h;
                        progressdlg.delete();
                        return;
                   else
                       h = ('Video camera  connected !!! ');
                       app.StatusCamera.Value = h;
                   end
                   app.NameAdaptorEditField.Value = Installedadaptor;
                   
                   info = imaqhwinfo(app.NameAdaptorEditField.Value, app.DeviceIDEditField.Value);
                   app.SupportedFormats.Items = info.SupportedFormats;
                   progressdlg.delete();
                
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: SupportedFormats
        function SupportedFormatsValueChanged(app, event)
            value = app.SupportedFormats.Value;
            try 
                f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
                %pause(0.1);
                name = app.NameAdaptorEditField.Value;
                id =   app.DeviceIDEditField.Value;
                format = char(value);
                vid= videoinput(name, id, format);
                info = getselectedsource(vid);
                Height = info.ImageHeight; 
                Width  = info.ImageWidth ; 
                ExposureTime = info.ExposureTime ;
                FramesPerTrigger = vid.FramesPerTrigger;
                
                app.HeightEditField.Value = double(Height);
                app.WidthEditField.Value = double(Width);
                app.ExposureTimeEditField.Value = double(ExposureTime);
                app.FramesPerTriggerEditField.Value = double(FramesPerTrigger);
                
                clear vid;
                %delete(vid);
                imaqreset;
                progressdlg.delete();
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            try
                    f=app.UIFigure;
                    progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
                     %pause(0.1);   
                % Image acquisition
                    value = app.SupportedFormats.Value;
                    name = app.NameAdaptorEditField.Value;
                    id =   app.DeviceIDEditField.Value;
                    format = char(value);
                    app.VideoH = videoinput(name, id, format);
                    src = getselectedsource(app.VideoH);
                    app.VideoH.FramesPerTrigger = int32(app.FramesPerTriggerEditField.Value) ;
                    src.ExposureTime = (app.ExposureTimeEditField.Value);
                    
                    %vid = videoinput('winvideo',1);
                    % Create blank image
                    hImage = image(app.FrameVideo,zeros(app.HeightEditField.Value,app.WidthEditField.Value,3)); %If image resolution is 1280x720
                    %pause(2)
                    % These lines set proper aspect ratio
                    app.FrameVideo.XLim = [0,app.HeightEditField.Value];
                    app.FrameVideo.YLim = [0,app.WidthEditField.Value];
                    app.FrameVideo.XTick = [];
                    app.FrameVideo.YTick = [];
                    pbaspect(app.FrameVideo,[app.WidthEditField.Value,app.HeightEditField.Value,1])
                    % Preview the image
                   
                    preview(app.VideoH,hImage);
                    progressdlg.delete();
                    
                    
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                 % Create blank image
                    hImage = image(app.FrameVideo,zeros(app.HeightEditField.Value,app.WidthEditField.Value,3)); %If image resolution is 1280x720

                    stoppreview(app.VideoH);
                    f=app.UIFigure;
                    progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
                     pause(0.1);  
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    progressdlg.delete();
                    
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: TESTFRAMESButton
        function TESTFRAMESButtonPushed(app, event)
             f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
            try
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    value = app.SupportedFormats.Value;
                    name = app.NameAdaptorEditField.Value;
                    id =   app.DeviceIDEditField.Value;
                    format = char(value);
                    VideoE = videoinput(name, id, format);
                    src = getselectedsource(VideoE);
                    %frame = getsnapshot(app.VideoH);
                    src.ExposureTime = (app.ExposureTimeEditField.Value);
                    
                    set(VideoE,'FramesPerTrigger',Inf);
                    set(VideoE,'ReturnedColorspace','grayscale');
                    %VideoE.FrameGrabInterval = app.FrameGrabInttervalEditField.Value;
                        %frame = getsnapshot(VideoE);
                        %h= imshow(frame, 'parent', app.FrameVideo);
                        progressdlg.delete();
                    start(VideoE);
                    while(VideoE.FramesAcquired<=1000)
                        tic
                        %%%%%%%%%%%%%%%%%%%%%%%%%%% GPU testing
                        if app.GPUCheckBox.Value
                            frame = gpuArray(getsnapshot(VideoE));
                        else
                            frame = getsnapshot(VideoE);
                        end
                        
                        %%%%%%%%%%%%%%%%%%median filter for noise
                        if app.MediaFil.Value
                             frame = medfilt2(frame,[3,3]);
                        end
                        %%%%%%%%%%%%%% ima adjust filter
                        if app.AdjustContrastCheckBox.Value
                            frame = imadjust(frame); 
                        end
                        src.ExposureTime = (app.ExposureTimeEditField.Value);
                        if  app.TabGroup3.SelectedTab == app.Tab2_4
                        imshow(frame, 'parent', app.FrameRVideo);
                        
                            if app.HistogramCheckBox.Value
                                [minGL, maxGL] = PlotGrayscaleHistogram3(app, frame);
                            end
                            drawnow;
                            if app.FinishCheckBox.Value
                                break;
                            end
                        end
                         toc
                    end
                   
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
          
        end

        % Button pushed function: SelectOutputFolderButton_5
        function SelectOutputFolderButton_5Pushed(app, event)
             try 
                returnValue = uigetdir(app.FramesHamamatsuFolder,'Select output folder');
            	% returnValue will be 0 (a double) if they click cancel.
            	% returnValue will be the path (a string) if they clicked OK.
             if returnValue ~= 0
                 % Assign the value if they didn't click cancel.
            	 app.FramesHamamatsuFolder = returnValue;
                 app.OutputFolder_5.Value = app.FramesHamamatsuFolder;   
             end         
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end       
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
                f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
            try
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    value = app.SupportedFormats.Value;
                    name = app.NameAdaptorEditField.Value;
                    id =   app.DeviceIDEditField.Value;
                    format = char(value);
                    VideoE = videoinput(name, id, format);
                    src = getselectedsource(VideoE);
                    %frame = getsnapshot(app.VideoH);
                    src.ExposureTime = (app.ExposureTimeEditField.Value);
                    
                    set(VideoE,'FramesPerTrigger',Inf);
                    set(VideoE,'ReturnedColorspace','grayscale');
                    %VideoE.FrameGrabInterval = app.FrameGrabInttervalEditField.Value;
                        %frame = getsnapshot(VideoE);
                        %h= imshow(frame, 'parent', app.FrameVideo);
                        progressdlg.delete();
                        FramesCreated = 0;
                    start(VideoE);
                    while(VideoE.FramesAcquired<=app.FramesEditField.Value)
                        FramesCreated = FramesCreated + 1;
                        tic
                        %%%%%%%%%%%%%%%%%%%%%%%%%%% GPU testing
                        
                        if app.GPUCheckBox.Value
                            frame = gpuArray(getsnapshot(VideoE));
                        else
                            frame = getsnapshot(VideoE);
                        end
                        
                        %%%%%%%%%%%%%%%%%%median filter for noise
                        if app.MediaFil.Value
                             frame = medfilt2(frame,[3,3]);
                        end
                        %%%%%%%%%%%%%% ima adjust filter
                        if app.AdjustContrastCheckBox.Value
                            frame = imadjust(frame); 
                        end
                        
                        
                        src.ExposureTime = (app.ExposureTimeEditField.Value);
                        
                        %if  app.TabGroup3.SelectedTab == app.Tab2_4
                        %imshow(frame, 'parent', app.FrameRVideo);
                        
                         %   if app.HistogramCheckBox.Value
                         %       [minGL, maxGL] = PlotGrayscaleHistogram3(app, frame);
                         %   end
                         %   drawnow;
                            
                       % end
                        app.FramesHamamatsu{FramesCreated}=frame;
                        if app.FinishCheckBox.Value
                                break;
                         end
                         toc
                    end
                   
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    
                    loadFramesHamamatsu(app);
                    
                    
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: FrameHamamatsuList
        function FrameHamamatsuListValueChanged(app, event)
            value = app.FrameHamamatsuList.Value;
           
            try
                ListOfImageNames = app.FrameHamamatsuList.Items;
	        selectedFiles = app.FrameHamamatsuList.Value;
	        numberOfFrames = length(selectedFiles);


            	if isempty(selectedFiles)
            		
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedFiles) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  \n.',txtinfo,char(value(1)), numberOfFrames );
                        app.TextArea.Value = txtinfo; 
            		return;
            	end
               % [~,idx] = ismember(value,ListOfImageNames);      
               % Display_Image(app, app.BackgroundModel{idx},char(app.BackgroundModellist.Items(idx)),app.Image_4);
                
                [~,idx] = ismember(value,ListOfImageNames);      
                Display_Image(app, app.FramesHamamatsu{idx},char(app.FrameHamamatsuList.Items(idx)),app.FrameRVideo);
            	txtinfo = sprintf('  Background Model ');
                txtinfo = sprintf('%s\nName of  model %s:',txtinfo,char(app.FrameHamamatsuList.Items(idx)) );
                app.TextArea.Value = txtinfo; 
                
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             try 
                 %app.VisualizationLamp_4Label.Text = 'Processing..';
                 %app.VisualizationLamp_4.Color = [1 0 0];
                 %drawnow;
                 
                 %NameFolder = [];


                 promptMessage = sprintf('Do you want to save the frames filtered  to individual disk files?');
                 button = questdlg(promptMessage,'Save filtered frames?', 'Yes','No','Yes');
                 if strcmp(button,'Yes')
                    %[~,name,~] = fileparts(app.VideoListBox.Value);
                    baseFolderName = char(app.OutputBaseFileNameTextArea_5.Value);
                    if length(baseFolderName) < 1
            		baseFolderName = 'Hamamatsu Camera';
                    end
                    outputfolder = sprintf('%s/%s',app.FramesHamamatsuFolder,baseFolderName);
                    
                    if  exist(outputfolder,'dir')
                         errorMessage = sprintf('A folder already exists in the directory please choose a different name or delete the old version folder');
            	         f = app.UIFigure;         
                         uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                        return;
                    end
                    if ~exist(outputfolder,'dir') 
                        
                        mkdir(outputfolder);
                                                
                        %SaveBackgroundModels(app,outputfolder);
                        SaveFramesHamamatsu(app,outputfolder);
                        
                        %app.FinalBackgroundFolder = outputfolder;
                        
                        message = sprintf('\nYour images filteredes  is in file:\n%s\n\nClick OK to play it', outputfolder);
                	titleBarCaption = 'Open it?';
                	buttonText = questdlg(message, titleBarCaption, 'OK, Open It', 'Skip opeing', 'OK, open It');
                	if contains(buttonText, 'OK', 'IgnoreCase', true)
                		% Play the movie in the default program (like Windows Media Player).
                		% I think winopen() only works for the Windows operating system.
                		if contains(computer, 'PCWIN')
                			% It's a windows computer.
                			winopen(outputfolder);
                		else
                			% It's a not a Windows computer.
                			disp('iMac');
                		end
                	end
                    end
                  app.VisualizationLamp_4Label.Text = 'Done';
                  app.VisualizationLamp_4.Color = [0 0 1];
                  drawnow;
                end   
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Value changed function: 
        % SelectInputBackgroundFolderButton_2
        function SelectInputBackgroundFolderButton_2ValueChanged(app, event)
            value = app.SelectInputBackgroundFolderButton_2.Value;
             
             %value = app.SelectInputFolderButton_2.Value;
            try
                FramesDirectory = uigetdir(app.BackgroundFolder,'Select Folder');
                % FramesDirectory will be 0 (a double) if they click cancel.
	        % FramesDirectory will be the path (a string) if they clicked OK.
                if FramesDirectory ~= 0
                    % Assign the value if they didn't click cancel.
                    app.BackgroundFolder = FramesDirectory;
	   	    LoadBackgroundList(app);
                    app.DirectoryFolderBackground_2.Value = app.BackgroundFolder;
                    % Save the image folder in our ini file.
    		    SaveUserSettings(app);
                end
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Value changed function: BackgroundList_3
        function BackgroundList_3ValueChanged(app, event)
            value = app.BackgroundList_3.Value;
            try
            %app.BackgroundModellist.Value= {};
                %app.BackgroundModellist.Items = {};
                %StatusFilters(app);
                % Update the number of images in the Analyze button caption.
                %UpdateAnalyzeButtonCaption(app);
                %SpinnerValueChanged(app);
                % Get image name
            	selectedListboxItem = app.BackgroundList_3.Value;
            	if isempty(selectedListboxItem)
            		% Bail out if nothing was selected.
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedListboxItem) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  %s\n.',txtinfo,char(value(1)), length(selectedListboxItem), app.BackgroundFolder );
                        app.TextArea_2.Value = txtinfo; 
            		return;
            	end
                % If only one is selected, display it.
                %app.Plot.Visible = 'off';
        	%set(handles.axesPlot, 'visible', 'off');	% Hide plot of results since there are no results yet.
        	%ListOfImageNames = app.List.Items;
        	%baseImageFileName = strcat(cell2mat(ListOfImageNames(selectedListboxItem)));
                baseImageFileName                = char(value);
        	fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
            	[~, ~, extension] = fileparts(fullImageFileName);
            	switch lower(extension)
            		case {'.mov', '.wmv', '.asf'}
            			msgboxw('Mov and wmv format video files are not supported by MATLAB.');
            			return;
            		otherwise
            			% Display the image.
                                thisFrame = imread(fullImageFileName);
            			Display_Image(app,thisFrame,baseImageFileName,app.BackgroundImage);
                           
                                [rows, columns, numberOfColorChannels] = size(thisFrame);
                            	% Get the file date
                            	fileInfo = dir(fullImageFileName);
                            	txtInfo = sprintf('%s\n%d lines (rows) vertically\n%d columns across\n%d color channels\n', ...
                            	[baseImageFileName], rows, columns, numberOfColorChannels);
                                % Show the file time and date.
                            	txtInfo = sprintf('%s\n%s', txtInfo, fileInfo.date);
                            	app.TextArea_2.Value = txtInfo;
               
                                %PlotImageHistogram2(app,thisFrame);
            	end
                % If imgOriginal is empty (couldn't be read), just exit.
            	if isempty(thisFrame)
            		return;
            	end
            catch ME
               errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: TESTFRAMESButton_2
        function TESTFRAMESButton_2Pushed(app, event)
          
             f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
            try
                    value = app.BackgroundList_3.Value;
                    baseImageFileName                = char(value);
                    fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
                    BackgroundModelToProcess = imread(fullImageFileName);
                
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    value = app.SupportedFormats.Value;
                    name = app.NameAdaptorEditField.Value;
                    id =   app.DeviceIDEditField.Value;
                    format = char(value);
                    VideoE = videoinput(name, id, format);
                    src = getselectedsource(VideoE);
                    %frame = getsnapshot(app.VideoH);
                    src.ExposureTime = (app.ExposureTimeEditField.Value);
                    
                    set(VideoE,'FramesPerTrigger',Inf);
                    set(VideoE,'ReturnedColorspace','grayscale');
                    %VideoE.FrameGrabInterval = app.FrameGrabInttervalEditField.Value;
                        %frame = getsnapshot(VideoE);
                        %h= imshow(frame, 'parent', app.FrameVideo);
                        progressdlg.delete();
                    start(VideoE);
                    while(VideoE.FramesAcquired<=Inf)
                        tic
                        %%%%%%%%%%%%%%%%%%%%%%%%%%% GPU testing
                        if app.GPUCheckBox_2.Value
                            frame = gpuArray(getsnapshot(VideoE));
                        else
                            frame = getsnapshot(VideoE);
                        end
                        
                        %%%%%%%%%%%%%%%%%%median filter for noise
                        if app.MediaFil_2.Value
                             frame = medfilt2(frame,[3,3]);
                        end
                        %%%%%%%%%%%%%% ima adjust filter
                        if app.AdjustContrastCheckBox_2.Value
                            frame = imadjust(frame); 
                        end
                        src.ExposureTime = (app.ExposureTimeEditField.Value);
                        
                        
                        %%%% tab 3 
                        if  app.TabGroup3.SelectedTab == app.Tab3_2
                        imshow(frame, 'parent', app.LiveVideo);
                        [Foreground, Perimeter,Segmentation] = BackgroundSimple(app,frame,BackgroundModelToProcess);
                        imshow(Foreground, 'parent', app.ForegroundH);
                        imshow(Perimeter, 'parent', app.Perimeter);
                            if app.HistogramCheckBox.Value
                                [minGL, maxGL] = PlotGrayscaleHistogram3(app, frame);
                            end
                            drawnow;
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end
                        
                        
                        %%%%tab 4
                        if  app.TabGroup3.SelectedTab == app.Tab4_2
                            [Foreground, Perimeter] = BackgroundSimple(app,frame,BackgroundModelToProcess);
                            [ImageProcessedResult] = ProcessImageResult(app,frame, BackgroundModelToProcess,Foreground,app.HighGraylimitEditField_2.Value);   
                            imshow(ImageProcessedResult, 'parent', app.GrayScaleBackground);
                        
                            
                            drawnow;
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end


                         toc
                         flushdata(VideoE);
                         %clear VideoE;
                         %imaqreset;
                    end
                   
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    delete(VideoE);
                    clear VideoE;
                    
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
            
        end

        % Button pushed function: SelectOutputFolderButton_6
        function SelectOutputFolderButton_6Pushed(app, event)
             try 
                returnValue = uigetdir(app.OutputFramesToPIVFolder,'Select output folder');
            	% returnValue will be 0 (a double) if they click cancel.
            	% returnValue will be the path (a string) if they clicked OK.
             if returnValue ~= 0
                 % Assign the value if they didn't click cancel.
            	 app.OutputFramesToPIVFolder = returnValue;
                 app.OutputFolder_6.Value = app.OutputFramesToPIVFolder;   
             end         
            catch ME
                errorMessage = sprintf('Error in program %s , function %s() , at line %d.\n\nError Message:\n%s' , ...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
                f = app.UIFigure;
                uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end          
        end

        % Value changed function: FramesToPIVList
        function FramesToPIVListValueChanged(app, event)
             value = app.FramesToPIVList.Value;
             try
                ListOfImageNames = app.FramesToPIVList.Items;
	        selectedFiles = app.FramesToPIVList.Value;
	        numberOfFrames = length(selectedFiles);


            	if isempty(selectedFiles)
            		
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedFiles) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  \n.',txtinfo,char(value(1)), numberOfFrames );
                        app.Result_7.Value = txtinfo; 
            		return;
            	end
               % [~,idx] = ismember(value,ListOfImageNames);      
               % Display_Image(app, app.BackgroundModel{idx},char(app.BackgroundModellist.Items(idx)),app.Image_4);
                
                [~,idx] = ismember(value,ListOfImageNames);      
                Display_Image(app, app.FramesToPIV{idx},char(app.FramesToPIVList.Items(idx)),app.Image_25);
            	txtinfo = sprintf('  Frames to PIV ');
                txtinfo = sprintf('%s\nName of  FRAME %s:',txtinfo,char(app.FramesToPIVList.Items(idx)) );
                app.Result_7.Value = txtinfo; 
                
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: DeleteFrameButton_5
        function DeleteFrameButton_5Pushed(app, event)
             try
                [~,idx] = ismember(app.FramesToPIVList.Value,app.FramesToPIVList.Items);
               app.FramesToPIVList.Items(idx) = [];
               %a=app.BackgroundModel;
               app.FramesToPIV(idx) = [];
               %a=app.BackgroundModel;
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                           uialert(f,errorMessage,'Warning',...
                           'Icon','warning');
            end
        end

        % Button pushed function: SelectAllSamplesButton_5
        function SelectAllSamplesButton_5Pushed(app, event)
              try
                %app.FrameAfterFilter.Items = {};
            	% Find out button caption and take appropriate action.
            	ButtonCaption = app.SelectAllSamplesButton_5.Text;
            	if strcmp(ButtonCaption, 'Select All Samples') == 1
            		% Select all items in the listbox.
            		% Need to find out how many items are in the listbox (both selected and
            		% unselected).  It's quirky and inefficient but it's the only way I
            		% know how to do it.
            		% First get the whole damn listbox text into a cell array.
            		caListboxString =  app.FramesToPIVList.Items;
            		NumberOfItems = length(caListboxString);    % Get length of that cell array.
            		AllIndices=1:NumberOfItems; % Make a vector of all indices.
            		% Select all indices.
                        app.FramesToPIVList.Value = caListboxString ;                      
            		% Finally, change caption to say "Select None"
            		app.SelectAllSamplesButton_5.Text = 'Select None';
            		% It scrolls to the bottom of the list.  Use the following line
            		% if you want the first item at the top of the list.       
            		%set(app.List.set, 'ListboxTop', 1);
            	else
            		% Select none of the items in the listbox.
            		app.FramesToPIVList.Value = {};
            		% Change caption to say Select All
            		app.SelectAllSamplesButton_5.Text = 'Select All Samples';
            	end    	
            catch ME
            	errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end

        % Button pushed function: SaveImagestoanalizeusingPIVButton
        function SaveImagestoanalizeusingPIVButtonPushed(app, event)
              try 
                 app.VisualizationLamp_5Label.Text = 'Processing..';
                 app.VisualizationLamp_5.Color = [1 0 0];
                 drawnow;
                 
                 %NameFolder = [];


                 promptMessage = sprintf('Do you want to save the frames filtered  to individual disk files?');
                 button = questdlg(promptMessage,'Save filtered frames?', 'Yes','No','Yes');
                 if strcmp(button,'Yes')
                    %[~,name,~] = fileparts(app.VideoListBox.Value);
                    baseFolderName = char(app.OutputBaseFileNameTextArea_6.Value);
                    if length(baseFolderName) < 1
            		baseFolderName = 'Frames to PIV';
                    end
                    outputfolder = sprintf('%s/%s',app.OutputFramesToPIVFolder,baseFolderName);
                    
                    if  exist(outputfolder,'dir')
                         errorMessage = sprintf('A folder already exists in the directory please choose a different name or delete the old version folder');
            	         f = app.UIFigure;         
                         uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
                        return;
                    end
                    if ~exist(outputfolder,'dir') 
                        
                        mkdir(outputfolder);
                                                
                        %SaveBackgroundModels(app,outputfolder);
                        SaveFramesToPIV(app,outputfolder);
                        
                        %app.FinalBackgroundFolder = outputfolder;
                        
                        message = sprintf('\nYour images filteredes  is in file:\n%s\n\nClick OK to play it', outputfolder);
                	titleBarCaption = 'Open it?';
                	buttonText = questdlg(message, titleBarCaption, 'OK, Open It', 'Skip opeing', 'OK, open It');
                	if contains(buttonText, 'OK', 'IgnoreCase', true)
                		% Play the movie in the default program (like Windows Media Player).
                		% I think winopen() only works for the Windows operating system.
                		if contains(computer, 'PCWIN')
                			% It's a windows computer.
                			winopen(outputfolder);
                		else
                			% It's a not a Windows computer.
                			disp('iMac');
                		end
                	end
                    end
                  app.VisualizationLamp_5Label.Text = 'Done';
                  app.VisualizationLamp_5.Color = [0 0 1];
                  drawnow;
                end   
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s',...
            	mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;         
                uialert(f,errorMessage,'Warning',...
                        'Icon','warning');
            end
        end

        % Value changed function: AverageMethodButton
        function AverageMethodButtonValueChanged(app, event)
            value = app.AverageMethodButton.Value;
            try 
                 app.VisualizationLamp_5Label.Text = 'Processing..';
                 app.VisualizationLamp_5.Color = [1 0 0];
                 app.VisualizationLamp_6.Color = [1 1 0];
                 app.VisualizationLamp_6Label.Text = 'Processing..';
                 drawnow;
                 ListOfImageNames = app.ImagesToAnalizeList.Items;
	         selectedFiles = app.ImagesToAnalizeList.Value;
	         numberOfFrames = length(ListOfImageNames);
                      % If none are selected, select them all
                 if numberOfFrames == 0      
                   	errorMessage = sprintf('Image to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 
                 
                 ListOfBackgroundModels = app.BackgroundList_2.Items;
	         selectedBackgroundModels = app.BackgroundList_2.Value;
	         numberOfBackgroundModels = length(selectedBackgroundModels);
                      % If none are selected, select them all
                 if numberOfBackgroundModels == 0      
                   	errorMessage = sprintf('Background to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 inputImageFolder = app.FramesFolderUsed;
                 inputBackgroundFolder = app.FinalBackgroundFolder;
                 startTime = tic;
                 for frames=1 : numberOfFrames
                           if app.FinishNowCheckBox_5.Value
            			break;
                           end
                          %startTime = tic;
                         baseImageFileName                = char(app.ImagesToAnalizeList.Items{frames});
                	 fullImageFileName = fullfile(inputImageFolder,baseImageFileName);	% Prepend folder.
                         ImageToProcess = imread(fullImageFileName);
                             %%%%%using GPU
                            if app.GPUCheckBox_3.Value
                                ImageToProcess = gpuArray(ImageToProcess); 
                            end
                            
                            if frames == 1 
                                 baseBackgroundFileName                = char(selectedBackgroundModels);
                        	 fullBackgroundFileName = fullfile(inputBackgroundFolder,baseBackgroundFileName);	% Prepend folder.
                                 BackgroundModelToProcess = imread(fullBackgroundFileName);
                                    if app.GPUCheckBox_3.Value
                                     BackgroundModelToProcess = gpuArray(BackgroundModelToProcess); 
                                    end
                            end
                          %%running average 
                         [ForeGroundImage, ImageDilated, SmoothPixel, ResultPerimeter, Segmentation, Perimeter, RelativeConcentration, BackgroundRunning_Average] = RunningAverage(app,ImageToProcess, BackgroundModelToProcess , app.ThresholdThsEditField.Value ,app.ThresholdThuEditField.Value,app.ThresholdThFsEditField.Value, app.AlphaEditField.Value); 
                         BackgroundModelToProcess = uint8(BackgroundRunning_Average);
                                    if app.GPUCheckBox_3.Value
                                        BackgroundModelToProcess = gpuArray(BackgroundModelToProcess); 
                                    end
                          xValue(frames) = frames;
                          yValue(frames) = RelativeConcentration;
                          yValueThreshold(frames) = app.ThresholdPlotEditField.Value;
                          
                          if RelativeConcentration > app.ThresholdPlotEditField.Value
                             app.VisualizationLamp_6.Color = [1 0 0];
                             app.VisualizationLamp_6Label.Text = 'Alert';
                         else
                             app.VisualizationLamp_6.Color = [0 1 0];
                             app.VisualizationLamp_6Label.Text = 'Normal';
                         end
                          
                         if app.CheckProcessCheckBox_4.Value
                             if app.TabGroup2.SelectedTab == app.Tab_2
                                 %tab 1
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_5);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_5);
                                 end
                                 %CharRelativeConcentration = num2str(RelativeConcentration);
                                 %app.Result_5.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    %title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                     %   app.Image_22.XLabel.String =('Frame');
                                      %  app.Image_22.YLabel.String = ('Intensity Concentration %');
                                       % legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab2_3
                                 %TAB2
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_6);
                                 Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_7);
                                 Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_8);
                                 Display_Image(app, ImageDilated,char(['Image Dilated',baseImageFileName]),app.Image_9);
                                 Display_Image(app, SmoothPixel,char(['Smooth Pixel Image',baseImageFileName]),app.Image_10);
                                 Display_Image(app, ResultPerimeter,char(['Image Result Perimeter',baseImageFileName]),app.Image_11);
                                 %drawnow;
                                        %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab3
                                 %TAB 3
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_12);                 
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_13);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_13);
                                 end
                                 
                                 if isempty(app.PlotADropDown.Value)
                                
                                 else
                                    Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotADropDown,app.Image_12);  
                                 end
                                 if isempty(app.PlotBDropDown.Value)
                                
                                 else
                                    Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotBDropDown,app.Image_13);  
                                 end
                                 %drawnow;
                                            %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab4                            
                                 %TAB 4
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_14);
                                 Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_15);
                                 Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_16);
                                 Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_17);
                                 %drawnow;
                                            %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab5
                                     %TAB 5
                                     Display_Image(app, ImageToProcess,baseImageFileName,app.Image_18);
                                     if app.SegmentationOrPerimeterCheckBox.Value
                                         Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_20);
                                     else
                                         Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_20);
                                     end
                                     %RelativeConcentration = num2str(RelativeConcentration);
                                     app.PercentEditField.Value = RelativeConcentration;
                                     %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_19);
                                     
                                        %hold on;
                                        %xValue(frames) = frames;
                                        %yValue(frames) = RelativeConcentration;
                                        %yValueThreshold(frames) = app.ThresholdPlotEditField.Value;
                                        plot(app.Image_19,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                        title( app.Image_19, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_19.XLabel.String =('Frame');
                                        app.Image_19.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_19, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                                        %hold off;
                             end
                                 
                             
                             if  app.TabGroup2.SelectedTab == app.Tab6
                                 %tab 6
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                                 end
                                 CharRelativeConcentration = num2str(RelativeConcentration);
                                 app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %hold on;
                                   
                                    plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                    %plot(app.Image_22,);
                                    title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_22.XLabel.String =('Frame');
                                        app.Image_22.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                                    %hold off;
                             end
                              
                              if  app.TabGroup2.SelectedTab == app.Tab7
                                 %tab7
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                                 end
                                 %drawnow;
                              end

                                 %tab8
                                 if app.TabGroup2.SelectedTab == app.Tab8       
                                      ImageProcessedResult = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField.Value); 
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             ImageProcessedResult = medfilt2(ImageProcessedResult,[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            ImageProcessedResult = imadjust(ImageProcessedResult); 
                                        end
                                         %%%%% low pass filter
                                        if app.LowPassFilteringCheckBox.Value
                                            ImageProcessedResult = LowPassFiltering(app,ImageProcessedResult); 
                                        end
                                      Display_Image(app, ImageProcessedResult,char(['Frame to PIV ',baseImageFileName]),app.Image_24);
                                 end
                                 
                                 %tab9
                                 if app.TabGroup2.SelectedTab == app.Tab9       
                                      ImageProcessedResult = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField.Value);         
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             ImageProcessedResult = medfilt2(ImageProcessedResult,[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            ImageProcessedResult = imadjust(ImageProcessedResult); 
                                        end
                                        %%%%% low pass filter
                                        if app.LowPassFilteringCheckBox.Value
                                            ImageProcessedResult = LowPassFiltering(app,ImageProcessedResult); 
                                        end
                                        
                                      
                                      Display_Image(app, ImageProcessedResult,char(['Frame TO PIV ',baseImageFileName]),app.Image_25);
                                      
                                       elapsedSeconds = toc(startTime);
                                        txtinfo = sprintf('\n Processing ');
                                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: \nElapsed time = %.1f seconds.', ...
                        	        txtinfo,frames, numberOfFrames, elapsedSeconds);
                                        app.Result_7.Value = txtinfo;  
                                        drawnow;   
                                 end


                              drawnow;
                         else
                             if app.TabGroup2.SelectedTab == app.Tab_2
                                 %tab 1
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_5);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_5);
                                 end
                                % CharRelativeConcentration = num2str(RelativeConcentration);
                                % app.Result_5.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    %title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                     %   app.Image_22.XLabel.String =('Frame');
                                      %  app.Image_22.YLabel.String = ('Intensity Concentration %');
                                       % legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                             if  app.TabGroup2.SelectedTab == app.Tab6
                                 %tab 6
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                                 end
                                 CharRelativeConcentration = num2str(RelativeConcentration);
                                 app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_22.XLabel.String =('Frame');
                                        app.Image_22.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                              
                              if  app.TabGroup2.SelectedTab == app.Tab7
                                 %tab7
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                                 end
                                    %drawnow;
                              end
                              drawnow;
                         end
                         
                         %%NEW IMAGE FOR PIV
                         app.FramesToPIV{frames} = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage, app.HighGraylimitEditField.Value);   
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             app.FramesToPIV{frames} = medfilt2(app.FramesToPIV{frames},[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            app.FramesToPIV{frames} = imadjust(app.FramesToPIV{frames}); 
                                        end
                                         %%%%% low pass filter
                                        if app.LowPassFilteringCheckBox.Value
                                            app.FramesToPIV{frames} = LowPassFiltering(app,app.FramesToPIV{frames}); 
                                        end
                         
                         %%FILL LIST 
                         baseFileName = char(app.OutputBaseFileNameTextArea_6.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Frames to PIV ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.ImagesToAnalizeList.Items{frames}];
                        app.FramesToPIVList.Items(frames) = {outputBaseFileName};
                        sourceBaseFileName = char(app.ImagesToAnalizeList.Items{frames});
                        %sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        %Image_Frame = app.FilteredImage{frame};
                         
                         %drawnow;
                 end
                 
                 app.VisualizationLamp_5Label.Text = 'Done..';
                 app.VisualizationLamp_5.Color = [0 0 1];
                 drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: FuzzyApplicationButton
        function FuzzyApplicationButtonValueChanged(app, event)
            value = app.FuzzyApplicationButton.Value;
            try 
                 app.VisualizationLamp_5Label.Text = 'Processing..';
                 app.VisualizationLamp_5.Color = [1 0 0];
                 app.VisualizationLamp_6.Color = [1 1 0];
                 app.VisualizationLamp_6Label.Text = 'Processing..';
                 drawnow;
                 ListOfImageNames = app.ImagesToAnalizeList.Items;
	         selectedFiles = app.ImagesToAnalizeList.Value;
	         numberOfFrames = length(ListOfImageNames);
                      % If none are selected, select them all
                 if numberOfFrames == 0      
                   	errorMessage = sprintf('Image to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 
                 
                 ListOfBackgroundModels = app.BackgroundList_2.Items;
	         selectedBackgroundModels = app.BackgroundList_2.Value;
	         numberOfBackgroundModels = length(selectedBackgroundModels);
                      % If none are selected, select them all
                 if numberOfBackgroundModels == 0      
                   	errorMessage = sprintf('Background to process no selected');
            	       f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning');
                       %numberOfFrames = length(ListOfImageNames);
		       %selectedFiles = 1 : numberOfFrames;
                       return;
                 end
                 inputImageFolder = app.FramesFolderUsed;
                 inputBackgroundFolder = app.FinalBackgroundFolder;
                 startTime = tic;
                 for frames=1 : numberOfFrames
                           if app.FinishNowCheckBox_5.Value
            			break;
                           end
                          %startTime = tic;
                         baseImageFileName                = char(app.ImagesToAnalizeList.Items{frames});
                	 fullImageFileName = fullfile(inputImageFolder,baseImageFileName);	% Prepend folder.
                         ImageToProcess = imread(fullImageFileName);
                             %%%%%using GPU
                            if app.GPUCheckBox_3.Value
                                ImageToProcess = gpuArray(ImageToProcess); 
                            end
                            
                            if frames == 1 
                                 baseBackgroundFileName                = char(selectedBackgroundModels);
                        	 fullBackgroundFileName = fullfile(inputBackgroundFolder,baseBackgroundFileName);	% Prepend folder.
                                 BackgroundModelToProcess = imread(fullBackgroundFileName);
                                    if app.GPUCheckBox_3.Value
                                     BackgroundModelToProcess = gpuArray(BackgroundModelToProcess); 
                                    end
                            end
                          %%running average 
                         [ForeGroundImage, ImageDilated, SmoothPixel, ResultPerimeter, Segmentation, Perimeter, RelativeConcentration, BackgroundFuzzyRunning_Average] = FuzzyBKGsubtraction(app,ImageToProcess, BackgroundModelToProcess , app.ThresholdThsEditField.Value ,app.ThresholdThuEditField.Value,app.ThresholdThFsEditField.Value, app.AlphaEditField.Value);
                          BackgroundModelToProcess = uint8(BackgroundFuzzyRunning_Average);
                                    if app.GPUCheckBox_3.Value
                                        BackgroundModelToProcess = gpuArray(BackgroundModelToProcess); 
                                    end
                          xValue(frames) = frames;
                          yValue(frames) = RelativeConcentration;
                          yValueThreshold(frames) = app.ThresholdPlotEditField.Value;
                          
                          if RelativeConcentration > app.ThresholdPlotEditField.Value
                             app.VisualizationLamp_6.Color = [1 0 0];
                             app.VisualizationLamp_6Label.Text = 'Alert';
                         else
                             app.VisualizationLamp_6.Color = [0 1 0];
                             app.VisualizationLamp_6Label.Text = 'Normal';
                         end
                          
                         if app.CheckProcessCheckBox_4.Value
                             if app.TabGroup2.SelectedTab == app.Tab_2
                                 %tab 1
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_5);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_5);
                                 end
                                 %CharRelativeConcentration = num2str(RelativeConcentration);
                                 %app.Result_5.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    %title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                     %   app.Image_22.XLabel.String =('Frame');
                                      %  app.Image_22.YLabel.String = ('Intensity Concentration %');
                                       % legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab2_3
                                 %TAB2
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_6);
                                 Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_7);
                                 Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_8);
                                 Display_Image(app, ImageDilated,char(['Image Dilated',baseImageFileName]),app.Image_9);
                                 Display_Image(app, SmoothPixel,char(['Smooth Pixel Image',baseImageFileName]),app.Image_10);
                                 Display_Image(app, ResultPerimeter,char(['Image Result Perimeter',baseImageFileName]),app.Image_11);
                                 %drawnow;
                                        %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab3
                                 %TAB 3
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_12);                 
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_13);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_13);
                                 end
                                 
                                 if isempty(app.PlotADropDown.Value)
                                
                                 else
                                    Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotADropDown,app.Image_12);  
                                 end
                                 if isempty(app.PlotBDropDown.Value)
                                
                                 else
                                    Tab3MultiOption(app,ImageToProcess,BackgroundModelToProcess,ForeGroundImage,ImageDilated,SmoothPixel,ResultPerimeter,baseImageFileName,baseBackgroundFileName,app.PlotBDropDown,app.Image_13);  
                                 end
                                 %drawnow;
                                            %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab4                            
                                 %TAB 4
                                 Display_Image(app, ImageToProcess,baseImageFileName,app.Image_14);
                                 Display_Image(app, BackgroundModelToProcess,baseBackgroundFileName,app.Image_15);
                                 Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_16);
                                 Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_17);
                                 %drawnow;
                                            %hold off;
                             end
                             
                             if  app.TabGroup2.SelectedTab == app.Tab5
                                     %TAB 5
                                     Display_Image(app, ImageToProcess,baseImageFileName,app.Image_18);
                                     if app.SegmentationOrPerimeterCheckBox.Value
                                         Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_20);
                                     else
                                         Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_20);
                                     end
                                     %RelativeConcentration = num2str(RelativeConcentration);
                                     app.PercentEditField.Value = RelativeConcentration;
                                     %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_19);
                                     
                                        %hold on;
                                        %xValue(frames) = frames;
                                        %yValue(frames) = RelativeConcentration;
                                        %yValueThreshold(frames) = app.ThresholdPlotEditField.Value;
                                        plot(app.Image_19,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                        title( app.Image_19, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_19.XLabel.String =('Frame');
                                        app.Image_19.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_19, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                                        %hold off;
                             end
                                 
                             
                             if  app.TabGroup2.SelectedTab == app.Tab6
                                 %tab 6
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                                 end
                                 CharRelativeConcentration = num2str(RelativeConcentration);
                                 app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %hold on;
                                   
                                    plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                    %plot(app.Image_22,);
                                    title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_22.XLabel.String =('Frame');
                                        app.Image_22.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                                    %hold off;
                             end
                              
                              if  app.TabGroup2.SelectedTab == app.Tab7
                                 %tab7
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                                 end
                                 %drawnow;
                              end

                                 %tab8
                                 if app.TabGroup2.SelectedTab == app.Tab8       
                                      ImageProcessedResult = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField.Value); 
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             ImageProcessedResult = medfilt2(ImageProcessedResult,[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            ImageProcessedResult = imadjust(ImageProcessedResult); 
                                        end
                                         %%%%% low pass filter
                                        %if app.LowPassFilteringCheckBox.Value
                                        %    ImageProcessedResult = LowPassFiltering(app,ImageProcessedResult); 
                                        %end
                                      Display_Image(app, ImageProcessedResult,char(['Frame to PIV ',baseImageFileName]),app.Image_24);
                                 end
                                 
                                 %tab9
                                 if app.TabGroup2.SelectedTab == app.Tab9       
                                      ImageProcessedResult = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField.Value);         
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             ImageProcessedResult = medfilt2(ImageProcessedResult,[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            ImageProcessedResult = imadjust(ImageProcessedResult); 
                                        end
                                        %%%%% low pass filter
                                        %if app.LowPassFilteringCheckBox.Value
                                        %    ImageProcessedResult = LowPassFiltering(app,ImageProcessedResult); 
                                        %end
                                        
                                      
                                      Display_Image(app, ImageProcessedResult,char(['Frame TO PIV ',baseImageFileName]),app.Image_25);
                                      
                                       elapsedSeconds = toc(startTime);
                                        txtinfo = sprintf('\n Processing ');
                                        txtinfo = sprintf('%s\nBuilding Frames...\nOn frame %d of %d: \nElapsed time = %.1f seconds.', ...
                        	        txtinfo,frames, numberOfFrames, elapsedSeconds);
                                        app.Result_7.Value = txtinfo;  
                                        drawnow;   
                                 end


                              drawnow;
                         else
                             if app.TabGroup2.SelectedTab == app.Tab_2
                                 %tab 1
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_5);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_5);
                                 end
                                % CharRelativeConcentration = num2str(RelativeConcentration);
                                % app.Result_5.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    %plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    %title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                     %   app.Image_22.XLabel.String =('Frame');
                                      %  app.Image_22.YLabel.String = ('Intensity Concentration %');
                                       % legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                             if  app.TabGroup2.SelectedTab == app.Tab6
                                 %tab 6
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_21);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_21);
                                 end
                                 CharRelativeConcentration = num2str(RelativeConcentration);
                                 app.Result_6.Value = char([CharRelativeConcentration,('%')]);
                                 %Display_Image(app, ForeGroundImage,char(['ForeGround Image',baseImageFileName]),app.Image_22); 
                                    plot(app.Image_22,xValue,yValue,xValue,yValueThreshold);
                                        %plot(app.Image_19,xValue,yValueThreshold,'r');
                                       
                                    %plot(app.Image_22,);
                                    title( app.Image_22, ('Relative Concentration per Frames'), 'Interpreter', 'none', 'FontSize', 14);
                                       
                                        app.Image_22.XLabel.String =('Frame');
                                        app.Image_22.YLabel.String = ('Intensity Concentration %');
                                        legend( app.Image_22, 'Relative Concentration %','Threshold') 
                                        %drawnow;
                             end
                              
                              if  app.TabGroup2.SelectedTab == app.Tab7
                                 %tab7
                                 if app.SegmentationOrPerimeterCheckBox.Value
                                     Display_Image(app, Segmentation,char(['Segmentation',baseImageFileName]),app.Image_23);
                                 else
                                     Display_Image(app, Perimeter,char(['Perimeter',baseImageFileName]),app.Image_23);
                                 end
                                    %drawnow;
                              end
                              drawnow;
                         end
                         
                         %%NEW IMAGE FOR PIV
                         app.FramesToPIV{frames} = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage, app.HighGraylimitEditField.Value);   
                                        %%%%%%%%%%%%%%%%%%median filter for noise
                                        if app.MediaFil_3.Value
                                             app.FramesToPIV{frames} = medfilt2(app.FramesToPIV{frames},[app.windowEditField.Value,app.windowEditField.Value]);
                                        end
                                        %%%%%%%%%%%%%% ima adjust filter
                                        if app.AdjustContrastCheckBox_3.Value
                                            app.FramesToPIV{frames} = imadjust(app.FramesToPIV{frames}); 
                                        end
                                         %%%%% low pass filter
                                       % if app.LowPassFilteringCheckBox.Value
                                        %    app.FramesToPIV{frames} = LowPassFiltering(app,app.FramesToPIV{frames}); 
                                        %end
                         
                         %%FILL LIST 
                         baseFileName = char(app.OutputBaseFileNameTextArea_6.Value);
                	if length(baseFileName) < 1
                		baseFileName = 'Frames to PIV ';
                	end
                	if ~contains(baseFileName, '.')
                		% No format specified.  Add .tif
                		baseFileName = baseFileName;
                	end
                        outputBaseFileName = [baseFileName, app.ImagesToAnalizeList.Items{frames}];
                        app.FramesToPIVList.Items(frames) = {outputBaseFileName};
                        sourceBaseFileName = char(app.ImagesToAnalizeList.Items{frames});
                        %sourceFrameFullFileName = fullfile(inputFolder, sourceBaseFileName);
                        %Image_Frame = app.FilteredImage{frame};
                         
                         %drawnow;
                 end
                 
                 app.VisualizationLamp_5Label.Text = 'Done..';
                 app.VisualizationLamp_5.Color = [0 0 1];
                 drawnow;
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: AverageMethodButton_2
        function AverageMethodButton_2ValueChanged(app, event)
            %%value = app.AverageMethodButton_2.Value;
             f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
                cont = 0;
            try
                   
                
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    value = app.SupportedFormats.Value;
                    name = app.NameAdaptorEditField.Value;
                    id =   app.DeviceIDEditField.Value;
                    format = char(value);
                    VideoE = videoinput(name, id, format);
                    src = getselectedsource(VideoE);
                    %frame = getsnapshot(app.VideoH);
                    src.ExposureTime = (app.ExposureTimeEditField.Value);
                    
                    set(VideoE,'FramesPerTrigger',Inf);
                    set(VideoE,'ReturnedColorspace','grayscale');
                    %VideoE.FrameGrabInterval = app.FrameGrabInttervalEditField.Value;
                        %frame = getsnapshot(VideoE);
                        %h= imshow(frame, 'parent', app.FrameVideo);
                        progressdlg.delete();
                    start(VideoE);
                    while(VideoE.FramesAcquired<=Inf)
                        tic
                        
                        %%%%%%%%%%%%%% first frame
                           if VideoE.FramesAcquired == 1 
                                    value = app.BackgroundList_3.Value;
                                    baseImageFileName                = char(value);
                                    fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
                                    BackgroundModelToProcess = imread(fullImageFileName);
                           end
                           if cont == 0 
                                    value = app.BackgroundList_3.Value;
                                    baseImageFileName                = char(value);
                                    fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
                                    BackgroundModelToProcess = imread(fullImageFileName);
                                    cont =1;
                            end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%% GPU testing
                        if app.GPUCheckBox_2.Value
                            ImageToProcess = gpuArray(getsnapshot(VideoE));
                        else
                            ImageToProcess = getsnapshot(VideoE);
                        end
                        
                        %%%%%%%%%%%%%%%%%%median filter for noise
                        if app.MediaFil_2.Value
                             ImageToProcess = medfilt2(ImageToProcess,[3,3]);
                        end
                        %%%%%%%%%%%%%% ima adjust filter
                        if app.AdjustContrastCheckBox_2.Value
                            ImageToProcess = imadjust(ImageToProcess); 
                        end
                        src.ExposureTime = (app.ExposureTimeEditField.Value);
                        
                        
                        %%%% tab 3 
                        if  app.TabGroup3.SelectedTab == app.Tab3_2
                        imshow(ImageToProcess, 'parent', app.LiveVideo);
                        [ForeGroundImage, Segmentation, Perimeter, BackgroundRunning_Average] = RunningAverageRealTime (app,ImageToProcess, BackgroundModelToProcess ,app.ThresholdThsEditField_2.Value ,app.ThresholdThuEditField_2.Value,app.ThresholdThFsEditField_2.Value, app.AlphaEditField_2.Value);
                        BackgroundModelToProcess = uint8(BackgroundRunning_Average);
                        %%Display_Image(app,thisFrame,baseImageFileName,app.BackgroundImage);
                        imshow(BackgroundModelToProcess, 'parent',app.BackgroundImage);
                        imshow(ForeGroundImage, 'parent', app.ForegroundH);
                        imshow(Perimeter, 'parent', app.Perimeter);
                            if app.HistogramCheckBox.Value
                                [minGL, maxGL] = PlotGrayscaleHistogram3(app, ImageToProcess);
                            end
                            drawnow;
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end
                        
                        
                        %%%%tab 4
                        if  app.TabGroup3.SelectedTab == app.Tab4_2
                           [ForeGroundImage, Segmentation, Perimeter, BackgroundRunning_Average] = RunningAverageRealTime (app,ImageToProcess, BackgroundModelToProcess ,app.ThresholdThsEditField_2.Value ,app.ThresholdThuEditField_2.Value,app.ThresholdThFsEditField_2.Value, app.AlphaEditField_2.Value);
                            BackgroundModelToProcess = uint8(BackgroundRunning_Average);
                            [ImageProcessedResult] = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField_2.Value);   
                            imshow(ImageProcessedResult, 'parent', app.GrayScaleBackground);
                        
                            
                            drawnow;
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end


                         toc
                         flushdata(VideoE);
                         %clear VideoE;
                         %imaqreset;
                    end
                   
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    delete(VideoE);
                    clear VideoE;
                    
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: FuzzyApplicationButton_2
        function FuzzyApplicationButton_2ValueChanged(app, event)
             %%value = app.AverageMethodButton_2.Value;
             f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
                 cont = 0;
            try
                   
                
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    value = app.SupportedFormats.Value;
                    name = app.NameAdaptorEditField.Value;
                    id =   app.DeviceIDEditField.Value;
                    format = char(value);
                    VideoE = videoinput(name, id, format);
                    src = getselectedsource(VideoE);
                    %frame = getsnapshot(app.VideoH);
                    src.ExposureTime = (app.ExposureTimeEditField.Value);
                    
                    set(VideoE,'FramesPerTrigger',Inf);
                    set(VideoE,'ReturnedColorspace','grayscale');
                    %VideoE.FrameGrabInterval = app.FrameGrabInttervalEditField.Value;
                        %frame = getsnapshot(VideoE);
                        %h= imshow(frame, 'parent', app.FrameVideo);
                        progressdlg.delete();
                    start(VideoE);
                    while(VideoE.FramesAcquired<=Inf)
                        tic
                        
                        %%%%%%%%%%%%%% first frame
                           if VideoE.FramesAcquired == 1 
                                    value = app.BackgroundList_3.Value;
                                    baseImageFileName                = char(value);
                                    fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
                                    BackgroundModelToProcess = imread(fullImageFileName);
                           end
                           if cont == 0 
                                    value = app.BackgroundList_3.Value;
                                    baseImageFileName                = char(value);
                                    fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
                                    BackgroundModelToProcess = imread(fullImageFileName);
                                    cont =1;
                           end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%% GPU testing
                        if app.GPUCheckBox_2.Value
                            ImageToProcess = gpuArray(getsnapshot(VideoE));
                        else
                            ImageToProcess = getsnapshot(VideoE);
                        end
                        
                        %%%%%%%%%%%%%%%%%%median filter for noise
                        if app.MediaFil_2.Value
                             ImageToProcess = medfilt2(ImageToProcess,[3,3]);
                        end
                        %%%%%%%%%%%%%% ima adjust filter
                        if app.AdjustContrastCheckBox_2.Value
                            ImageToProcess = imadjust(ImageToProcess); 
                        end
                        src.ExposureTime = (app.ExposureTimeEditField.Value);
                        
                        
                        %%%% tab 3 
                        if  app.TabGroup3.SelectedTab == app.Tab3_2
                        imshow(ImageToProcess, 'parent', app.LiveVideo);
                        [ForeGroundImage, Segmentation, Perimeter, BackgroundFuzzyRunning_Average] = FuzzyBKGsubtractionRealTime (app,ImageToProcess, BackgroundModelToProcess ,app.ThresholdThsEditField_2.Value ,app.ThresholdThuEditField_2.Value,app.ThresholdThFsEditField_2.Value, app.AlphaEditField_2.Value);
                        BackgroundModelToProcess = uint8(BackgroundFuzzyRunning_Average);
                        %%Display_Image(app,thisFrame,baseImageFileName,app.BackgroundImage);
                        imshow(BackgroundModelToProcess, 'parent',app.BackgroundImage);
                        imshow(ForeGroundImage, 'parent', app.ForegroundH);
                        imshow(Perimeter, 'parent', app.Perimeter);
                            if app.HistogramCheckBox.Value
                                [minGL, maxGL] = PlotGrayscaleHistogram3(app, ImageToProcess);
                            end
                            drawnow;
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end
                        
                        
                        %%%%tab 4
                        if  app.TabGroup3.SelectedTab == app.Tab4_2
                           [ForeGroundImage, Segmentation, Perimeter, BackgroundFuzzyRunning_Average] = FuzzyBKGsubtractionRealTime (app,ImageToProcess, BackgroundModelToProcess ,app.ThresholdThsEditField_2.Value ,app.ThresholdThuEditField_2.Value,app.ThresholdThFsEditField_2.Value, app.AlphaEditField_2.Value);
                            BackgroundModelToProcess = uint8(BackgroundFuzzyRunning_Average);
                            [ImageProcessedResult] = ProcessImageResult(app,ImageToProcess, BackgroundModelToProcess,ForeGroundImage,app.HighGraylimitEditField_2.Value);   
                            imshow(ImageProcessedResult, 'parent', app.GrayScaleBackground);
                        
                            
                            drawnow;
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end


                         toc
                         flushdata(VideoE);
                         %clear VideoE;
                         %imaqreset;
                    end
                   
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    delete(VideoE);
                    clear VideoE;
                    
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Button pushed function: CaptureButton_2
        function CaptureButton_2Pushed(app, event)
            
        end

        % Button pushed function: BkGStaticProcessEntireListButton_2
        function BkGStaticProcessEntireListButton_2Pushed(app, event)
            
             app.FramesToPIV = {};
             app.FramesToPIVList_2.Items = {};
             f=app.UIFigure;
                progressdlg=uiprogressdlg(f,'Title','Progress','Message', 'Working please wait :D', 'Indeterminate','on');
            try
                    value = app.BackgroundList_3.Value;
                    baseImageFileName                = char(value);
                    fullImageFileName = fullfile(app.BackgroundFolder,baseImageFileName);	% Prepend folder.
                    BackgroundModelToProcess = imread(fullImageFileName);
                
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    value = app.SupportedFormats.Value;
                    name = app.NameAdaptorEditField.Value;
                    id =   app.DeviceIDEditField.Value;
                    format = char(value);
                    VideoE = videoinput(name, id, format);
                    src = getselectedsource(VideoE);
                    %frame = getsnapshot(app.VideoH);
                    src.ExposureTime = (app.ExposureTimeEditField.Value);
                    
                    set(VideoE,'FramesPerTrigger',Inf);
                    set(VideoE,'ReturnedColorspace','grayscale');
                    %VideoE.FrameGrabInterval = app.FrameGrabInttervalEditField.Value;
                        %frame = getsnapshot(VideoE);
                        %h= imshow(frame, 'parent', app.FrameVideo);
                        progressdlg.delete();
                        FramesCreated = 0;
                    start(VideoE);
                    while(VideoE.FramesAcquired<=app.FramesEditField_3.Value || FramesCreated<=app.FramesEditField_3.Value)
                        tic
                        FramesCreated = FramesCreated + 1;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%% GPU testing
                        if app.GPUCheckBox_4.Value
                            frame = gpuArray(getsnapshot(VideoE));
                        else
                            frame = getsnapshot(VideoE);
                        end
                        
                        %%%%%%%%%%%%%%%%%%median filter for noise
                        if app.MediaFil_5.Value
                             frame = medfilt2(frame,[3,3]);
                        end
                        %%%%%%%%%%%%%% ima adjust filter
                        if app.AdjustContrastCheckBox_5.Value
                            frame = imadjust(frame); 
                        end
                        src.ExposureTime = (app.ExposureTimeEditField.Value);
                        
                        
                        %%%% tab 3 
                        if  app.TabGroup3.SelectedTab == app.Tab3_2
                        imshow(frame, 'parent', app.LiveVideo);
                        [Foreground, Perimeter,Segmentation] = BackgroundSimple(app,frame,BackgroundModelToProcess);
                        imshow(Foreground, 'parent', app.ForegroundH);
                        imshow(Perimeter, 'parent', app.Perimeter);
                            if app.HistogramCheckBox.Value
                                [minGL, maxGL] = PlotGrayscaleHistogram3(app, frame);
                            end
                            drawnow;
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end
                        
                        
                        %%%%tab 5
                        if  app.TabGroup3.SelectedTab == app.Tab5_2
                            [Foreground, Perimeter] = BackgroundSimple(app,frame,BackgroundModelToProcess);
                            [ImageProcessedResult] = ProcessImageResult(app,frame, BackgroundModelToProcess,Foreground,app.HighGraylimitEditField_2.Value);   
                           
                            imshow(ImageProcessedResult, 'parent', app.GrayScaleBackground);
                            drawnow;
                            
                            if app.FinishCheckBox.Value
                                delete(VideoE);
                                clear VideoE;
                                break;
                            end
                        end
                        
                            app.FramesToPIV{FramesCreated} = ImageProcessedResult;   
                           
                        
                         toc
                         %flushdata(VideoE);
                         %clear VideoE;
                         %imaqreset;
                    end
                   
                    clear vid;
                    %delete(vid);
                    imaqreset;
                    delete(VideoE);
                    loadFramesHamamatsuVideo(app);
                    clear VideoE;
                    
                    
            catch ME
                errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
                       uialert(f,errorMessage,'Warning',...
                       'Icon','warning'); 
            end
        end

        % Value changed function: FramesToPIVList_2
        function FramesToPIVList_2ValueChanged(app, event)
            value = app.FramesToPIVList_2.Value;
            
           
            try
                ListOfImageNames = app.FramesToPIVList_2.Items;
	        selectedFiles = app.FramesToPIVList_2.Value;
	        numberOfFrames = length(selectedFiles);


            	if isempty(selectedFiles)
            		
            		return;
            	end
                % If more than one is selected, bail out.
            	if length(selectedFiles) > 1
            		%baseImageFileName = '';
            		% Change mouse pointer (cursor) to an arrow.
            		%%%%%%%%%%%set(gcf,'Pointer','arrow');
            		%%%%%%%%%%drawnow;	% Cursor won't change right away unless you do this.
                            %progressdlg.delete();
                        txtinfo = sprintf('Files selected ');
                        txtinfo = sprintf('%s\nName of Initial Frame %s \n Number of frames: %d \nFolder  \n.',txtinfo,char(value(1)), numberOfFrames );
                        app.TextArea.Value = txtinfo; 
            		return;
            	end
               % [~,idx] = ismember(value,ListOfImageNames);      
               % Display_Image(app, app.BackgroundModel{idx},char(app.BackgroundModellist.Items(idx)),app.Image_4);
                
                [~,idx] = ismember(value,ListOfImageNames);      
                Display_Image(app, app.FramesToPIV{idx},char(app.FramesToPIVList_2.Items(idx)),app.Image_26);
            	%txtinfo = sprintf('  Background Model ');
                %txtinfo = sprintf('%s\nName of  model %s:',txtinfo,char(app.FrameHamamatsuList.Items(idx)) );
                %app.TextArea.Value = txtinfo; 
                
            catch ME
                 errorMessage = sprintf('Error in program %s, function %s(), at line %d.\n\nError Message:\n%s', ...
            		mfilename, ME.stack(1).name, ME.stack(1).line, ME.message);
            	f = app.UIFigure;
               uialert(f,errorMessage,'Warning',...
               'Icon','warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.VIDEOTOFRAMESTab = uitab(app.TabGroup);
            app.VIDEOTOFRAMESTab.Title = 'VIDEO TO FRAMES';
            app.GridLayout16 = uigridlayout(app.VIDEOTOFRAMESTab);
            app.Panel_7 = uipanel(app.GridLayout16);
            app.SelectInputFolderButton = uibutton(app.Panel_7, 'state');
            app.SelectInputFolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectInputFolderButtonValueChanged, true);
            app.SelectInputFolderButton.Text = 'Select Input Folder';
            app.DirectoryFolder = uitextarea(app.Panel_7);
            app.VideoListBox = uilistbox(app.Panel_7);
            app.VideoListBox.Items = {};
            app.VideoListBox.ValueChangedFcn = createCallbackFcn(app, @VideoListBoxValueChanged, true);
            app.VideoListBox.Value = {};
            app.SelectOutputFolderButton_2 = uibutton(app.Panel_7, 'push');
            app.SelectOutputFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButton_2Pushed, true);
            app.SelectOutputFolderButton_2.Text = 'Select Output Folder';
            app.OutputFolder_2 = uitextarea(app.Panel_7);
            app.OutputBaseFileNameTextAreaLabel_2 = uilabel(app.Panel_7);
            app.OutputBaseFileNameTextAreaLabel_2.Text = 'Output Base File Name';
            app.OutputBaseFileNameTextArea_2 = uitextarea(app.Panel_7);
            app.GetandSaveFramesButton = uibutton(app.Panel_7, 'push');
            app.GetandSaveFramesButton.ButtonPushedFcn = createCallbackFcn(app, @GetandSaveFramesButtonPushed, true);
            app.GetandSaveFramesButton.Text = 'Get and Save Frames';
            app.CheckProcessCheckBox = uicheckbox(app.Panel_7);
            app.CheckProcessCheckBox.Text = 'Check Process';
            app.GrayImageCheckBox = uicheckbox(app.Panel_7);
            app.GrayImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrayImageCheckBoxValueChanged, true);
            app.GrayImageCheckBox.Text = 'Gray Image';
            app.FrameVisualizationSpinnerLabel = uilabel(app.Panel_7);
            app.FrameVisualizationSpinnerLabel.Text = 'Frame Visualization';
            app.FrameVisualizationSpinner = uispinner(app.Panel_7);
            app.FrameVisualizationSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameVisualizationSpinnerValueChanged, true);
            app.FrameVisualizationSpinner.Value = 1;
            app.FramesOfVideoList = uilistbox(app.Panel_7);
            app.FramesOfVideoList.Items = {};
            app.FramesOfVideoList.ValueChangedFcn = createCallbackFcn(app, @FramesOfVideoListValueChanged, true);
            app.FramesOfVideoList.Value = {};
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.Image = uiaxes(app.GridLayout17);
            title(app.Image, 'Title')
            xlabel(app.Image, 'X')
            ylabel(app.Image, 'Y')
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.Panel_8 = uipanel(app.GridLayout18);
            app.VisualizationLamp_2Label = uilabel(app.Panel_8);
            app.VisualizationLamp_2Label.Text = 'Visualization';
            app.VisualizationLamp_2 = uilamp(app.Panel_8);
            app.FinishNowCheckBox_2 = uicheckbox(app.Panel_8);
            app.FinishNowCheckBox_2.Text = 'Finish Now';
            app.EXPORTButton = uibutton(app.Panel_8, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.CommandWindowTextAreaLabel = uilabel(app.GridLayout18);
            app.CommandWindowTextAreaLabel.Text = 'Command Window';
            app.Result = uitextarea(app.GridLayout18);
            app.FRAMESTOVIDEOTab = uitab(app.TabGroup);
            app.FRAMESTOVIDEOTab.Title = 'FRAMES TO VIDEO';
            app.GridLayout19 = uigridlayout(app.FRAMESTOVIDEOTab);
            app.Panel_9 = uipanel(app.GridLayout19);
            app.SelectInputFolderButton_2 = uibutton(app.Panel_9, 'state');
            app.SelectInputFolderButton_2.ValueChangedFcn = createCallbackFcn(app, @SelectInputFolderButton_2ValueChanged, true);
            app.SelectInputFolderButton_2.Text = 'Select Input Folder';
            app.DirectoryFolderFrames = uitextarea(app.Panel_9);
            app.FrameList = uilistbox(app.Panel_9);
            app.FrameList.Items = {};
            app.FrameList.Multiselect = 'on';
            app.FrameList.ValueChangedFcn = createCallbackFcn(app, @FrameListValueChanged, true);
            app.FrameList.Value = {};
            app.SelectionOptionButton = uibutton(app.Panel_9, 'push');
            app.SelectionOptionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectionOptionButtonPushed, true);
            app.SelectionOptionButton.Text = 'Selection Option';
            app.SortAlphabeticallyCheckBox = uicheckbox(app.Panel_9);
            app.SortAlphabeticallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortAlphabeticallyCheckBoxValueChanged, true);
            app.SortAlphabeticallyCheckBox.Text = 'Sort Alphabetically';
            app.SortbyfiledatetimeCheckBox = uicheckbox(app.Panel_9);
            app.SortbyfiledatetimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortbyfiledatetimeCheckBoxValueChanged, true);
            app.SortbyfiledatetimeCheckBox.Text = 'Sort by file date/time';
            app.Panel_10 = uipanel(app.GridLayout19);
            app.PauseAftereachimageCheckBox = uicheckbox(app.Panel_10);
            app.PauseAftereachimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @PauseAftereachimageCheckBoxValueChanged, true);
            app.PauseAftereachimageCheckBox.Text = 'Pause After each image';
            app.ChekPlotHistogramsImagesCheckBox = uicheckbox(app.Panel_10);
            app.ChekPlotHistogramsImagesCheckBox.Text = {'Chek Plot '; 'Histograms/Images'};
            app.FrameRateSpinnerLabel = uilabel(app.Panel_10);
            app.FrameRateSpinnerLabel.Text = 'Frame Rate';
            app.FrameRateSpinner = uispinner(app.Panel_10);
            app.FrameRateSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameRateSpinnerValueChanged, true);
            app.FrameRateSpinner.Value = 30;
            app.FrameRateLabel = uilabel(app.Panel_10);
            app.FrameRateLabel.Text = 'Frame Rate';
            app.NumberoftimestorepeatfirstframeLabel = uilabel(app.Panel_10);
            app.NumberoftimestorepeatfirstframeLabel.Text = {'Number of times to'; ' repeat first frame'};
            app.NumberoftimestorepeatfirstframeEditField = uieditfield(app.Panel_10, 'numeric');
            app.ColumnsEditFieldLabel = uilabel(app.Panel_10);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.Panel_10, 'numeric');
            app.ColumnsEditField.Value = 1920;
            app.RowsEditFieldLabel = uilabel(app.Panel_10);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.Panel_10, 'numeric');
            app.RowsEditField.Value = 1080;
            app.ResizeImagesCheckBox = uicheckbox(app.Panel_10);
            app.ResizeImagesCheckBox.Text = 'Resize Images';
            app.SelectOutputFolderButton = uibutton(app.Panel_10, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.OutputFolder = uitextarea(app.Panel_10);
            app.OutputBaseFileNameTextAreaLabel = uilabel(app.Panel_10);
            app.OutputBaseFileNameTextAreaLabel.Text = 'Output Base File Name';
            app.OutputBaseFileNameTextArea = uitextarea(app.Panel_10);
            app.EXPORTTOIMAGEFILTERINGButton = uibutton(app.Panel_10, 'push');
            app.EXPORTTOIMAGEFILTERINGButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTTOIMAGEFILTERINGButtonPushed, true);
            app.EXPORTTOIMAGEFILTERINGButton.Text = {'EXPORT TO IMAGE'; ' FILTERING'};
            app.SaveDirectoryButton = uibutton(app.Panel_10, 'push');
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.AnalyzeButton = uibutton(app.Panel_10, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.FinishNowCheckBox = uicheckbox(app.Panel_10);
            app.FinishNowCheckBox.Text = 'Finish Now';
            app.VisualizationLampLabel = uilabel(app.Panel_10);
            app.VisualizationLampLabel.Text = 'Visualization';
            app.VisualizationLamp = uilamp(app.Panel_10);
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.Image_2 = uiaxes(app.GridLayout20);
            title(app.Image_2, 'Title')
            xlabel(app.Image_2, 'X')
            ylabel(app.Image_2, 'Y')
            app.GridLayout21 = uigridlayout(app.GridLayout20);
            app.CommandWindowTextAreaLabel_2 = uilabel(app.GridLayout21);
            app.CommandWindowTextAreaLabel_2.Text = 'Command Window';
            app.Result_2 = uitextarea(app.GridLayout21);
            app.Plot = uiaxes(app.GridLayout21);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            app.FILTERINGTab = uitab(app.TabGroup);
            app.FILTERINGTab.Title = 'FILTERING';
            app.GridLayout22 = uigridlayout(app.FILTERINGTab);
            app.Panel_11 = uipanel(app.GridLayout22);
            app.DirectoryFolderFramesToFilter = uitextarea(app.Panel_11);
            app.DeleteFrameButton = uibutton(app.Panel_11, 'push');
            app.DeleteFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFrameButtonPushed, true);
            app.DeleteFrameButton.Text = 'Delete Frame';
            app.SelectAllSamplesButton = uibutton(app.Panel_11, 'push');
            app.SelectAllSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllSamplesButtonPushed, true);
            app.SelectAllSamplesButton.Text = 'Select All Samples';
            app.FrameListToFilter = uilistbox(app.Panel_11);
            app.FrameListToFilter.Items = {};
            app.FrameListToFilter.Multiselect = 'on';
            app.FrameListToFilter.ValueChangedFcn = createCallbackFcn(app, @FrameListToFilterValueChanged, true);
            app.FrameListToFilter.Value = {};
            app.ClearButton = uibutton(app.Panel_11, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FrameAfterFilter = uilistbox(app.Panel_11);
            app.FrameAfterFilter.Items = {};
            app.FrameAfterFilter.Multiselect = 'on';
            app.FrameAfterFilter.ValueChangedFcn = createCallbackFcn(app, @FrameAfterFilterValueChanged, true);
            app.FrameAfterFilter.Value = {};
            app.VisualizationLamp_3Label = uilabel(app.Panel_11);
            app.VisualizationLamp_3Label.Text = 'Visualization';
            app.VisualizationLamp_3 = uilamp(app.Panel_11);
            app.FinishNowCheckBox_3 = uicheckbox(app.Panel_11);
            app.FinishNowCheckBox_3.Text = 'Finish Now';
            app.Panel_12 = uipanel(app.GridLayout22);
            app.ImageAdjustButton = uibutton(app.Panel_12, 'push');
            app.ImageAdjustButton.ButtonPushedFcn = createCallbackFcn(app, @ImageAdjustButtonPushed, true);
            app.ImageAdjustButton.Text = 'Image Adjust';
            app.HistogramEqualizationButton = uibutton(app.Panel_12, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.AdaptiveHistogramEquButton = uibutton(app.Panel_12, 'push');
            app.AdaptiveHistogramEquButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveHistogramEquButtonPushed, true);
            app.AdaptiveHistogramEquButton.Text = 'Adaptive Histogram Equ';
            app.FlatFieldcorrectionButton = uibutton(app.Panel_12, 'push');
            app.FlatFieldcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @FlatFieldcorrectionButtonPushed, true);
            app.FlatFieldcorrectionButton.Text = 'Flat-Field correction';
            app.Label = uilabel(app.Panel_12);
            app.Label.Text = 'Σ';
            app.Sigma = uispinner(app.Panel_12);
            app.Sigma.Value = 30;
            app.MedianfilteringButton = uibutton(app.Panel_12, 'push');
            app.MedianfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @MedianfilteringButtonPushed, true);
            app.MedianfilteringButton.Text = 'Median filtering';
            app.AdaptiveFilteringButton = uibutton(app.Panel_12, 'push');
            app.AdaptiveFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveFilteringButtonPushed, true);
            app.AdaptiveFilteringButton.Text = 'Adaptive Filtering';
            app.DenoisedButton = uibutton(app.Panel_12, 'push');
            app.DenoisedButton.ButtonPushedFcn = createCallbackFcn(app, @DenoisedButtonPushed, true);
            app.DenoisedButton.Text = 'Denoised';
            app.CheckProcessCheckBox_2 = uicheckbox(app.Panel_12);
            app.CheckProcessCheckBox_2.Text = 'Check Process';
            app.CheckProcessCheckBox_2.Value = true;
            app.DeleteFilterButton = uibutton(app.Panel_12, 'push');
            app.DeleteFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFilterButtonPushed, true);
            app.DeleteFilterButton.Text = 'Delete Filter';
            app.ListFilterUsed = uilistbox(app.Panel_12);
            app.ListFilterUsed.Items = {};
            app.ListFilterUsed.Value = {};
            app.ApplyFiltersButton = uibutton(app.Panel_12, 'push');
            app.ApplyFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFiltersButtonPushed, true);
            app.ApplyFiltersButton.Visible = 'off';
            app.ApplyFiltersButton.Text = 'Apply Filters';
            app.ListFiltersApplied = uilistbox(app.Panel_12);
            app.ListFiltersApplied.Items = {};
            app.ListFiltersApplied.Value = {};
            app.SelectOutputFolderButton_3 = uibutton(app.Panel_12, 'push');
            app.SelectOutputFolderButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButton_3Pushed, true);
            app.SelectOutputFolderButton_3.Text = 'Select Output Folder';
            app.OutputFolder_3 = uitextarea(app.Panel_12);
            app.OutputBaseFileNameTextArea_3Label = uilabel(app.Panel_12);
            app.OutputBaseFileNameTextArea_3Label.Text = 'Output Base File Name';
            app.OutputBaseFileNameTextArea_3 = uitextarea(app.Panel_12);
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.Panel_13 = uipanel(app.GridLayout24);
            app.Result_3 = uitextarea(app.Panel_13);
            app.SaveImageFilteredButton = uibutton(app.Panel_13, 'push');
            app.SaveImageFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageFilteredButtonPushed, true);
            app.SaveImageFilteredButton.Text = 'Save Image Filtered';
            app.EXPORTTOBKGSUBSTRACTIONButton_2 = uibutton(app.Panel_13, 'push');
            app.EXPORTTOBKGSUBSTRACTIONButton_2.ButtonPushedFcn = createCallbackFcn(app, @EXPORTTOBKGSUBSTRACTIONButton_2Pushed, true);
            app.EXPORTTOBKGSUBSTRACTIONButton_2.Text = {'EXPORT TO'; ' BKG SUBSTRACTION'};
            app.Plot_2 = uiaxes(app.GridLayout24);
            title(app.Plot_2, 'Title')
            xlabel(app.Plot_2, 'X')
            ylabel(app.Plot_2, 'Y')
            app.Image_3 = uiaxes(app.GridLayout23);
            title(app.Image_3, 'Title')
            xlabel(app.Image_3, 'X')
            ylabel(app.Image_3, 'Y')
            app.BACKGROUNDSUBTRACTIONMODELTab = uitab(app.TabGroup);
            app.BACKGROUNDSUBTRACTIONMODELTab.Title = 'BACKGROUND SUBTRACTION MODEL';
            app.GridLayout25 = uigridlayout(app.BACKGROUNDSUBTRACTIONMODELTab);
            app.Panel_14 = uipanel(app.GridLayout25);
            app.DirectoryFolderFramesToBackground = uitextarea(app.Panel_14);
            app.RefreshButton = uibutton(app.Panel_14, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DeleteFrameButton_2 = uibutton(app.Panel_14, 'push');
            app.DeleteFrameButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteFrameButton_2Pushed, true);
            app.DeleteFrameButton_2.Text = 'Delete Frame';
            app.SelectAllSamplesButton_2 = uibutton(app.Panel_14, 'push');
            app.SelectAllSamplesButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAllSamplesButton_2Pushed, true);
            app.SelectAllSamplesButton_2.Text = 'Select All Samples';
            app.FrameListBackground = uilistbox(app.Panel_14);
            app.FrameListBackground.Items = {};
            app.FrameListBackground.Multiselect = 'on';
            app.FrameListBackground.ValueChangedFcn = createCallbackFcn(app, @FrameListBackgroundValueChanged, true);
            app.FrameListBackground.Value = {};
            app.GetCoresInfoButton = uibutton(app.Panel_14, 'push');
            app.GetCoresInfoButton.ButtonPushedFcn = createCallbackFcn(app, @GetCoresInfoButtonPushed, true);
            app.GetCoresInfoButton.Text = 'Get Cores Info';
            app.FinishNowCheckBox_4 = uicheckbox(app.Panel_14);
            app.FinishNowCheckBox_4.Text = 'Finish Now';
            app.CoresNumberDropDownLabel = uilabel(app.Panel_14);
            app.CoresNumberDropDownLabel.Text = 'Cores Number';
            app.CoresNumberDropDown = uidropdown(app.Panel_14);
            app.CoresNumberDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.CoresNumberDropDown.Value = '1';
            app.Create3DARRAYButton = uibutton(app.Panel_14, 'push');
            app.Create3DARRAYButton.ButtonPushedFcn = createCallbackFcn(app, @Create3DARRAYButtonPushed, true);
            app.Create3DARRAYButton.Text = 'Create 3D ARRAY';
            app.Create3DARRAYButton_2 = uibutton(app.Panel_14, 'push');
            app.Create3DARRAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @Create3DARRAYButton_2Pushed, true);
            app.Create3DARRAYButton_2.Text = 'Create 3D ARRAY';
            app.BackgroundModellist = uilistbox(app.Panel_14);
            app.BackgroundModellist.Items = {};
            app.BackgroundModellist.Multiselect = 'on';
            app.BackgroundModellist.ValueChangedFcn = createCallbackFcn(app, @BackgroundModellistValueChanged, true);
            app.BackgroundModellist.Value = {};
            app.DeleteFrameButton_3 = uibutton(app.Panel_14, 'push');
            app.DeleteFrameButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteFrameButton_3Pushed, true);
            app.DeleteFrameButton_3.Text = 'Delete Frame';
            app.SelectAllSamplesButton_3 = uibutton(app.Panel_14, 'push');
            app.SelectAllSamplesButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectAllSamplesButton_3Pushed, true);
            app.SelectAllSamplesButton_3.Text = 'Select All Samples';
            app.VisualizationLamp_4Label = uilabel(app.Panel_14);
            app.VisualizationLamp_4Label.Text = 'Visualization';
            app.VisualizationLamp_4 = uilamp(app.Panel_14);
            app.Panel_15 = uipanel(app.GridLayout25);
            app.SelectInputFolderButton_3 = uibutton(app.Panel_15, 'state');
            app.SelectInputFolderButton_3.ValueChangedFcn = createCallbackFcn(app, @SelectInputFolderButton_3ValueChanged, true);
            app.SelectInputFolderButton_3.Text = 'Select Input Folder';
            app.DirectoryFolderFrames_2 = uitextarea(app.Panel_15);
            app.BackgroundList = uilistbox(app.Panel_15);
            app.BackgroundList.Items = {};
            app.BackgroundList.Multiselect = 'on';
            app.BackgroundList.ValueChangedFcn = createCallbackFcn(app, @BackgroundListValueChanged, true);
            app.BackgroundList.Value = {};
            app.BackGroundMedianOperationButton = uibutton(app.Panel_15, 'push');
            app.BackGroundMedianOperationButton.ButtonPushedFcn = createCallbackFcn(app, @BackGroundMedianOperationButtonPushed, true);
            app.BackGroundMedianOperationButton.Text = {'BackGround'; ' Median Operation'};
            app.BackGroundMeanOperationButton = uibutton(app.Panel_15, 'push');
            app.BackGroundMeanOperationButton.ButtonPushedFcn = createCallbackFcn(app, @BackGroundMeanOperationButtonPushed, true);
            app.BackGroundMeanOperationButton.Text = {'BackGround'; 'Mean Operation'};
            app.BackGroundModeOperationButton = uibutton(app.Panel_15, 'push');
            app.BackGroundModeOperationButton.ButtonPushedFcn = createCallbackFcn(app, @BackGroundModeOperationButtonPushed, true);
            app.BackGroundModeOperationButton.Text = {'BackGround '; 'Mode Operation'};
            app.CheckProcessCheckBox_3 = uicheckbox(app.Panel_15);
            app.CheckProcessCheckBox_3.Text = 'Check Process';
            app.CheckProcessCheckBox_3.Value = true;
            app.SelectOutputFolderButton_4 = uibutton(app.Panel_15, 'push');
            app.SelectOutputFolderButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButton_4Pushed, true);
            app.SelectOutputFolderButton_4.Text = 'Select Output Folder';
            app.OutputFolder_4 = uitextarea(app.Panel_15);
            app.OutputBaseFileNameTextArea_4Label = uilabel(app.Panel_15);
            app.OutputBaseFileNameTextArea_4Label.Text = 'Output Base File Name';
            app.OutputBaseFileNameTextArea_4 = uitextarea(app.Panel_15);
            app.GridLayout26 = uigridlayout(app.GridLayout25);
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.Panel_16 = uipanel(app.GridLayout27);
            app.SaveBackgroundImageButton = uibutton(app.Panel_16, 'push');
            app.SaveBackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBackgroundImageButtonPushed, true);
            app.SaveBackgroundImageButton.Text = {'Save Background '; 'Image'};
            app.EXPORTTOANALIZEButton = uibutton(app.Panel_16, 'push');
            app.EXPORTTOANALIZEButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTTOANALIZEButtonPushed, true);
            app.EXPORTTOANALIZEButton.Text = 'EXPORT TO ANALIZE';
            app.CommandWindowTextAreaLabel_3 = uilabel(app.GridLayout27);
            app.CommandWindowTextAreaLabel_3.Text = 'Command Window';
            app.Result_4 = uitextarea(app.GridLayout27);
            app.Image_4 = uiaxes(app.GridLayout26);
            title(app.Image_4, 'Title')
            xlabel(app.Image_4, 'X')
            ylabel(app.Image_4, 'Y')
            app.ANALYZETab = uitab(app.TabGroup);
            app.ANALYZETab.Title = 'ANALYZE';
            app.GridLayout4 = uigridlayout(app.ANALYZETab);
            app.Panel_2 = uipanel(app.GridLayout4);
            app.SelectInputImagesFolderButton = uibutton(app.Panel_2, 'state');
            app.SelectInputImagesFolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectInputImagesFolderButtonValueChanged, true);
            app.SelectInputImagesFolderButton.Text = 'Select Input Images Folder';
            app.DirectoryFolderFrames_3 = uitextarea(app.Panel_2);
            app.ImagesToAnalizeList = uilistbox(app.Panel_2);
            app.ImagesToAnalizeList.Items = {};
            app.ImagesToAnalizeList.Multiselect = 'on';
            app.ImagesToAnalizeList.ValueChangedFcn = createCallbackFcn(app, @ImagesToAnalizeListValueChanged, true);
            app.ImagesToAnalizeList.Value = {};
            app.DeleteFrameButton_4 = uibutton(app.Panel_2, 'push');
            app.DeleteFrameButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteFrameButton_4Pushed, true);
            app.DeleteFrameButton_4.Text = 'Delete Frame';
            app.SelectAllSamplesButton_4 = uibutton(app.Panel_2, 'push');
            app.SelectAllSamplesButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectAllSamplesButton_4Pushed, true);
            app.SelectAllSamplesButton_4.Text = 'Select All Samples';
            app.SelectInputBackgroundFolderButton = uibutton(app.Panel_2, 'state');
            app.SelectInputBackgroundFolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectInputBackgroundFolderButtonValueChanged, true);
            app.SelectInputBackgroundFolderButton.Text = 'Select Input Background Folder';
            app.DirectoryFolderBackground = uitextarea(app.Panel_2);
            app.BackgroundList_2 = uilistbox(app.Panel_2);
            app.BackgroundList_2.Items = {};
            app.BackgroundList_2.Multiselect = 'on';
            app.BackgroundList_2.ValueChangedFcn = createCallbackFcn(app, @BackgroundList_2ValueChanged, true);
            app.BackgroundList_2.Value = {};
            app.FinishNowCheckBox_5 = uicheckbox(app.Panel_2);
            app.FinishNowCheckBox_5.Text = 'Finish Now';
            app.CheckProcessCheckBox_4 = uicheckbox(app.Panel_2);
            app.CheckProcessCheckBox_4.Text = 'Check Process';
            app.CheckProcessCheckBox_4.Value = true;
            app.VisualizationLamp_5Label = uilabel(app.Panel_2);
            app.VisualizationLamp_5Label.Text = 'Visualization';
            app.VisualizationLamp_5 = uilamp(app.Panel_2);
            app.BkGStaticProcessFrameButton = uibutton(app.Panel_2, 'state');
            app.BkGStaticProcessFrameButton.ValueChangedFcn = createCallbackFcn(app, @BkGStaticProcessFrameButtonValueChanged, true);
            app.BkGStaticProcessFrameButton.Text = 'BkG Static Process Frame';
            app.DilatePixelSizeLabel = uilabel(app.Panel_2);
            app.DilatePixelSizeLabel.Text = {'Dilate'; 'Pixel Size'};
            app.DilatePixelSizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.DilatePixelSizeEditField.Value = 10;
            app.SmoothPixelSizeLabel = uilabel(app.Panel_2);
            app.SmoothPixelSizeLabel.Text = {'Smooth '; 'Pixel Size'};
            app.SmoothPixelSizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.SmoothPixelSizeEditField.Value = 1;
            app.BkGStaticProcessEntireListButton = uibutton(app.Panel_2, 'state');
            app.BkGStaticProcessEntireListButton.ValueChangedFcn = createCallbackFcn(app, @BkGStaticProcessEntireListButtonValueChanged, true);
            app.BkGStaticProcessEntireListButton.Text = 'BkG Static Process Entire List';
            app.ThresholdThsEditFieldLabel = uilabel(app.Panel_2);
            app.ThresholdThsEditFieldLabel.Text = {'Threshold'; 'Ths'; ''};
            app.ThresholdThsEditField = uieditfield(app.Panel_2, 'numeric');
            app.ThresholdThsEditField.Value = 30;
            app.HighGraylimitLabel = uilabel(app.Panel_2);
            app.HighGraylimitLabel.Text = {'High Gray'; ' limit'};
            app.HighGraylimitEditField = uieditfield(app.Panel_2, 'numeric');
            app.HighGraylimitEditField.Value = 127;
            app.GPUCheckBox_3 = uicheckbox(app.Panel_2);
            app.GPUCheckBox_3.Text = 'GPU';
            app.AverageMethodButton = uibutton(app.Panel_2, 'state');
            app.AverageMethodButton.ValueChangedFcn = createCallbackFcn(app, @AverageMethodButtonValueChanged, true);
            app.AverageMethodButton.Text = 'Average Method';
            app.FuzzyApplicationButton = uibutton(app.Panel_2, 'state');
            app.FuzzyApplicationButton.ValueChangedFcn = createCallbackFcn(app, @FuzzyApplicationButtonValueChanged, true);
            app.FuzzyApplicationButton.Text = 'Fuzzy Application';
            app.ThresholdThuEditFieldLabel = uilabel(app.Panel_2);
            app.ThresholdThuEditFieldLabel.Text = {'Threshold'; 'Thu'; ''};
            app.ThresholdThuEditField = uieditfield(app.Panel_2, 'numeric');
            app.ThresholdThuEditField.Value = 30;
            app.ThresholdThFsEditFieldLabel = uilabel(app.Panel_2);
            app.ThresholdThFsEditFieldLabel.Text = {'Threshold'; 'ThFs'; ''};
            app.ThresholdThFsEditField = uieditfield(app.Panel_2, 'numeric');
            app.ThresholdThFsEditField.Value = 1;
            app.AlphaEditFieldLabel = uilabel(app.Panel_2);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.Panel_2, 'numeric');
            app.AlphaEditField.Value = 0.9;
            app.CoresNumberLabel = uilabel(app.Panel_2);
            app.CoresNumberLabel.Text = {'Cores'; 'Number'};
            app.CoresNumberDropDown_2 = uidropdown(app.Panel_2);
            app.CoresNumberDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.CoresNumberDropDown_2.Value = '1';
            app.Panel_3 = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.GridLayout6 = uigridlayout(app.Tab_2);
            app.Image_5 = uiaxes(app.GridLayout6);
            title(app.Image_5, 'Title')
            xlabel(app.Image_5, 'X')
            ylabel(app.Image_5, 'Y')
            app.Result_5 = uitextarea(app.GridLayout6);
            app.Tab2_3 = uitab(app.TabGroup2);
            app.Tab2_3.Title = 'Tab2';
            app.GridLayout7 = uigridlayout(app.Tab2_3);
            app.Image_6 = uiaxes(app.GridLayout7);
            title(app.Image_6, 'Title')
            xlabel(app.Image_6, 'X')
            ylabel(app.Image_6, 'Y')
            app.Image_7 = uiaxes(app.GridLayout7);
            title(app.Image_7, 'Title')
            xlabel(app.Image_7, 'X')
            ylabel(app.Image_7, 'Y')
            app.Image_8 = uiaxes(app.GridLayout7);
            title(app.Image_8, 'Title')
            xlabel(app.Image_8, 'X')
            ylabel(app.Image_8, 'Y')
            app.Image_9 = uiaxes(app.GridLayout7);
            title(app.Image_9, 'Title')
            xlabel(app.Image_9, 'X')
            ylabel(app.Image_9, 'Y')
            app.Image_10 = uiaxes(app.GridLayout7);
            title(app.Image_10, 'Title')
            xlabel(app.Image_10, 'X')
            ylabel(app.Image_10, 'Y')
            app.Image_11 = uiaxes(app.GridLayout7);
            title(app.Image_11, 'Title')
            xlabel(app.Image_11, 'X')
            ylabel(app.Image_11, 'Y')
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Title = 'Tab3';
            app.GridLayout8 = uigridlayout(app.Tab3);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.Image_12 = uiaxes(app.GridLayout9);
            title(app.Image_12, 'Title')
            xlabel(app.Image_12, 'X')
            ylabel(app.Image_12, 'Y')
            app.Panel_4 = uipanel(app.GridLayout9);
            app.SegmentationOrPerimeterCheckBox = uicheckbox(app.Panel_4);
            app.SegmentationOrPerimeterCheckBox.Text = 'Segmentation Or Perimeter';
            app.SegmentationOrPerimeterCheckBox.Value = true;
            app.CheckButton = uibutton(app.Panel_4, 'state');
            app.CheckButton.Text = 'Check';
            app.PlotADropDownLabel = uilabel(app.Panel_4);
            app.PlotADropDownLabel.Text = 'Plot A';
            app.PlotADropDown = uidropdown(app.Panel_4);
            app.PlotADropDown.Items = {'Original Image', 'Background Image', 'Foreground Binary Image', 'Image Dilate', 'Smooth Image', 'Image Segmentation'};
            app.PlotADropDown.Value = 'Original Image';
            app.PlotBDropDownLabel = uilabel(app.Panel_4);
            app.PlotBDropDownLabel.Text = 'Plot B';
            app.PlotBDropDown = uidropdown(app.Panel_4);
            app.PlotBDropDown.Items = {'Original Image', 'Background Image', 'Foreground Binary Image', 'Image Dilate', 'Smooth Image', 'Image Segmentation'};
            app.PlotBDropDown.Value = 'Foreground Binary Image';
            app.Image_13 = uiaxes(app.GridLayout8);
            title(app.Image_13, 'Title')
            xlabel(app.Image_13, 'X')
            ylabel(app.Image_13, 'Y')
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Title = 'Tab4';
            app.GridLayout10 = uigridlayout(app.Tab4);
            app.Image_14 = uiaxes(app.GridLayout10);
            title(app.Image_14, 'Title')
            xlabel(app.Image_14, 'X')
            ylabel(app.Image_14, 'Y')
            app.Image_15 = uiaxes(app.GridLayout10);
            title(app.Image_15, 'Title')
            xlabel(app.Image_15, 'X')
            ylabel(app.Image_15, 'Y')
            app.Image_16 = uiaxes(app.GridLayout10);
            title(app.Image_16, 'Title')
            xlabel(app.Image_16, 'X')
            ylabel(app.Image_16, 'Y')
            app.Image_17 = uiaxes(app.GridLayout10);
            title(app.Image_17, 'Title')
            xlabel(app.Image_17, 'X')
            ylabel(app.Image_17, 'Y')
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Title = 'Tab5';
            app.GridLayout11 = uigridlayout(app.Tab5);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.Image_18 = uiaxes(app.GridLayout12);
            title(app.Image_18, 'Title')
            xlabel(app.Image_18, 'X')
            ylabel(app.Image_18, 'Y')
            app.Image_19 = uiaxes(app.GridLayout12);
            title(app.Image_19, 'Title')
            xlabel(app.Image_19, 'X')
            ylabel(app.Image_19, 'Y')
            app.Panel_5 = uipanel(app.GridLayout12);
            app.ThresholdPlotEditFieldLabel = uilabel(app.Panel_5);
            app.ThresholdPlotEditFieldLabel.Text = 'Threshold Plot';
            app.ThresholdPlotEditField = uieditfield(app.Panel_5, 'numeric');
            app.ThresholdPlotEditField.Value = 1;
            app.PercentEditFieldLabel = uilabel(app.Panel_5);
            app.PercentEditFieldLabel.Text = 'Percent';
            app.PercentEditField = uieditfield(app.Panel_5, 'numeric');
            app.Button = uibutton(app.Panel_5, 'push');
            app.Image_20 = uiaxes(app.GridLayout11);
            title(app.Image_20, 'Title')
            xlabel(app.Image_20, 'X')
            ylabel(app.Image_20, 'Y')
            app.Tab6 = uitab(app.TabGroup2);
            app.Tab6.Title = 'Tab6';
            app.GridLayout13 = uigridlayout(app.Tab6);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.Image_22 = uiaxes(app.GridLayout14);
            title(app.Image_22, 'Title')
            xlabel(app.Image_22, 'X')
            ylabel(app.Image_22, 'Y')
            app.Panel_6 = uipanel(app.GridLayout14);
            app.VisualizationLamp_6Label = uilabel(app.Panel_6);
            app.VisualizationLamp_6Label.Text = 'Visualization';
            app.VisualizationLamp_6 = uilamp(app.Panel_6);
            app.Result_6 = uitextarea(app.Panel_6);
            app.Image_21 = uiaxes(app.GridLayout13);
            title(app.Image_21, 'Title')
            xlabel(app.Image_21, 'X')
            ylabel(app.Image_21, 'Y')
            app.Tab7 = uitab(app.TabGroup2);
            app.Tab7.Title = 'Tab7';
            app.GridLayout15 = uigridlayout(app.Tab7);
            app.Image_23 = uiaxes(app.GridLayout15);
            title(app.Image_23, 'Title')
            xlabel(app.Image_23, 'X')
            ylabel(app.Image_23, 'Y')
            app.Tab8 = uitab(app.TabGroup2);
            app.Tab8.Title = 'Tab8';
            app.Image_24 = uiaxes(app.Tab8);
            title(app.Image_24, 'Title')
            xlabel(app.Image_24, 'X')
            ylabel(app.Image_24, 'Y')
            app.Tab9 = uitab(app.TabGroup2);
            app.Tab9.Title = 'Tab9';
            app.GridLayout37 = uigridlayout(app.Tab9);
            app.GridLayout38 = uigridlayout(app.GridLayout37);
            app.Result_7 = uitextarea(app.GridLayout38);
            app.Image_25 = uiaxes(app.GridLayout38);
            title(app.Image_25, 'Title')
            xlabel(app.Image_25, 'X')
            ylabel(app.Image_25, 'Y')
            app.Panel_21 = uipanel(app.GridLayout37);
            app.SelectOutputFolderButton_6 = uibutton(app.Panel_21, 'push');
            app.SelectOutputFolderButton_6.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButton_6Pushed, true);
            app.SelectOutputFolderButton_6.Text = 'Select Output Folder';
            app.OutputFolder_6 = uitextarea(app.Panel_21);
            app.OutputBaseFileNameTextArea_4Label_3 = uilabel(app.Panel_21);
            app.OutputBaseFileNameTextArea_4Label_3.Text = 'Output Base File Name';
            app.OutputBaseFileNameTextArea_6 = uitextarea(app.Panel_21);
            app.FramesToPIVList = uilistbox(app.Panel_21);
            app.FramesToPIVList.Items = {};
            app.FramesToPIVList.Multiselect = 'on';
            app.FramesToPIVList.ValueChangedFcn = createCallbackFcn(app, @FramesToPIVListValueChanged, true);
            app.FramesToPIVList.Value = {};
            app.DeleteFrameButton_5 = uibutton(app.Panel_21, 'push');
            app.DeleteFrameButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeleteFrameButton_5Pushed, true);
            app.DeleteFrameButton_5.Text = 'Delete Frame';
            app.SelectAllSamplesButton_5 = uibutton(app.Panel_21, 'push');
            app.SelectAllSamplesButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectAllSamplesButton_5Pushed, true);
            app.SelectAllSamplesButton_5.Text = 'Select All Samples';
            app.SaveImagestoanalizeusingPIVButton = uibutton(app.Panel_21, 'push');
            app.SaveImagestoanalizeusingPIVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagestoanalizeusingPIVButtonPushed, true);
            app.SaveImagestoanalizeusingPIVButton.Text = {'Save Images'; ' to analize using PIV'};
            app.MediaFil_3 = uicheckbox(app.Panel_21);
            app.MediaFil_3.Text = 'Media Filter';
            app.AdjustContrastCheckBox_3 = uicheckbox(app.Panel_21);
            app.AdjustContrastCheckBox_3.Text = 'Adjust Contrast';
            app.windowEditFieldLabel = uilabel(app.Panel_21);
            app.windowEditFieldLabel.Text = 'window';
            app.windowEditField = uieditfield(app.Panel_21, 'numeric');
            app.windowEditField.Value = 3;
            app.LowPassFilteringCheckBox = uicheckbox(app.Panel_21);
            app.LowPassFilteringCheckBox.Text = 'Low Pass Filtering';
            app.VIDEOTab = uitab(app.TabGroup);
            app.VIDEOTab.Title = 'VIDEO';
            app.GridLayout28 = uigridlayout(app.VIDEOTab);
            app.Panel_17 = uipanel(app.GridLayout28);
            app.CheckCameraButton = uibutton(app.Panel_17, 'push');
            app.CheckCameraButton.ButtonPushedFcn = createCallbackFcn(app, @CheckCameraButtonPushed, true);
            app.CheckCameraButton.Text = 'Check Camera';
            app.SelectOutputFolderButton_5 = uibutton(app.Panel_17, 'push');
            app.SelectOutputFolderButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButton_5Pushed, true);
            app.SelectOutputFolderButton_5.Text = 'Select Output Folder';
            app.OutputFolder_5 = uitextarea(app.Panel_17);
            app.OutputBaseFileNameTextArea_4Label_2 = uilabel(app.Panel_17);
            app.OutputBaseFileNameTextArea_4Label_2.Text = 'Output Base File Name';
            app.OutputBaseFileNameTextArea_5 = uitextarea(app.Panel_17);
            app.NameAdaptorEditFieldLabel = uilabel(app.Panel_17);
            app.NameAdaptorEditFieldLabel.Text = 'Name Adaptor ';
            app.NameAdaptorEditField = uieditfield(app.Panel_17, 'text');
            app.DeviceIDEditFieldLabel = uilabel(app.Panel_17);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.Panel_17, 'numeric');
            app.DeviceIDEditField.Value = 1;
            app.SupportedFormats = uilistbox(app.Panel_17);
            app.SupportedFormats.Items = {};
            app.SupportedFormats.ValueChangedFcn = createCallbackFcn(app, @SupportedFormatsValueChanged, true);
            app.SupportedFormats.Value = {};
            app.HeightLabel = uilabel(app.Panel_17);
            app.HeightLabel.Text = 'Height :';
            app.HeightEditField = uieditfield(app.Panel_17, 'numeric');
            app.WidthLabel = uilabel(app.Panel_17);
            app.WidthLabel.Text = 'Width :';
            app.WidthEditField = uieditfield(app.Panel_17, 'numeric');
            app.ExposureTimeLabel = uilabel(app.Panel_17);
            app.ExposureTimeLabel.Text = {'Exposure'; 'Time'};
            app.ExposureTimeEditField = uieditfield(app.Panel_17, 'numeric');
            app.ExposureTimeEditField.Value = 0.0099977;
            app.FramesPerTriggerLabel = uilabel(app.Panel_17);
            app.FramesPerTriggerLabel.Text = {'Frames'; 'Per Trigger'};
            app.FramesPerTriggerEditField = uieditfield(app.Panel_17, 'numeric');
            app.FramesPerTriggerEditField.Value = 1;
            app.PreviewButton = uibutton(app.Panel_17, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.StopButton = uibutton(app.Panel_17, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FinishCheckBox = uicheckbox(app.Panel_17);
            app.FinishCheckBox.Text = 'Finish';
            app.Panel_19 = uipanel(app.GridLayout28);
            app.GridLayout29 = uigridlayout(app.Panel_19);
            app.TabGroup3 = uitabgroup(app.GridLayout29);
            app.Tab_3 = uitab(app.TabGroup3);
            app.Tab_3.Title = 'Tab';
            app.GridLayout30 = uigridlayout(app.Tab_3);
            app.FrameVideo = uiaxes(app.GridLayout30);
            title(app.FrameVideo, 'Title')
            xlabel(app.FrameVideo, 'X')
            ylabel(app.FrameVideo, 'Y')
            app.StatusCamera = uitextarea(app.GridLayout30);
            app.Tab2_4 = uitab(app.TabGroup3);
            app.Tab2_4.Title = 'Tab2';
            app.GridLayout31 = uigridlayout(app.Tab2_4);
            app.Panel_18 = uipanel(app.GridLayout31);
            app.TESTFRAMESButton = uibutton(app.Panel_18, 'push');
            app.TESTFRAMESButton.ButtonPushedFcn = createCallbackFcn(app, @TESTFRAMESButtonPushed, true);
            app.TESTFRAMESButton.Text = 'TEST FRAMES';
            app.MediaFil = uicheckbox(app.Panel_18);
            app.MediaFil.Text = 'Media Filter';
            app.FrameGrabInttervalEditFieldLabel = uilabel(app.Panel_18);
            app.FrameGrabInttervalEditFieldLabel.Text = 'Frame Grab Intterval';
            app.FrameGrabInttervalEditField = uieditfield(app.Panel_18, 'numeric');
            app.FrameGrabInttervalEditField.Value = 5;
            app.AdjustContrastCheckBox = uicheckbox(app.Panel_18);
            app.AdjustContrastCheckBox.Text = 'Adjust Contrast';
            app.GPUCheckBox = uicheckbox(app.Panel_18);
            app.GPUCheckBox.Text = 'GPU';
            app.FrameHamamatsuList = uilistbox(app.Panel_18);
            app.FrameHamamatsuList.Items = {};
            app.FrameHamamatsuList.Multiselect = 'on';
            app.FrameHamamatsuList.ValueChangedFcn = createCallbackFcn(app, @FrameHamamatsuListValueChanged, true);
            app.FrameHamamatsuList.Value = {};
            app.TextArea = uitextarea(app.Panel_18);
            app.CaptureButton = uibutton(app.Panel_18, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.HistogramCheckBox = uicheckbox(app.Panel_18);
            app.HistogramCheckBox.Text = 'Histogram';
            app.FramesEditFieldLabel = uilabel(app.Panel_18);
            app.FramesEditFieldLabel.Text = '# Frames';
            app.FramesEditField = uieditfield(app.Panel_18, 'numeric');
            app.FramesEditField.Value = 1;
            app.SaveButton = uibutton(app.Panel_18, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.GridLayout32 = uigridlayout(app.GridLayout31);
            app.HistogramVideoFrame = uiaxes(app.GridLayout32);
            title(app.HistogramVideoFrame, 'Title')
            xlabel(app.HistogramVideoFrame, 'X')
            ylabel(app.HistogramVideoFrame, 'Y')
            app.FrameRVideo = uiaxes(app.GridLayout32);
            title(app.FrameRVideo, 'Title')
            xlabel(app.FrameRVideo, 'X')
            ylabel(app.FrameRVideo, 'Y')
            app.Tab3_2 = uitab(app.TabGroup3);
            app.Tab3_2.Title = 'Tab3';
            app.GridLayout33 = uigridlayout(app.Tab3_2);
            app.Panel_20 = uipanel(app.GridLayout33);
            app.SelectInputBackgroundFolderButton_2 = uibutton(app.Panel_20, 'state');
            app.SelectInputBackgroundFolderButton_2.ValueChangedFcn = createCallbackFcn(app, @SelectInputBackgroundFolderButton_2ValueChanged, true);
            app.SelectInputBackgroundFolderButton_2.Text = {'Select Input '; 'Background Folder'};
            app.DirectoryFolderBackground_2 = uitextarea(app.Panel_20);
            app.BackgroundList_3 = uilistbox(app.Panel_20);
            app.BackgroundList_3.Items = {};
            app.BackgroundList_3.Multiselect = 'on';
            app.BackgroundList_3.ValueChangedFcn = createCallbackFcn(app, @BackgroundList_3ValueChanged, true);
            app.BackgroundList_3.Value = {};
            app.FrameGrabInttervalEditField_2Label = uilabel(app.Panel_20);
            app.FrameGrabInttervalEditField_2Label.Text = 'Frame Grab Intterval';
            app.FrameGrabInttervalEditField_2 = uieditfield(app.Panel_20, 'numeric');
            app.FrameGrabInttervalEditField_2.Value = 5;
            app.TESTFRAMESButton_2 = uibutton(app.Panel_20, 'push');
            app.TESTFRAMESButton_2.ButtonPushedFcn = createCallbackFcn(app, @TESTFRAMESButton_2Pushed, true);
            app.TESTFRAMESButton_2.Text = 'TEST FRAMES';
            app.CaptureButton_2 = uibutton(app.Panel_20, 'push');
            app.CaptureButton_2.ButtonPushedFcn = createCallbackFcn(app, @CaptureButton_2Pushed, true);
            app.CaptureButton_2.Text = 'Capture';
            app.FramesEditField_2Label = uilabel(app.Panel_20);
            app.FramesEditField_2Label.Text = '# Frames';
            app.FramesEditField_2 = uieditfield(app.Panel_20, 'numeric');
            app.FramesEditField_2.Value = 1;
            app.MediaFil_2 = uicheckbox(app.Panel_20);
            app.MediaFil_2.Text = 'Media Filter';
            app.AdjustContrastCheckBox_2 = uicheckbox(app.Panel_20);
            app.AdjustContrastCheckBox_2.Text = 'Adjust Contrast';
            app.GPUCheckBox_2 = uicheckbox(app.Panel_20);
            app.GPUCheckBox_2.Text = 'GPU';
            app.ListBox_2 = uilistbox(app.Panel_20);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.TextArea_2 = uitextarea(app.Panel_20);
            app.ThresholdThsEditField_2Label = uilabel(app.Panel_20);
            app.ThresholdThsEditField_2Label.Text = {'Threshold'; 'Ths'; ''};
            app.ThresholdThsEditField_2 = uieditfield(app.Panel_20, 'numeric');
            app.ThresholdThsEditField_2.Value = 65;
            app.HighGraylimitEditField_2Label = uilabel(app.Panel_20);
            app.HighGraylimitEditField_2Label.Text = {'High Gray'; ' limit'};
            app.HighGraylimitEditField_2 = uieditfield(app.Panel_20, 'numeric');
            app.HighGraylimitEditField_2.Value = 127;
            app.ThresholdThuEditField_2Label = uilabel(app.Panel_20);
            app.ThresholdThuEditField_2Label.Text = {'Threshold'; 'Thu'; ''};
            app.ThresholdThuEditField_2 = uieditfield(app.Panel_20, 'numeric');
            app.ThresholdThuEditField_2.Value = 30;
            app.ThresholdThFsEditField_2Label = uilabel(app.Panel_20);
            app.ThresholdThFsEditField_2Label.Text = {'Threshold'; 'ThFs'; ''};
            app.ThresholdThFsEditField_2 = uieditfield(app.Panel_20, 'numeric');
            app.ThresholdThFsEditField_2.Value = 1;
            app.AlphaEditField_2Label = uilabel(app.Panel_20);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField_2 = uieditfield(app.Panel_20, 'numeric');
            app.AlphaEditField_2.Value = 0.9;
            app.AverageMethodButton_2 = uibutton(app.Panel_20, 'state');
            app.AverageMethodButton_2.ValueChangedFcn = createCallbackFcn(app, @AverageMethodButton_2ValueChanged, true);
            app.AverageMethodButton_2.Text = 'Average Method';
            app.FuzzyApplicationButton_2 = uibutton(app.Panel_20, 'state');
            app.FuzzyApplicationButton_2.ValueChangedFcn = createCallbackFcn(app, @FuzzyApplicationButton_2ValueChanged, true);
            app.FuzzyApplicationButton_2.Text = 'Fuzzy Application';
            app.CoresNumberDropDown_3Label = uilabel(app.Panel_20);
            app.CoresNumberDropDown_3Label.Text = {'Cores'; 'Number'};
            app.CoresNumberDropDown_3 = uidropdown(app.Panel_20);
            app.CoresNumberDropDown_3.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.CoresNumberDropDown_3.Value = '1';
            app.GridLayout34 = uigridlayout(app.GridLayout33);
            app.BackgroundImage = uiaxes(app.GridLayout34);
            title(app.BackgroundImage, 'Title')
            xlabel(app.BackgroundImage, 'X')
            ylabel(app.BackgroundImage, 'Y')
            app.LiveVideo = uiaxes(app.GridLayout34);
            title(app.LiveVideo, 'Title')
            xlabel(app.LiveVideo, 'X')
            ylabel(app.LiveVideo, 'Y')
            app.ForegroundH = uiaxes(app.GridLayout34);
            title(app.ForegroundH, 'Title')
            xlabel(app.ForegroundH, 'X')
            ylabel(app.ForegroundH, 'Y')
            app.Perimeter = uiaxes(app.GridLayout34);
            title(app.Perimeter, 'Title')
            xlabel(app.Perimeter, 'X')
            ylabel(app.Perimeter, 'Y')
            app.Tab4_2 = uitab(app.TabGroup3);
            app.Tab4_2.Title = 'Tab4';
            app.GridLayout35 = uigridlayout(app.Tab4_2);
            app.GrayScaleBackground = uiaxes(app.GridLayout35);
            title(app.GrayScaleBackground, 'Title')
            xlabel(app.GrayScaleBackground, 'X')
            ylabel(app.GrayScaleBackground, 'Y')
            app.Tab5_2 = uitab(app.TabGroup3);
            app.Tab5_2.Title = 'Tab5';
            app.GridLayout36 = uigridlayout(app.Tab5_2);
            app.GridLayout39 = uigridlayout(app.GridLayout36);
            app.Panel_22 = uipanel(app.GridLayout39);
            app.FramesToPIVList_2 = uilistbox(app.Panel_22);
            app.FramesToPIVList_2.Items = {};
            app.FramesToPIVList_2.Multiselect = 'on';
            app.FramesToPIVList_2.ValueChangedFcn = createCallbackFcn(app, @FramesToPIVList_2ValueChanged, true);
            app.FramesToPIVList_2.Value = {};
            app.DeleteFrameButton_6 = uibutton(app.Panel_22, 'push');
            app.DeleteFrameButton_6.Text = 'Delete Frame';
            app.SelectAllSamplesButton_6 = uibutton(app.Panel_22, 'push');
            app.SelectAllSamplesButton_6.Text = 'Select All Samples';
            app.SaveImagestoanalizeusingPIVButton_2 = uibutton(app.Panel_22, 'push');
            app.SaveImagestoanalizeusingPIVButton_2.Text = {'Save Images'; ' to analize using PIV'};
            app.MediaFil_4 = uicheckbox(app.Panel_22);
            app.MediaFil_4.Text = 'Media Filter';
            app.AdjustContrastCheckBox_4 = uicheckbox(app.Panel_22);
            app.AdjustContrastCheckBox_4.Text = 'Adjust Contrast';
            app.windowEditField_2Label = uilabel(app.Panel_22);
            app.windowEditField_2Label.Text = 'window';
            app.windowEditField_2 = uieditfield(app.Panel_22, 'numeric');
            app.windowEditField_2.Value = 3;
            app.LowPassFilteringCheckBox_2 = uicheckbox(app.Panel_22);
            app.LowPassFilteringCheckBox_2.Text = 'Low Pass Filtering';
            app.BkGStaticProcessEntireListButton_2 = uibutton(app.Panel_22, 'push');
            app.BkGStaticProcessEntireListButton_2.ButtonPushedFcn = createCallbackFcn(app, @BkGStaticProcessEntireListButton_2Pushed, true);
            app.BkGStaticProcessEntireListButton_2.Text = 'BkG Static Process Entire List';
            app.MediaFil_5 = uicheckbox(app.Panel_22);
            app.MediaFil_5.Text = 'Media Filter';
            app.AdjustContrastCheckBox_5 = uicheckbox(app.Panel_22);
            app.AdjustContrastCheckBox_5.Text = 'Adjust Contrast';
            app.GPUCheckBox_4 = uicheckbox(app.Panel_22);
            app.GPUCheckBox_4.Text = 'GPU';
            app.AverageMethodButton_3 = uibutton(app.Panel_22, 'state');
            app.AverageMethodButton_3.Text = 'Average Method';
            app.FuzzyApplicationButton_3 = uibutton(app.Panel_22, 'state');
            app.FuzzyApplicationButton_3.Text = 'Fuzzy Application';
            app.CoresNumberDropDown_3Label_2 = uilabel(app.Panel_22);
            app.CoresNumberDropDown_3Label_2.Text = {'Cores'; 'Number'};
            app.CoresNumberDropDown_4 = uidropdown(app.Panel_22);
            app.CoresNumberDropDown_4.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.CoresNumberDropDown_4.Value = '1';
            app.FramesEditField_3Label = uilabel(app.Panel_22);
            app.FramesEditField_3Label.Text = '# Frames';
            app.FramesEditField_3 = uieditfield(app.Panel_22, 'numeric');
            app.FramesEditField_3.Value = 1;
            app.ThresholdThsEditField_3Label = uilabel(app.Panel_22);
            app.ThresholdThsEditField_3Label.Text = {'Threshold'; 'Ths'; ''};
            app.ThresholdThsEditField_3 = uieditfield(app.Panel_22, 'numeric');
            app.ThresholdThsEditField_3.Value = 65;
            app.HighGraylimitEditField_3Label = uilabel(app.Panel_22);
            app.HighGraylimitEditField_3Label.Text = {'High Gray'; ' limit'};
            app.HighGraylimitEditField_3 = uieditfield(app.Panel_22, 'numeric');
            app.HighGraylimitEditField_3.Value = 127;
            app.ThresholdThuEditField_3Label = uilabel(app.Panel_22);
            app.ThresholdThuEditField_3Label.Text = {'Threshold'; 'Thu'; ''};
            app.ThresholdThuEditField_3 = uieditfield(app.Panel_22, 'numeric');
            app.ThresholdThuEditField_3.Value = 30;
            app.ThresholdThFsEditField_3Label = uilabel(app.Panel_22);
            app.ThresholdThFsEditField_3Label.Text = {'Threshold'; 'ThFs'; ''};
            app.ThresholdThFsEditField_3 = uieditfield(app.Panel_22, 'numeric');
            app.ThresholdThFsEditField_3.Value = 1;
            app.AlphaEditField_3Label = uilabel(app.Panel_22);
            app.AlphaEditField_3Label.Text = 'Alpha';
            app.AlphaEditField_3 = uieditfield(app.Panel_22, 'numeric');
            app.AlphaEditField_3.Value = 0.9;
            app.CheckProcessCheckBox_5 = uicheckbox(app.Panel_22);
            app.CheckProcessCheckBox_5.Text = 'Check Process';
            app.CheckProcessCheckBox_5.Value = true;
            app.Image_26 = uiaxes(app.GridLayout39);
            title(app.Image_26, 'Title')
            xlabel(app.Image_26, 'X')
            ylabel(app.Image_26, 'Y')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PARTICLE_FLOW_VISUALIZATION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end